classdef IceScanner_Version_1_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IceScannerUI                    matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        ReopenexternalwindowMenu        matlab.ui.container.Menu
        MenuImgProcSaveState            matlab.ui.container.Menu
        MenuDiagnosticImport            matlab.ui.container.Menu
        TabGroup_Main                   matlab.ui.container.TabGroup
        TabGroup_VertexDetect           matlab.ui.container.Tab
        StepsPanel_VertexDetect         matlab.ui.container.Panel
        VertexDetectPanel_Grid          matlab.ui.container.GridLayout
        arrow_finishEMD                 matlab.ui.control.Label
        steps_finishEMD                 matlab.ui.control.Label
        arrow_thresholding              matlab.ui.control.Label
        steps_thresholding              matlab.ui.control.Label
        arrow_importImg                 matlab.ui.control.Label
        steps_importImg                 matlab.ui.control.Label
        TabGroupEMD                     matlab.ui.container.TabGroup
        Tab_Reference                   matlab.ui.container.Tab
        GridLayout7_6                   matlab.ui.container.GridLayout
        topographyMode                  matlab.ui.control.DropDown
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_48  matlab.ui.control.Label
        EMD_edgeLengthROI               matlab.ui.control.NumericEditField
        ROIedgesizeLabel                matlab.ui.control.Label
        EMD_flatfieldSigma              matlab.ui.control.NumericEditField
        FlatfieldsigmaLabel             matlab.ui.control.Label
        DropDownASIType                 matlab.ui.control.DropDown
        ButtonImportRefImg8             matlab.ui.control.Button
        ButtonImportRefImg7             matlab.ui.control.Button
        ButtonImportRefImg6             matlab.ui.control.Button
        ButtonImportRefImg5             matlab.ui.control.Button
        ButtonImportRefImg4             matlab.ui.control.Button
        ButtonImportRefImg3             matlab.ui.control.Button
        next_image                      matlab.ui.control.Button
        ButtonImportRefImg2             matlab.ui.control.Button
        TopographyimageButton           matlab.ui.control.Button
        ButtonImportRefImg1             matlab.ui.control.Button
        EMD_skipInterval                matlab.ui.control.NumericEditField
        SkipofpixelsLabel               matlab.ui.control.Label
        EMD_scaleFactor                 matlab.ui.control.NumericEditField
        Scale1041Label                  matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_38  matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_37  matlab.ui.control.Label
        AxesRefFrame8                   matlab.ui.control.UIAxes
        AxesRefFrame7                   matlab.ui.control.UIAxes
        AxesRefFrame6                   matlab.ui.control.UIAxes
        AxesRefFrame5                   matlab.ui.control.UIAxes
        AxesRefFrame4                   matlab.ui.control.UIAxes
        AxesRefFrame3                   matlab.ui.control.UIAxes
        AxesRefFrame1                   matlab.ui.control.UIAxes
        AxesRefFrame2                   matlab.ui.control.UIAxes
        Tab_Threshold                   matlab.ui.container.Tab
        GridLayout7_7                   matlab.ui.container.GridLayout
        img8Threshold                   matlab.ui.control.NumericEditField
        threferencethresholdLabel_5     matlab.ui.control.Label
        img7Threshold                   matlab.ui.control.NumericEditField
        threferencethresholdLabel_4     matlab.ui.control.Label
        img6Threshold                   matlab.ui.control.NumericEditField
        threferencethresholdLabel_3     matlab.ui.control.Label
        img5Threshold                   matlab.ui.control.NumericEditField
        threferencethresholdLabel_2     matlab.ui.control.Label
        img4Threshold                   matlab.ui.control.NumericEditField
        threferencethresholdLabel       matlab.ui.control.Label
        img3Threshold                   matlab.ui.control.NumericEditField
        rdreferencethresholdLabel       matlab.ui.control.Label
        back_threshold                  matlab.ui.control.Button
        SaveresultsButton               matlab.ui.control.Button
        img2Threshold                   matlab.ui.control.NumericEditField
        ndreferencethresholdLabel       matlab.ui.control.Label
        img1Threshold                   matlab.ui.control.NumericEditField
        streferencethresholdLabel       matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_39  matlab.ui.control.Label
        Tab_FinishEMD                   matlab.ui.container.Tab
        GridLayout7_8                   matlab.ui.container.GridLayout
        finish_scanNewImage             matlab.ui.control.Button
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_40  matlab.ui.control.Label
        TabGroup_ImageProcess           matlab.ui.container.Tab
        StepsPanel_ImageProcess         matlab.ui.container.Panel
        StepsPanel_Grid                 matlab.ui.container.GridLayout
        arrow_finish                    matlab.ui.control.Label
        steps_finish                    matlab.ui.control.Label
        arrow_postProcess               matlab.ui.control.Label
        steps_postProcess               matlab.ui.control.Label
        arrow_finalInspect              matlab.ui.control.Label
        steps_finalInspect              matlab.ui.control.Label
        arrow_latticeIdx                matlab.ui.control.Label
        steps_latticeIdx                matlab.ui.control.Label
        arrow_nbrScan                   matlab.ui.control.Label
        steps_nbrScan                   matlab.ui.control.Label
        arrow_cleanUp                   matlab.ui.control.Label
        steps_cleanUp                   matlab.ui.control.Label
        arrow_import                    matlab.ui.control.Label
        steps_import                    matlab.ui.control.Label
        TabGroup_ImgProcess             matlab.ui.container.TabGroup
        Tab_Import                      matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        tipPole                         matlab.ui.control.DropDown
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_47  matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_46  matlab.ui.control.Label
        contrastMode                    matlab.ui.control.DropDown
        magContrast_adjust              matlab.ui.control.CheckBox
        magContrast_cutoff              matlab.ui.control.NumericEditField
        IntensitycutoffstdLabel         matlab.ui.control.Label
        magContrast_sigma               matlab.ui.control.NumericEditField
        FlatfieldsigmaLabel_2           matlab.ui.control.Label
        next_import                     matlab.ui.control.Button
        processedBefore                 matlab.ui.control.DropDown
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_2  matlab.ui.control.Label
        browse_importMagContrast        matlab.ui.control.Button
        Field_magImagePath              matlab.ui.control.EditField
        MagneticimagefileEditFieldLabel  matlab.ui.control.Label
        browse_importEMDResults         matlab.ui.control.Button
        Field_detectionPath             matlab.ui.control.EditField
        DetectionresultfileEditFieldLabel  matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel  matlab.ui.control.Label
        Tab_CleanUp                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        next_cleanUp                    matlab.ui.control.Button
        back_cleanUp                    matlab.ui.control.Button
        ButtonAddPts                    matlab.ui.control.Button
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_6  matlab.ui.control.Label
        ButtonRemovePts                 matlab.ui.control.Button
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_5  matlab.ui.control.Label
        EditFieldVertexReduction        matlab.ui.control.NumericEditField
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_4  matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_3  matlab.ui.control.Label
        Tab_NeighborScan                matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        Button_previewMagnet            matlab.ui.control.Button
        magnetHeight                    matlab.ui.control.NumericEditField
        magnetWidth                     matlab.ui.control.NumericEditField
        InputascanningdistanceLabel_28  matlab.ui.control.Label
        brickMode                       matlab.ui.control.DropDown
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_44  matlab.ui.control.Label
        ButtonNeighborDetect            matlab.ui.control.Button
        next_nbrScan                    matlab.ui.control.Button
        back_nbrScan                    matlab.ui.control.Button
        ButtonPreviewScan               matlab.ui.control.Button
        scanDiameter                    matlab.ui.control.NumericEditField
        scanCross2Angle                 matlab.ui.control.NumericEditField
        scanCross2Height                matlab.ui.control.NumericEditField
        scanCross2Width                 matlab.ui.control.NumericEditField
        scanCross1Angle                 matlab.ui.control.NumericEditField
        scanCross1Height                matlab.ui.control.NumericEditField
        scanCross1Width                 matlab.ui.control.NumericEditField
        InputascanningdistanceLabel_15  matlab.ui.control.Label
        InputascanningdistanceLabel_14  matlab.ui.control.Label
        InputascanningdistanceLabel_10  matlab.ui.control.Label
        EditFieldVertexIndex            matlab.ui.control.NumericEditField
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_9  matlab.ui.control.Label
        InputascanningdistanceLabel_13  matlab.ui.control.Label
        InputascanningdistanceLabel_12  matlab.ui.control.Label
        InputascanningdistanceLabel_11  matlab.ui.control.Label
        oldVersion                      matlab.ui.control.DropDown
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_8  matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_7  matlab.ui.control.Label
        Tab_Finalinspection             matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        isingDetectMethod               matlab.ui.container.ButtonGroup
        UniqueintensitiesButton         matlab.ui.control.RadioButton
        STDEVButton                     matlab.ui.control.RadioButton
        ShowstandarddeviationhistogramButton  matlab.ui.control.Button
        ZeroizedetectednonIsingstatesButton  matlab.ui.control.Button
        ShowdetectedIsingstatesButton   matlab.ui.control.Button
        ShowdetectednonIsingstatesButton  matlab.ui.control.Button
        nonIsingThreshold               matlab.ui.control.NumericEditField
        STDEVthresholdLabel             matlab.ui.control.Label
        showMagVector                   matlab.ui.control.CheckBox
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_49  matlab.ui.control.Label
        drop_CST                        matlab.ui.control.DropDown
        Button_CST                      matlab.ui.control.Button
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_43  matlab.ui.control.Label
        Button_FlipSpin                 matlab.ui.control.Button
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_42  matlab.ui.control.Label
        Button_RemoveMagnet             matlab.ui.control.Button
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_41  matlab.ui.control.Label
        undoAll_finalInspect            matlab.ui.control.Button
        back_finalInspect               matlab.ui.control.Button
        next_finalInspect               matlab.ui.control.Button
        Button_ZeroizeMagnet            matlab.ui.control.Button
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_14  matlab.ui.control.Label
        drop_inspectionImage            matlab.ui.control.DropDown
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_13  matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_12  matlab.ui.control.Label
        Tab_LatticeIndexing             matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        back_latticeIdx                 matlab.ui.control.Button
        next_latticeIdx                 matlab.ui.control.Button
        ButtonRefMagSelect              matlab.ui.control.Button
        ForthesquareandKagomeLabel      matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_11  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Tab_Postprocessing              matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        SpinspincorrelatorPanel         matlab.ui.container.Panel
        PairwiseTxt                     matlab.ui.control.Label
        correlation_localMap            matlab.ui.control.CheckBox
        correlation_Magnetostatic       matlab.ui.control.CheckBox
        correlation_DotBinary           matlab.ui.control.CheckBox
        correlation_SpinDot             matlab.ui.control.CheckBox
        correlation_nbrMovie            matlab.ui.control.CheckBox
        next_postProcess                matlab.ui.control.Button
        back_postProcess                matlab.ui.control.Button
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_15  matlab.ui.control.Label
        MagneticstructurefactorPanel    matlab.ui.container.Panel
        GenerateMSFCheckBox             matlab.ui.control.CheckBox
        InputascanningdistanceLabel_25  matlab.ui.control.Label
        MSFSteps                        matlab.ui.control.NumericEditField
        MSFStart                        matlab.ui.control.NumericEditField
        InputascanningdistanceLabel_27  matlab.ui.control.Label
        MSFEnd                          matlab.ui.control.NumericEditField
        InputascanningdistanceLabel_26  matlab.ui.control.Label
        VertexandmagnetizationdetectionPanel  matlab.ui.container.Panel
        VertexcountsCheckBox            matlab.ui.control.CheckBox
        ContrastdetectedMCheckBox       matlab.ui.control.CheckBox
        LatticecoordinatesCheckBox      matlab.ui.control.CheckBox
        ContrastvertextypeCheckBox      matlab.ui.control.CheckBox
        Tab_Finish                      matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        startAgain_finish               matlab.ui.control.Button
        Dialog1                         matlab.ui.control.Label
        Tab_Reprocess                   matlab.ui.container.Tab
        GridLayout7_9                   matlab.ui.container.GridLayout
        BrickmodeDropDown               matlab.ui.control.DropDown
        BrickmodeDropDownLabel          matlab.ui.control.Label
        Reprocess_Postprocess           matlab.ui.control.Button
        Reprocess_Inspect               matlab.ui.control.Button
        Reprocess_Back                  matlab.ui.control.Button
        Dialog1_2                       matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu_2                          matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
    end

    % Variable dungeon
    properties (Access = public)
        % For vertex detection
        dirMexOpenCV = pwd;     % (char)    Directory for MexOpenCV
        dirImages = pwd;        % (char)    Directory for image files
        FileName                % (char)    File name of imported EMD file
        emdGlobalRef1           % (matrix)  Stores all the values of the EMD intensity associated with reference 1 and the ASI topography
        emdGlobalRef2           % (matrix)  Stores all the values of the EMD intensity associated with reference 2 and the ASI topography
        emdGlobalRef3
        emdGlobalRef4
        emdGlobalRef5
        emdGlobalRef6
        emdGlobalRef7
        emdGlobalRef8
        gridHeight              % (int)     Height of xasGrid
        gridWidth               % (int)     Width of xasGrid
        iceGrid                 % (image)   Duplicate of xasGrid image to be used in EMD calculation
        iRangeSize              % (int)     i-scanning range for EMD calculation
        isSquareIce             % (logical) Logical to describe whether the analyzed array is a square ASI or not.
        jRangeSize              % (int)     j-scanning range for EMD calculation
        refFrame1               % (image)   First reference image of a vertex. Used for square, brick, and kagome
        refFrame2               % (image)   Second reference image of a vertex. Used only for brick and kagome
        refFrame3
        refFrame4
        refFrame5
        refFrame6
        refFrame7
        refFrame8
        refFrame1_raw           % (image)   Lazy bone-headed way of defining variables to store raw reference vertex images
        refFrame2_raw           
        refFrame3_raw
        refFrame4_raw
        refFrame5_raw
        refFrame6_raw
        refFrame7_raw
        refFrame8_raw
        typeASI                 % (str)     Type of ASI: 1 for square, 2 for brick, 3 for Kagome
        xasGrid                 % (matrix)  Topography image of the ASI structure. XAS since this was built around PEEM images.
                                %           [row,column,1 > grayscale intensity, 2 > detection source
                                %           3 > crude detection intensity, 4 > refined detect int]
        xasGrid_old             %           Duplicate of xasGrid
        xasGrid_raw             %           Raw XAS/topography image
        emdROI                  %           Rectangular ROI drawn on EMD topography image
        magnetROI               %           Rectangular ROI that represents the nanoisland area
        extWindow               % (struct)  Create an external figure for displaying images
        
        % For processing vertex detection data (both pre- and post-processing)
        dialogSel               % (char?)   Dialogue selection for determining next steps for vertex processing
        dialogTitle             % (char?)   Title for dialog box
        dialogMsg               % (char?)   Message for dialog box
        dialogButtons           % (cell)    Different button options for dialog box
        vd = struct('name','')  % (struct)  Pre- or post-processed vertex detection data (vertex detection output)
    end
    methods (Access = private)
        % Alters the magnetic contrast image based on user-provided flatfield sigma and threshold
        function alterMagneticContrast(app)
            % Changes the magnetic contrast image based on the provided flatfield sigma
            app.vd.xmcd = magContrastEdit(app.vd.xmcdOriginal,app.magContrast_sigma.Value,...
                app.magContrast_cutoff.Value);
            % Show the initial asymmetry image
            app.vd.xmcdBoost = imadjust(app.vd.xmcd,[0.4,0.6],[],0.5);
            %general_imageDisplay(app,app.AxesImportMagneticContrast,mat2gray(app.vd.xmcd));
            general_imageDisplay(app,app.extWindow.axes,mat2gray(app.vd.xmcd));
            % Save a binarized image (really a trinary image)
            % For background subtraction, we first bin the intensities into
            % 50 bins, then take the mode (assuming most frequent intensity is "gray")
            app.vd.xmcdBinary = floor(mat2gray(app.vd.xmcd)*50);
            binMode = mode(app.vd.xmcdBinary,'all');
            binSTD = std(app.vd.xmcdBinary,0,'all');
            app.vd.xmcdBinary(app.vd.xmcdBinary < binMode - binSTD/2) = -1;
            app.vd.xmcdBinary(app.vd.xmcdBinary > binMode + binSTD/2) = 1;
            app.vd.xmcdBinary(abs(app.vd.xmcdBinary) ~= 1) = 0;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            currentStatus = uiprogressdlg(app.IceScannerUI,'Message','Please wait.','Indeterminate',"on");
            close all;
            app.extWindow.figure = figure("Name","IceScanner - External Window",...
                "NumberTitle","off");
            app.extWindow.axes = axes(app.extWindow.figure,...
                'Position',[0,0,1,1]);
            movegui(app.extWindow.figure,'center');
            movegui(app.IceScannerUI,'center');
            pause(5);
            close(currentStatus);
        end

        % Callback function
        function imageProcess_importMAT(app, event)
            % Get the file name and directory for the MAT file containing either the pre-
            % or post-processed vertex EMD data
            [FileName,PathName] = uigetfile('*.mat','Select the pre- or post-processed vertex output file');
            if FileName == 0
                return
            end
            app.vertexEMDOutput = load(fullfile(PathName,FileName));
        end

        % Button pushed function: ButtonImportRefImg1
        function vertexDetect_importRefImg1(app, event)
            % Import 1st reference image
            switch app.topographyMode.Value
                case 'XA-PEEM'
                    app.refFrame1_raw = imcrop(imflatfield(app.xasGrid_raw,app.EMD_flatfieldSigma.Value),round(app.emdROI.Position));
                case 'AFM'
                    app.refFrame1_raw = imcrop(app.xasGrid_raw,round(app.emdROI.Position));
            end
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame1,mat2gray(app.refFrame1_raw));
        end

        % Button pushed function: ButtonImportRefImg2
        function vertexDetect_importRefImg2(app, event)
            % Import 2nd reference image
            switch app.topographyMode.Value
                case 'XA-PEEM'
                    app.refFrame2_raw = imcrop(imflatfield(app.xasGrid_raw,app.EMD_flatfieldSigma.Value),round(app.emdROI.Position));
                case 'AFM'
                    app.refFrame2_raw = imcrop(app.xasGrid_raw,round(app.emdROI.Position));
            end
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame2,mat2gray(app.refFrame2_raw));
        end

        % Button pushed function: ButtonImportRefImg3
        function vertexDetect_importRefImg3(app, event)
            % Import 3rd reference image
            switch app.topographyMode.Value
                case 'XA-PEEM'
                    app.refFrame3_raw = imcrop(imflatfield(app.xasGrid_raw,app.EMD_flatfieldSigma.Value),round(app.emdROI.Position));
                case 'AFM'
                    app.refFrame3_raw = imcrop(app.xasGrid_raw,round(app.emdROI.Position));
            end
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame3,mat2gray(app.refFrame3_raw));
        end

        % Button pushed function: ButtonImportRefImg4
        function vertexDetect_importRefImg4(app, event)
            % Import 4th reference image
            switch app.topographyMode.Value
                case 'XA-PEEM'
                    app.refFrame4_raw = imcrop(imflatfield(app.xasGrid_raw,app.EMD_flatfieldSigma.Value),round(app.emdROI.Position));
                case 'AFM'
                    app.refFrame4_raw = imcrop(app.xasGrid_raw,round(app.emdROI.Position));
            end
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame4,mat2gray(app.refFrame4_raw));
        end

        % Button pushed function: ButtonImportRefImg5
        function vertexDetect_importRefImg5(app, event)
            % Import 5th reference image
            switch app.topographyMode.Value
                case 'XA-PEEM'
                    app.refFrame5_raw = imcrop(imflatfield(app.xasGrid_raw,app.EMD_flatfieldSigma.Value),round(app.emdROI.Position));
                case 'AFM'
                    app.refFrame5_raw = imcrop(app.xasGrid_raw,round(app.emdROI.Position));
            end
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame5,mat2gray(app.refFrame5_raw));
        end

        % Button pushed function: ButtonImportRefImg6
        function vertexDetect_importRefImg6(app, event)
            % Import 6th reference image
            switch app.topographyMode.Value
                case 'XA-PEEM'
                    app.refFrame6_raw = imcrop(imflatfield(app.xasGrid_raw,app.EMD_flatfieldSigma.Value),round(app.emdROI.Position));
                case 'AFM'
                    app.refFrame6_raw = imcrop(app.xasGrid_raw,round(app.emdROI.Position));
            end
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame6,mat2gray(app.refFrame6_raw));
        end

        % Button pushed function: ButtonImportRefImg7
        function vertexDetect_importRefImg7(app, event)
            % Import 7th reference image
            switch app.topographyMode.Value
                case 'XA-PEEM'
                    app.refFrame7_raw = imcrop(imflatfield(app.xasGrid_raw,app.EMD_flatfieldSigma.Value),round(app.emdROI.Position));
                case 'AFM'
                    app.refFrame7_raw = imcrop(app.xasGrid_raw,round(app.emdROI.Position));
            end
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame7,mat2gray(app.refFrame7_raw));
        end

        % Button pushed function: ButtonImportRefImg8
        function vertexDetect_importRefImg8(app, event)
            % Import 8th reference image
            switch app.topographyMode.Value
                case 'XA-PEEM'
                    app.refFrame8_raw = imcrop(imflatfield(app.xasGrid_raw,app.EMD_flatfieldSigma.Value),round(app.emdROI.Position));
                case 'AFM'
                    app.refFrame8_raw = imcrop(app.xasGrid_raw,round(app.emdROI.Position));
            end
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame8,mat2gray(app.refFrame8_raw));
        end

        % Value changed function: img1Threshold
        function vertexDetect_adjThresholdFactor1(app, event)
            % Plot the rough detection points
            vertexDetect_roughDetection(app);
        end

        % Value changed function: img2Threshold
        function vertexDetect_adjThresholdFactor2(app, event)
            % Plot the rough detection points
            vertexDetect_roughDetection(app);
        end

        % Value changed function: img3Threshold
        function vertexDetect_adjThresholdFactor3(app, event)
            % Plot the rough detection points
            vertexDetect_roughDetection(app);
        end

        % Value changed function: img4Threshold
        function vertexDetect_adjThresholdFactor4(app, event)
            % Plot the rough detection points
            vertexDetect_roughDetection(app);
        end

        % Value changed function: img5Threshold
        function vertexDetect_adjThresholdFactor5(app, event)
            % Plot the rough detection points
            vertexDetect_roughDetection(app);
        end

        % Value changed function: img6Threshold
        function vertexDetect_adjThresholdFactor6(app, event)
            % Plot the rough detection points
            vertexDetect_roughDetection(app);
        end

        % Value changed function: img7Threshold
        function vertexDetect_adjThresholdFactor7(app, event)
            % Plot the rough detection points
            vertexDetect_roughDetection(app);
        end

        % Value changed function: img8Threshold
        function vertexDetect_adjThresholdFactor8(app, event)
            % Plot the rough detection points
            vertexDetect_roughDetection(app);
        end

        % Button pushed function: TopographyimageButton
        function vertexDetect_importTopography(app, event)
            % Get the file name and directory for the image file containing the ASI topography
            oldDirImages = app.dirImages;
            % Processing depends on whether or not the image was acquired at PEEM3/PEEMViewer or Park XEI
            switch app.topographyMode.Value
                case 'XA-PEEM'
                    [FileName,app.dirImages] = uigetfile({'*.tif';'*.tiff'},'Select XA-PEEM image',app.dirImages);
                    if ~ischar(FileName)
                        app.dirImages = oldDirImages;
                        return;
                    end
                    app.xasGrid_raw = imread(fullfile(app.dirImages,FileName));
                    
                case 'AFM'
                    [FileName,app.dirImages] = uigetfile({'*.txt'},'Select XEI-exported text file',app.dirImages);
                    if ~ischar(FileName)
                        app.dirImages = oldDirImages;
                        return;
                    end
                    % First, import text file as table
                    T = readtable(fullfile(app.dirImages,FileName));
                    % Process the image through readSPMImage
                    app.xasGrid_raw = readSPMImage(T);
            end
            
            app.xasGrid = app.xasGrid_raw;
            % Initial processing of the topography image
            switch app.topographyMode.Value
                case 'XA-PEEM'
                    app.iceGrid = vertexDetect_imgPreprocess(app,imflatfield(app.xasGrid_raw,app.EMD_flatfieldSigma.Value));
                    app.xasGrid = abs(mat2gray(imflatfield(app.xasGrid_raw,app.EMD_flatfieldSigma.Value)));
                case 'AFM'
                    app.iceGrid = vertexDetect_imgPreprocess(app,app.xasGrid_raw);
                    app.xasGrid = abs(mat2gray(app.xasGrid_raw));
            end
            
            % Display image in figure window
            general_imageDisplay(app,app.extWindow.axes,app.xasGrid(:,:,1));
            % Modifying topography images to prevent crashing in EMD calculation associated with zeros
            app.iceGrid(app.iceGrid == 0) = 0.01;
            app.xasGrid(app.xasGrid == 0) = 0.01;
            % For XAS Grid, we need to introduce additional dimensions for subsequent processing via EMD
            app.xasGrid(:,:,5) = zeros(size(app.xasGrid));
            % Update the height and width of xasGrid
            [app.gridHeight,app.gridWidth,~] = size(app.xasGrid);
            delete(app.emdROI);
            app.emdROI = drawrectangle(app.extWindow.axes,'AspectRatio',1,'Deletable',false,'FaceAlpha',0.1,...
                'InteractionsAllowed','translate','MarkerSize',5,...
                'Position',[round(app.gridHeight/2),round(app.gridWidth/2),...
                app.EMD_edgeLengthROI.Value,app.EMD_edgeLengthROI.Value]);
        end

        % Value changed function: DropDownASIType
        function vertexDetect_adjTypeASI(app, event)
            % Determine the type of ASI analyzed for vertex detection
            app.vd.typeASI = app.DropDownASIType.Value;
            % Updates the value of isSquareIce to denote that more than 1 unique vertex is required to fully describe system
            app.isSquareIce = strcmp(app.vd.typeASI,'Square');
            % Update status window
            switch app.vd.typeASI
                case 'Square'
                    app.ButtonImportRefImg2.Enable = 'off';
                    app.ButtonImportRefImg3.Enable = 'off';
                    app.ButtonImportRefImg4.Enable = 'off';
                    app.ButtonImportRefImg5.Enable = 'off';
                    app.ButtonImportRefImg6.Enable = 'off';
                    app.ButtonImportRefImg7.Enable = 'off';
                    app.ButtonImportRefImg8.Enable = 'off';
                    app.img2Threshold.Enable = 'off';
                    app.img3Threshold.Enable = 'off';
                    app.img4Threshold.Enable = 'off';
                    app.img5Threshold.Enable = 'off';
                    app.img6Threshold.Enable = 'off';
                    app.img7Threshold.Enable = 'off';
                    app.img8Threshold.Enable = 'off';
                case {'Brickwork','Kagome'}
                    app.ButtonImportRefImg2.Enable = 'on';
                    app.ButtonImportRefImg3.Enable = 'off';
                    app.ButtonImportRefImg4.Enable = 'off';
                    app.ButtonImportRefImg5.Enable = 'off';
                    app.ButtonImportRefImg6.Enable = 'off';
                    app.ButtonImportRefImg7.Enable = 'off';
                    app.ButtonImportRefImg8.Enable = 'off';
                    app.img2Threshold.Enable = 'on';
                    app.img3Threshold.Enable = 'off';
                    app.img4Threshold.Enable = 'off';
                    app.img5Threshold.Enable = 'off';
                    app.img6Threshold.Enable = 'off';
                    app.img7Threshold.Enable = 'off';
                    app.img8Threshold.Enable = 'off';
                case 'Tetris'
                    app.ButtonImportRefImg2.Enable = 'on';
                    app.ButtonImportRefImg3.Enable = 'on';
                    app.ButtonImportRefImg4.Enable = 'on';
                    app.ButtonImportRefImg5.Enable = 'on';
                    app.ButtonImportRefImg6.Enable = 'on';
                    app.ButtonImportRefImg7.Enable = 'on';
                    app.ButtonImportRefImg8.Enable = 'on';
                    app.img2Threshold.Enable = 'on';
                    app.img3Threshold.Enable = 'on';
                    app.img4Threshold.Enable = 'on';
                    app.img5Threshold.Enable = 'on';
                    app.img6Threshold.Enable = 'on';
                    app.img7Threshold.Enable = 'on';
                    app.img8Threshold.Enable = 'on';
                otherwise
                    uialert(app.IceScannerUI,'Please change the ASI type.','Error');
                    app.ButtonImportRefImg2.Enable = 'off';
                    app.ButtonImportRefImg3.Enable = 'off';
                    app.ButtonImportRefImg4.Enable = 'off';
                    app.ButtonImportRefImg5.Enable = 'off';
                    app.ButtonImportRefImg6.Enable = 'off';
                    app.ButtonImportRefImg7.Enable = 'off';
                    app.ButtonImportRefImg8.Enable = 'off';
                    app.img2Threshold.Enable = 'off';
                    app.img3Threshold.Enable = 'off';
                    app.img4Threshold.Enable = 'off';
                    app.img5Threshold.Enable = 'off';
                    app.img6Threshold.Enable = 'off';
                    app.img7Threshold.Enable = 'off';
                    app.img8Threshold.Enable = 'off';
            end
        end

        % Button pushed function: next_image
        function vertexDetectButton_fromReferenceToThreshold(app, event)
            % Executes Earth Movers Distance calculation
            % First, recalculate iceGrid and reference magnet resizing
            switch app.topographyMode.Value
                case 'XA-PEEM'
                    app.iceGrid = vertexDetect_imgPreprocess(app,imflatfield(app.xasGrid_raw,app.EMD_flatfieldSigma.Value));
                case 'AFM'
                    app.iceGrid = vertexDetect_imgPreprocess(app,app.xasGrid_raw);
            end
            
            app.iceGrid(app.iceGrid == 0) = 0.01;
            % Second, recalculate the reference image resizing
            app.refFrame1 = vertexDetect_imgPreprocess(app,app.refFrame1_raw);
            switch app.vd.typeASI
                case {'Brickwork','Kagome'}
                    app.refFrame2 = vertexDetect_imgPreprocess(app,app.refFrame2_raw);
                case 'Tetris'
                    app.refFrame2 = vertexDetect_imgPreprocess(app,app.refFrame2_raw);
                    app.refFrame3 = vertexDetect_imgPreprocess(app,app.refFrame3_raw);
                    app.refFrame4 = vertexDetect_imgPreprocess(app,app.refFrame4_raw);
                    app.refFrame5 = vertexDetect_imgPreprocess(app,app.refFrame5_raw);
                    app.refFrame6 = vertexDetect_imgPreprocess(app,app.refFrame6_raw);
                    app.refFrame7 = vertexDetect_imgPreprocess(app,app.refFrame7_raw);
                    app.refFrame8 = vertexDetect_imgPreprocess(app,app.refFrame8_raw);
            end
            vertexDetect_EMD(app);
            app.TabGroupEMD.SelectedTab = app.Tab_Threshold;
            app.arrow_importImg.Visible = 0;
            app.steps_importImg.Enable = 0;
            app.arrow_thresholding.Visible = 1;
            app.steps_thresholding.Enable = 1;  
        end

        % Button pushed function: back_threshold
        function vertexDetectButton_fromThresholdToReference(app, event)
            app.TabGroupEMD.SelectedTab = app.Tab_Reference;
            app.arrow_importImg.Visible = 1;
            app.steps_importImg.Enable = 1;
            app.arrow_thresholding.Visible = 0;
            app.steps_thresholding.Enable = 0;
        end

        % Button pushed function: SaveresultsButton
        function vertexDetectButton_fromThresholdToFinish(app, event)
            % Save all the relevant "app" variables to the local structure "vd"
            vd.dirMexOpenCV = app.dirMexOpenCV;   
            vd.dirImages = app.dirImages;      
            vd.emdGlobalRef1 = app.emdGlobalRef1;           
            vd.emdGlobalRef2 = app.emdGlobalRef2;
            vd.gridHeight = app.gridHeight;        
            vd.gridWidth = app.gridWidth;              
            vd.iceGrid = app.iceGrid;                 
            vd.iRangeSize = app.iRangeSize;              
            vd.isSquareIce = app.isSquareIce;             
            vd.jRangeSize = app.jRangeSize;              
            vd.refFrame1 = app.refFrame1;               
            vd.refFrame2 = app.refFrame2;               
            vd.scalingFactor = app.EMD_scaleFactor.Value;  
            vd.skipInterval = app.EMD_skipInterval.Value;       
            vd.typeASI = app.vd.typeASI;                 
            vd.xasGrid = app.xasGrid;
            % Exports the results
            oldDirImages = app.dirImages;
            [FileName,app.dirImages] = uiputfile(strcat(app.dirImages,'*.mat'),'Save vertex output file');
            if ~ischar(FileName)
                app.dirImages = oldDirImages;
                return;
            end
            save(fullfile(app.dirImages,FileName),'-struct','vd');
            % Message
            app.TabGroupEMD.SelectedTab = app.Tab_FinishEMD;
            app.arrow_finishEMD.Visible = 1;
            app.steps_finishEMD.Enable = 1;
            app.arrow_thresholding.Visible = 0;
            app.steps_thresholding.Enable = 0;
        end

        % Button pushed function: finish_scanNewImage
        function vertexDetectButton_fromFinishToReference(app, event)
            app.TabGroupEMD.SelectedTab = app.Tab_Reference;
            app.arrow_importImg.Visible = 1;
            app.steps_importImg.Enable = 1;
            app.arrow_finishEMD.Visible = 0;
            app.steps_finishEMD.Enable = 0;
        end

        % Button pushed function: browse_importEMDResults
        function imgProcess_importEMDResults(app, event)
            % Open dialogue for importing vertex detection data
            oldDirImages = app.dirImages;
            [app.FileName,app.dirImages] = uigetfile({'*.mat'},'Select vertex detection *.mat file',app.dirImages);
            if ~ischar(app.FileName)
                app.dirImages = oldDirImages;
                return;
            end
            % Load the file
            app.vd = load(fullfile(app.dirImages,app.FileName));
            % Show path to the EMD file
            app.Field_detectionPath.Value = sprintf('%s%s',app.dirImages,app.FileName);
        end

        % Button pushed function: browse_importMagContrast
        function imgProcess_importMagContrast(app, event)
            % Open dialogue for importing XMCD image
            oldDirImages = app.dirImages;
            
            % Processing depends on whether or not the image was acquired at PEEM3/PEEMViewer or Park XEI
            switch app.contrastMode.Value
                case 'XMCD-PEEM'
                    [FileName,app.dirImages] = uigetfile({'*.tif';'*.tiff'},'Select XMCD-PEEM image',app.dirImages);
                    if ~ischar(FileName)
                        app.dirImages = oldDirImages;
                        return;
                    end
                    % Import xmcd raw xmcd image
                    app.vd.xmcd = double(imread(fullfile(app.dirImages,FileName))); 
                    
                case 'MFM'
                    [FileName,app.dirImages] = uigetfile({'*.txt'},'Select XEI-exported text file',app.dirImages);
                    if ~ischar(FileName)
                        app.dirImages = oldDirImages;
                        return;
                    end
                    % First, import text file as table
                    T = readtable(fullfile(app.dirImages,FileName));
                    % Process the image through readSPMImage
                    app.vd.xmcd = readSPMImage(T);
            end
            % Duplicate xmcd image for viewing
            app.vd.xmcdOriginal = app.vd.xmcd;
            % Show the initial asymmetry image
            app.vd.xmcdBoost = imadjust(mat2gray(app.vd.xmcd,[-double(max(app.vd.xmcd,[],'all')/2),double(max(app.vd.xmcd,[],'all')/2)])...
                ,[0.4,0.6],[],0.5);
            %general_imageDisplay(app,app.AxesImportMagneticContrast,mat2gray(app.vd.xmcd));
            general_imageDisplay(app,app.extWindow.axes,mat2gray(app.vd.xmcd));
            % Save a binarized image (really a trinary image)
            % For background subtraction, we first bin the intensities into
            % 50 bins, then take the mode (assuming most frequent intensity is "gray")
            app.vd.xmcdBinary = floor(mat2gray(app.vd.xmcd)*50);
            binMode = mode(app.vd.xmcdBinary,'all');
            binSTD = std(app.vd.xmcdBinary,0,'all');
            app.vd.xmcdBinary(app.vd.xmcdBinary < binMode - binSTD/2) = -1;
            app.vd.xmcdBinary(app.vd.xmcdBinary > binMode + binSTD/2) = 1;
            app.vd.xmcdBinary(abs(app.vd.xmcdBinary) ~= 1) = 0;
            % Show path to the magnetic contrast image file
            app.Field_magImagePath.Value = sprintf('%s%s',app.dirImages,FileName);
        end

        % Value changed function: EditFieldVertexReduction
        function imgProcess_reduceEMDPoints(app, event)
            % Performs the vertex reduction by setting a minimum distance between vertices
            switch app.processedBefore.Value
                case 'No' % For the case where the data has not been processed before
                    % First, reset xasGrid
                    app.vd.xasGrid = app.vd.xasOld;
                    % Reset minLoc
                    app.vd.minLoc = app.vd.minLocOld;
                    % Save the scan distance
                    scnDst = app.EditFieldVertexReduction.Value;
                    pause(1);
                    f = uiprogressdlg(app.IceScannerUI,'Title','Vertex reduction running');
                    % Establish radial scan area
                    initialScanX = scnDst * cos(app.vd.thetaRange);
                    initialScanY = scnDst * sin(app.vd.thetaRange);
                    % Double scan to eliminate any remaining duplicates after the first scan
                    for j = 1:2
                        for i = 1:length(app.vd.minLoc)
                            f.Value = (i-1)/(2*length(app.vd.minLoc))+(j-1)*0.5;
                            ii = 0;
                            if app.vd.minLoc(i,1) ~= 0
                                % Find all other points close to this and determine which represents the true local minimum
                                iiMin = 0;
                                localInitScanX = initialScanX + app.vd.minLoc(i,1);
                                localInitScanY = initialScanY + app.vd.minLoc(i,2);
                                % Obtain all the indices of the vertices sitting within the polygon
                                switch app.vd.typeASI
                                    case {'Square','Kagome','Tetris'}
                                        ii = find(inpolygon(app.vd.minLoc(:,1),app.vd.minLoc(:,2),localInitScanX,localInitScanY) == 1);                                     
                                    case 'Brickwork'
                                        ii = find(inpolygon(app.vd.minLoc(:,1),app.vd.minLoc(:,2),localInitScanX,localInitScanY) == 1 & ...
                                           app.vd.minLoc(:,4) == app.vd.minLoc(i,4));
                                    otherwise
                                        uialert(app.IceScannerUI,'Please change the ASI type.','Error','Icon','error');
                                        return;
                                end
        
                                % Figure out which of the selected indices contains the local minima
                                % If only one vertex was found within the polygon
                                if length(ii) == 1
                                    app.vd.xasGrid(app.vd.minLoc(ii,1),app.vd.minLoc(ii,2),4) = app.vd.minLoc(ii,3);
                                % If multiple vertices were detected within the polygon
                                else
                                    % Within "ii", find the vertex/vertices that are the local minimum/minima
                                    iiMin = find(app.vd.minLoc(ii,3) == min(app.vd.minLoc(ii,3)));
        
                                    % If only one minimum exists
                                    if length(iiMin) == 1
                                        app.vd.minLoc(ii(app.vd.minLoc(ii,3) ~= min(app.vd.minLoc(ii(iiMin),3))),:) = 0;
                                        app.vd.xasGrid(app.vd.minLoc(ii(iiMin),1),app.vd.minLoc(ii(iiMin),2),4) = app.vd.minLoc(ii(iiMin),3);
                                    
                                        % If multiple minima exists
                                    else
                                        % Condition if, for the second scan, vertices of different references exist
                                        if all(app.vd.minLoc(ii,4) == 1) || all(app.vd.minLoc(ii,4) == 2)
                                            app.vd.minLoc(ii(app.vd.minLoc(ii,3) ~= min(app.vd.minLoc(ii(iiMin),3))),:) = 0;
                                            avgMinX = floor(sum(app.vd.minLoc(ii(iiMin),1),'all')/length(iiMin));
                                            avgMinY = floor(sum(app.vd.minLoc(ii(iiMin),2),'all')/length(iiMin));
                                            app.vd.xasGrid(avgMinX,avgMinY,4) = app.vd.minLoc(ii(iiMin,3));
                                        else
                                            app.vd.minLoc(ii,4) = 0;
                                        end
                                    end
                                end
                            end
                        end
                        minLocator(app);
                        if j == 1
                            app.vd.xasGrid(:,:,4) = 0;
                        end
                    end
                    close(f);
                    
                    % Only for brickwork and Kagome: Double check to see if two points detected from different images are in close
                    % proximity to one another. If so, delete both
                    f = uiprogressdlg(app.IceScannerUI,'Title','Checking double reference duplicates');
                    switch app.vd.typeASI
                        case {'Brickwork','Kagome'}
                            % Create a vector to store indices with detected duplicates
                            % Scan through each point in the first reference image detection
                            for i = 1:length(app.vd.minLoc)
                                localInitScanX = initialScanX + app.vd.minLoc(i,1);
                                localInitScanY = initialScanY + app.vd.minLoc(i,2);
                                idxMin = find(inpolygon(app.vd.minLoc(:,1),app.vd.minLoc(:,2),localInitScanX,localInitScanY) == 1);
                                if length(idxMin) > 1
                                    app.vd.minLoc(i,4) = 0;
                                    app.vd.xasGrid(app.vd.minLoc(i,1),app.vd.minLoc(i,2),4) = 0;
                                end
                                f.Value = i / length(app.vd.minLoc);
                            end
                    end
                    
                    % Plot the vertices
                    vertexPlot(app,app.extWindow.axes);
                    
                case 'Yes' % For the case where the data has been processed before
                    % Plot the vertices
                    vertexPlot(app,app.extWindow.axes);
                otherwise
                    uialert(app.IceScannerUI,'Title','Invalid processing status','Message',...
                        'Please indicate the processing status of the imported data.');
            end
            
            % Enable the manual point editing buttons
            app.ButtonRemovePts.Enable = 1;
            app.ButtonAddPts.Enable = 1;
            app.next_cleanUp.Enable = 1;
        end

        % Button pushed function: ButtonRemovePts
        function imgProcess_removeVertices(app, event)
            %%%%%%%%%% Manual removal of garbage points %%%%%%%%%%
            % Show the analysis window
            %app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
            app.extWindow.axes.XLim = [0 app.vd.gridHeight];
            app.extWindow.axes.YLim = [0 app.vd.gridWidth];
            deletionFigure = figure('Name','Select vertices to delete');
            deletionFigure.MenuBar = 'none';
            deletionFigure.ToolBar = 'none';
            deletionAxes = axes(deletionFigure);
            movegui(deletionFigure,'center');
            vertexPlot(app,deletionAxes)
            set(deletionAxes,'position',[0 0 1 1],'units','normalized');
            % Display message box indicating to user the operation of the point removal/adder step
            removalDialog = uiprogressdlg(app.IceScannerUI,'Title','Please view the pop-up figure window','Message',...
                strcat('In the pop-up figure window, click near the existing vertex detection points you wish to remove. To undo the last',...
                ' selection, press the delete/backspace key. You may also resize this window. Once you are done, press the enter key. For this to',...
                ' work, the last object you click must be somewhere within the analysis window.'),...
                'Indeterminate','on');
            % Deletion script
            try
                [iRemove, jRemove] = getpts(deletionAxes);
                jRemove = floor(jRemove);
                iRemove = floor(iRemove);
                for i = 1:length(jRemove)
                    app.vd.xasGrid(jRemove(i)-10:jRemove(i)+10,iRemove(i)-10:iRemove(i)+10,4) = 0;
                end
            catch ME
                errorNotice(app,ME);
                close(deletionFigure);
                close(removalDialog);
                return;
            end
            close(deletionFigure);
            close(removalDialog);
            minLocator(app);
            vertexPlot(app,app.extWindow.axes);
        end

        % Button pushed function: ButtonAddPts
        function imgProcess_addVertices(app, event)
            %%%%%%%%%% Manual addition of vertex points %%%%%%%%%%
            try
                %app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
                app.extWindow.axes.XLim = [0 app.vd.gridHeight];
                app.extWindow.axes.YLim = [0 app.vd.gridWidth];
                switch app.vd.typeASI
                    case {'Square','Kagome','Tetris'}
                        removalDialog = uiprogressdlg(app.IceScannerUI,'Title','Please view the pop-up figure window','Message',...
                            strcat('In the pop-up figure window, click the points where you would like to add a vertex. To undo the last selection, ',...
                            'press the delete/backspace key. Once you are done, press the enter key. For this to work, the last object you click ',...
                            'must be somewhere within the analysis window.'),'Indeterminate','on');
                        [iAdd, jAdd] = additionPlotter(app);
                        jAdd = floor(jAdd);
                        iAdd = floor(iAdd);
                        for i = 1:length(jAdd)
                            app.vd.xasGrid(jAdd(i),iAdd(i),4) = 1;
                            app.vd.xasGrid(jAdd(i),iAdd(i),2) = 1;
                        end 
                    case 'Brickwork'
                        removalDialog = uiprogressdlg(app.IceScannerUI,'Title','Please view the pop-up figure window','Message',...
                            strcat('In the pop-up figure window, click the points where you would like to add a vertex associated with the FIRST ',...
                            ' reference frame (BLUE PLUS). To undo the last selection, press the delete/backspace key. Once you are done, press the ',...
                            'enter key. For this to work, the last object you click must be somewhere within the analysis window.'),...
                            'Indeterminate','on');
                        [iAdd, jAdd] = additionPlotter(app);
                        jAdd = floor(jAdd);
                        iAdd = floor(iAdd);
                        for i = 1:length(jAdd)
                            app.vd.xasGrid(jAdd(i),iAdd(i),4) = 1;
                            app.vd.xasGrid(jAdd(i),iAdd(i),2) = 1; %#ok<*SAGROW>
                        end 
                        minLocator(app);
                        close(removalDialog);
                        pause(1);
                        removalDialog = uiprogressdlg(app.IceScannerUI,'Title','Please view the pop-up figure window','Message',...
                            strcat('In the pop-up figure window, click the points where you would like to add a vertex associated with the SECOND ',...
                            ' reference frame (GREEN X). To undo the last selection, press the delete/backspace key. Once you are done, press the ',...
                            'enter key. For this to work, the last object you click must be somewhere within the analysis window.'),...
                            'Indeterminate','on');
                        [iAdd, jAdd] = additionPlotter(app);
                        jAdd = floor(jAdd);
                        iAdd = floor(iAdd);
                        for i = 1:length(jAdd)
                            app.vd.xasGrid(jAdd(i),iAdd(i),4) = 1;
                            app.vd.xasGrid(jAdd(i),iAdd(i),2) = 2; %#ok<*SAGROW>
                        end
                        pause(1);
                end
            catch ME
                errorNotice(app,ME);
            end
            close(removalDialog);
            minLocator(app);
            vertexPlot(app,app.extWindow.axes);
        end

        % Value changed function: EditFieldVertexIndex
        function imgProcess_displayScanPoint(app, event)
            % Selects the index of the vertex to be used to previewing the neighbor scan
            app.vd.pointSelect = app.EditFieldVertexIndex.Value;
            % Filter to reject zero or values beyond length of vertex
            if app.vd.pointSelect > 0 && app.vd.pointSelect <= length(app.vd.vertex)
                %app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
                general_imageDisplay(app,app.extWindow.axes,app.vd.xasGrid(:,:,1))
                hold(app.extWindow.axes,'on')
                xPos = app.vd.vertex(app.vd.pointSelect).colXPos;
                yPos = app.vd.vertex(app.vd.pointSelect).rowYPos;
                plot(app.extWindow.axes,xPos,yPos,'b.','MarkerSize',20);
                app.extWindow.axes.XLim = [0 app.vd.gridHeight];
                app.extWindow.axes.YLim = [0 app.vd.gridWidth];
            else
                uialert(app.IceScannerUI,sprintf('Please input a value between 1 and %d (max index value).', length(app.vd.vertex)),'Error',...
                    'Icon',"error");
            end
        end

        % Button pushed function: ButtonPreviewScan
        function imgProcess_displayScanArea(app, event)
            try
                % Plot the preview of the scan area around the chosen vertex index
                % Show the analysis window
                %app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
                % Show the topography image
                general_imageDisplay(app,app.extWindow.axes,app.vd.xasGrid(:,:,1));
                hold(app.extWindow.axes,'on');
                vertex = app.vd.vertex;
                pointSelect = app.vd.pointSelect;
                switch app.vd.typeASI
                    case {'Square', 'Brickwork','Tetris'}
                        % Variable duplication
                        cross1Width = app.scanCross1Width.Value;
                        cross1Height = app.scanCross1Height.Value;
                        cross1Angle = app.scanCross1Angle.Value;
                        cross2Width = app.scanCross2Width.Value;
                        cross2Height = app.scanCross2Height.Value;
                        cross2Angle = app.scanCross2Angle.Value;
                        cross1PerimeterX = cross1Width * [-1:0.02:1,ones(1,length(-1:0.02:1)),1:-0.02:-1,-ones(1,length(-1:0.02:1))];
                        cross1PerimeterY = cross1Height * [ones(1,length(-1:0.02:1)),1:-0.02:-1,-ones(1,length(-1:0.02:1)),-1:0.02:1];
                        cross1AreaScanX = cosd(-cross1Angle)*cross1PerimeterX - sind(-cross1Angle)*cross1PerimeterY + ...
                            vertex(pointSelect).colXPos;
                        cross1AreaScanY = sind(-cross1Angle)*cross1PerimeterX + cosd(-cross1Angle)*cross1PerimeterY + ...
                            vertex(pointSelect).rowYPos;
                        cross1LocalArea = [cross1AreaScanX',cross1AreaScanY'];
                        % Remove any portions of the circle that would exceed the edge
                        cross1LocalArea(cross1LocalArea(:,1) < 1 | cross1LocalArea(:,1) > app.vd.gridWidth) = NaN;
                        cross1LocalArea(cross1LocalArea(:,2) < 1 | cross1LocalArea(:,2) > app.vd.gridHeight) = NaN;
                        cross1LocalArea(any(isnan(cross1LocalArea),2) == 1,:) = [];
                        cross2PerimeterX = cross2Width * [-1:0.02:1,ones(1,length(-1:0.02:1)),1:-0.02:-1,-ones(1,length(-1:0.02:1))];
                        cross2PerimeterY = cross2Height * [ones(1,length(-1:0.02:1)),1:-0.02:-1,-ones(1,length(-1:0.02:1)),-1:0.02:1];
                        cross2AreaScanX = cosd(-cross2Angle)*cross2PerimeterX - sind(-cross2Angle)*cross2PerimeterY + ...
                            vertex(pointSelect).colXPos;
                        cross2AreaScanY = sind(-cross2Angle)*cross2PerimeterX + cosd(-cross2Angle)*cross2PerimeterY + ...
                            vertex(pointSelect).rowYPos;
                        cross2LocalArea = [cross2AreaScanX',cross2AreaScanY'];
                        % Remove any portions of the circle that would exceed the edge
                        cross2LocalArea(cross2LocalArea(:,1) < 1 | cross2LocalArea(:,1) > app.vd.gridWidth) = NaN;
                        cross2LocalArea(cross2LocalArea(:,2) < 1 | cross2LocalArea(:,2) > app.vd.gridHeight) = NaN;
                        cross2LocalArea(any(isnan(cross2LocalArea),2) == 1,:) = [];
                        localArea = [cross1LocalArea;cross2LocalArea];
                        app.vd.cross1PerimeterX = cross1PerimeterX;
                        app.vd.cross1PerimeterY = cross1PerimeterY;
                        app.vd.cross2PerimeterX = cross2PerimeterX;
                        app.vd.cross2PerimeterY = cross2PerimeterY;
                    case 'Kagome'
                        areaScanX = app.scanDiameter.Value*cos(app.vd.thetaRange) + vertex(pointSelect).colXPos;
                        areaScanY = app.scanDiameter.Value*sin(app.vd.thetaRange) + vertex(pointSelect).rowYPos;
                        localArea = [areaScanX',areaScanY']; 
                end
                
                % Remove any portions of the scan area that would exceed the edge
                localArea(localArea(:,1) < 1 | localArea(:,1) > app.vd.gridWidth) = NaN;
                localArea(localArea(:,2) < 1 | localArea(:,2) > app.vd.gridHeight) = NaN;
                localArea(any(isnan(localArea),2) == 1,:) = [];
                % Plot
                plot(app.extWindow.axes,localArea(:,1),localArea(:,2));
                xPos = vertex(pointSelect).colXPos;
                yPos = vertex(pointSelect).rowYPos;
                plot(app.extWindow.axes,xPos,yPos,'b.','MarkerSize',20);
                hold(app.extWindow.axes,'off');
                % Enable the neighbor detection button if it is currently disabled
                if app.ButtonNeighborDetect.Enable == 0
                    app.ButtonNeighborDetect.Enable = 1;
                end
            catch ME
                errorNotice(app,ME);
            end
        end

        % Value changed function: scanCross1Width
        function imgProcess_scanCross1WidthValueChanged(app, event)
            % If the value is nonzero, set the value of the cross2 width to be the same
            if app.scanCross1Width.Value > 0
                app.scanCross2Width.Value = app.scanCross1Width.Value;
            end
            % If this value becomes zero, disable the preview scan area and neighbor detect buttons
            nbrScanButtonEnable(app);
        end

        % Value changed function: scanCross1Height
        function imgProcess_scanCross1HeightValueChanged(app, event)
            % If the value is nonzero, set the value of the cross2 height to be the same
            if app.scanCross1Height.Value > 0
                app.scanCross2Height.Value = app.scanCross1Height.Value;
            end
            % If this value becomes zero, disable the preview scan area and neighbor detect buttons
            nbrScanButtonEnable(app);
        end

        % Value changed function: scanCross2Width
        function imgProcess_scanCross2WidthValueChanged(app, event)
            % If this value becomes zero, disable the preview scan area and neighbor detect buttons
            nbrScanButtonEnable(app);
        end

        % Value changed function: scanCross2Height
        function imgProcess_scanCross2HeightValueChanged(app, event)
            % If this value becomes zero, disable the preview scan area and neighbor detect buttons
            nbrScanButtonEnable(app);
        end

        % Value changed function: scanCross2Angle
        function imgProcess_scanCross2AngleValueChanged(app, event)
            % If this value becomes zero, disable the preview scan area and neighbor detect buttons
            nbrScanButtonEnable(app);
        end

        % Value changed function: scanDiameter
        function imgProcess_scanDiameterValueChanged(app, event)
            % If this value becomes zero, disable the preview scan area and neighbor detect buttons
            nbrScanButtonEnable(app);
        end

        % Button pushed function: ButtonNeighborDetect
        function imgProcess_executeNeighborDetection(app, event)
            try
                % Scan for neighboring vertices
                % First create a waitbar to get an idea of where we're at
                f = uiprogressdlg(app.IceScannerUI,'Title','Neighbor search','Message',...
                    'Searching for neighboring vertices, assigning magnet positions, and reading magnetizations.');
                % Reset vertex
                app.vd = rmfield(app.vd,'vertex');
                app.vd.vertex = app.vd.vertexOld;
                
                % Reset magnet
                app.vd = rmfield(app.vd,'magnet');
                app.vd.magnet = app.vd.oldMagnet;
                % Clear and initialize magnet
                initializeMagnet(app)
                
                % Variable duplication
                trueMinXPos = vertcat(app.vd.vertex.colXPos);
                trueMinYPos = vertcat(app.vd.vertex.rowYPos);
                thetaRange = app.vd.thetaRange;   
                switch app.vd.typeASI
                    case {'Square','Brickwork','Tetris'}
                        diameter = 0;
                        cross1Angle = app.scanCross1Angle.Value;
                        cross2Angle = app.scanCross2Angle.Value;
                        cross1PerimeterX = app.vd.cross1PerimeterX;
                        cross1PerimeterY = app.vd.cross1PerimeterY;
                        cross2PerimeterX = app.vd.cross2PerimeterX;
                        cross2PerimeterY = app.vd.cross2PerimeterY;
                    case 'Kagome'
                        diameter = app.scanDiameter.Value;
                        cross1Angle = 0;
                        cross2Angle = 0;
                        cross1PerimeterX = 0;
                        cross1PerimeterY = 0;
                        cross2PerimeterX = 0;
                        cross2PerimeterY = 0;
                end
                
                % Conduct area scan to search for nearest neighboring vertices
                for currIndVtx = 1:length(app.vd.vertex)
                    f.Value = currIndVtx/length(app.vd.vertex);
                    % First, locate the neighboring vertices
                    neighborVertexLocator(app,currIndVtx,cross1Angle,cross1PerimeterX,cross1PerimeterY,cross2Angle,...
                        cross2PerimeterX,cross2PerimeterY,trueMinXPos,trueMinYPos,thetaRange,diameter);
                    
                    % Second, define magnets between vertices
                    magnetScanFilter(app,currIndVtx); % This seems like it's adding additional entries into nbrMagnetInd...
                    
                    % Third, assign vertex types
                    vertexTypeAssignment(app,currIndVtx); % This seems like it's adding additional entries into nbrMagnetInd...
                end
                close(f);
                
                % Look through all the magnet values and change empty projections into zero projections
                for i = 1:length(app.vd.magnet)
                    if isempty(app.vd.magnet(i).projection)
                        app.vd.magnet(i).projection = 0;
                    end
                end
                
                % Once the analysis has been completed, plot the results
               
                % First, show the analysis window with the XMCD image
                %app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
                general_imageDisplay(app,app.extWindow.axes,mat2gray(app.vd.xmcd));
                overlayVertexType(app,app.extWindow.axes);
                overlayMagnetization(app,app.extWindow.axes);
                pause(1);
                
                % Output ROI matrices
                app.vd.xmcdBinarySkeleton = app.vd.xmcdBinary;
                app.vd.xmcdBinarySkeleton(app.vd.magnetMask == 0) = 0;
                app.vd.xmcdBoostSkeleton = app.vd.xmcdBoost;
                app.vd.xmcdBoostSkeleton(app.vd.magnetMask == 0) = 1/2;
                app.vd.xmcdOriginalSkeleton = mat2gray(app.vd.xmcd,...
                    [-double(max(app.vd.xmcd,[],'all')/2),double(max(app.vd.xmcd,[],'all')/2)]);
                app.vd.xmcdOriginalSkeleton(app.vd.magnetMask == 0) = 1/2;
                app.vd.magnetInterpretCombinedImg = ...
                    mat2gray(app.vd.magnetInterpretCombined,[-double(max(app.vd.magnetInterpretCombined,[],'all')/2),...
                    double(max(app.vd.magnetInterpretCombined,[],'all')/2)]);
                
                % Display the magnetinterpretcombinedimg
                %general_imageDisplay(app,app.AxesInterpret,mat2gray(app.vd.magnetInterpretCombinedImg));
                %overlayVertexType(app,app.AxesInterpret);
                %overlayMagnetization(app,app.AxesInterpret);
                
                % Display the binarized image
                %general_imageDisplay(app,app.AxesBinary,mat2gray(app.vd.xmcdBinarySkeleton));
                %overlayVertexType(app, app.AxesBinary);
                %overlayMagnetization(app,app.AxesBinary)
               
                uialert(app.IceScannerUI,'Images of the program-interpreted magnetization and binary contrast images are ready for previewing',...
                    'Ready for previewing','Icon','success');
                % Enable the next button if it is currently disabled
                if app.next_nbrScan.Enable == 0
                    app.next_nbrScan.Enable = 1;
                end
            catch ME
                errorNotice(app,ME)
                return;
            end
        end

        % Button pushed function: ButtonRefMagSelect
        function imgProcess_selectRefMagnet(app, event)
            % Lets the user select the reference magnet state used for performing lattice indexing.
            selectionFigure = figure('Name','Select reference magnet');
            selectionAxes = axes(selectionFigure);
            movegui(selectionFigure,'center');
            vertexPlot(app,selectionAxes)
            set(selectionAxes,'position',[0 0 1 1],'units','normalized');
            
            % Display message box indicating to user the operation of the point removal/adder step
            latticeDialog = uiprogressdlg(app.IceScannerUI,'Title','Please view the pop-up figure window','Message',...
                strcat('In the pop-up figure window, click near the center of the magnet you wish to choose as the reference magnet. ',...
                'To undo the last selection, press the delete/backspace key. Once you are done, press the enter key.',...
                'For this to work, the last object you click must be somewhere within the analysis window.'),...
                'Indeterminate','on');
            
            % Selection script
            try
                [colXRef, rowYRef] = getpts(selectionAxes);
                colXRef = floor(colXRef);
                rowYRef = floor(rowYRef);
            catch ME
                errorNotice(app,ME);
                close(selectionFigure);
                close(latticeDialog);
                return;
            end
            close(selectionFigure);
            close(latticeDialog);
            % Initialize the magnet index that will reside at the origin of the generated lattice
            magnet00 = find((vertcat(app.vd.magnet.rowYPos) <= rowYRef + 10 & vertcat(app.vd.magnet.rowYPos) >= rowYRef - 10) &...
                vertcat(app.vd.magnet.colXPos) <= colXRef + 10 & vertcat(app.vd.magnet.colXPos) >= colXRef - 10);
            app.vd.magnet00 = magnet00;
            
            % Before doing anything else, initialize all index flags to be zero (old code work based on assumption that all index flags start as 0)
            for magIdx = 1:length(app.vd.magnet)
                app.vd.magnet(magIdx).indexFlag = 0;
            end
            
            % Initialize the origin state
            app.vd.magnet(magnet00).aInd = 0;
            app.vd.magnet(magnet00).bInd = 0;
            app.vd.magnet(magnet00).indexFlag = 2;
            switch app.vd.typeASI
                case 'Kagome'
                    app.vd.magnet(magnet00).orient = 1;
                    app.vd.magnet(magnet00).forkType = 1;
                case {'Brickwork','Square','Tetris'}
                    app.vd.magnet(magnet00).orient = 3;
                    app.vd.magnet(magnet00).forkType = 0;
            end
            
            % Plot the first lattice point
            %app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
            app.extWindow.axes.XLim = [0 app.vd.gridHeight];
            app.extWindow.axes.YLim = [0 app.vd.gridWidth];
            general_imageDisplay(app,app.extWindow.axes,app.vd.xasGrid(:,:,1));
            
            hold(app.extWindow.axes,'on');
            plot(app.extWindow.axes,app.vd.magnet(magnet00).colXPos,app.vd.magnet(magnet00).rowYPos,'r.','MarkerSize',10);
            text(app.extWindow.axes,app.vd.magnet(magnet00).colXPos,app.vd.magnet(magnet00).rowYPos,...
                sprintf('(%i,%i)',app.vd.magnet(magnet00).aInd,app.vd.magnet(magnet00).bInd),'Color','r')
            
            
            % Enable mapping button
            app.next_latticeIdx.Enable = 1;
        end

        % Button pushed function: Button_ZeroizeMagnet
        function imgProcess_zeroizeMagnet(app, event)
            %%%%%%%%%% Choose magnets to zeroize using specified image %%%%%%%%%%
            alterMagnet(app,'zeroize');
        end

        % Button pushed function: Button_RemoveMagnet
        function imgProcess_removeMagnet(app, event)
            %%%%%%%%%% Choose magnets to manually remove using specified image %%%%%%%%%%
            alterMagnet(app,'ignore');
        end

        % Button pushed function: Button_FlipSpin
        function imgProcess_flipMagnet(app, event)
            %%%%%%%%%% Choose magnets to manually flip magnetizations using specified image %%%%%%%%%%
            alterMagnet(app,'flip');
        end

        % Button pushed function: undoAll_finalInspect
        function imgProcess_undoMagnetizationChange(app, event)
            %%%%%%%%%% Reverses any deletion of the magnetization %%%%%%%%%%
            app.vd.magnet = app.vd.oldMagnet;
            %app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
            general_imageDisplay(app,app.extWindow.axes,mat2gray(app.vd.xmcd));
            overlayMagnetization(app,app.extWindow.axes);
            overlayVertexType(app,app.extWindow.axes);
            uialert(app.IceScannerUI,'Magnetization data restored.','Undo all','Icon','success');
        end

        % Menu selected function: MenuImgProcSaveState
        function menuAdvanced_earlyExportResults(app, event)
            % Save the current image processing state
            [FileName,PathName] = uiputfile('*.mat','Save data');
            vd = app.vd;
            if ischar(FileName)
                save(fullfile(PathName,FileName),'-struct','vd');
            end
        end

        % Menu selected function: MenuDiagnosticImport
        function menuAdvanced_earlyImportResults(app, event)
            % Diagnostic import of data file
            [FileName,app.dirImages] = uigetfile({'*.mat'},'Select vertex detection *.mat file',app.dirImages);
            if ischar(FileName)
                app.vd = load(fullfile(app.dirImages,FileName));
            end
        end

        % Button pushed function: next_import
        function imgProcessButton_fromImportToClean(app, event)
            switch app.processedBefore.Value
                case 'No'
                    % Make xas grid have 5 dimensions (in case it hasn't already been done.
                    app.vd.xasGrid(:,:,5) = 0;
                    % Save a variable xasOld
                    app.vd.xasOld = app.vd.xasGrid;
                    % Extract the locations of the detected vertex clusters in minLoc
                    [app.vd.minLocJ,app.vd.minLocI] = find(app.vd.xasGrid(:,:,3) ~= 0);
                    app.vd.minLoc = [app.vd.minLocJ,app.vd.minLocI,zeros(length(app.vd.minLocI),2)];
                    for i = 1:length(app.vd.minLoc)
                        % Store intensity
                        app.vd.minLoc(i,3) = app.vd.xasGrid(app.vd.minLoc(i,1),app.vd.minLoc(i,2),3);
                        % Store which reference image was utilized for detection
                        app.vd.minLoc(i,4) = app.vd.xasGrid(app.vd.minLoc(i,1),app.vd.minLoc(i,2),2);
                    end
                    % Save an old version of minLoc
                    app.vd.minLocOld = app.vd.minLoc;
                    % Initialize matrices to capture ROI images that will be acquired later in the analysis process.
                    app.vd.magnetMask = zeros(app.vd.gridHeight,app.vd.gridWidth);
                    app.vd.magnetInterpretReg = app.vd.magnetMask;
                    app.vd.magnetInterpretCombined = app.vd.magnetMask;
                    app.vd.magnetEntropy = app.vd.magnetMask;
                    % Additional values to initialize for later use in the analysis
                    app.vd.thetaRange = linspace(0,2*pi,100);   
                    % Move to the clean-up step
                    app.TabGroup_ImgProcess.SelectedTab = app.Tab_CleanUp;
                    app.arrow_import.Visible = 0;
                    app.steps_import.Enable = 0;
                    app.arrow_cleanUp.Visible = 1;
                    app.steps_cleanUp.Enable = 1;
                case 'Yes'
                    % Go to the select step option tab
                    app.TabGroup_ImgProcess.SelectedTab = app.Tab_Reprocess;
            end
                
        end

        % Button pushed function: next_cleanUp
        function imgProcessButton_fromCleanToNbr(app, event)
            % Once the user is happy with the vertex layout, then several variables will be initialized for use in the analysis
            % TrueMinLoc is now Vertex
            % First, reset vertex
            if isfield(app.vd,'vertex') == 1
                app.vd = rmfield(app.vd,'vertex');
            end
            % Initialize trueMinLoc as a structure and import minLoc values
            app.vd.vertex(1).type = [];                             % (5) Vertex type
            app.vd.vertex(1).charge = [];                           %     Vertex charge state
            app.vd.vertex(1).nbrVertexInd = [];                     % (6-9) Index of neighboring vertices
            app.vd.vertex(1).nbrMagnetInd = [];                     % (10-13) Index of neighboring magnets in magnet
            app.vd.vertex(1).rowYPos = [];     
            app.vd.vertex(1).colXPos = []; 
            % First, make sure that EACH X-Y PAIR IN MINLOC IS UNIQUE!!!
            j = 1;
            for i = 1:length(app.vd.minLoc)
                if app.vd.minLoc(i,4) ~= 0
                    if ~isempty(app.vd.vertex(1).rowYPos)
                        j = j + 1;
                    end
                    app.vd.vertex(j).rowYPos = app.vd.minLoc(i,1);      % Row position / y-position of colXPos
                    app.vd.vertex(j).colXPos = app.vd.minLoc(i,2);      % Column position / x-position of vertex
                    app.vd.vertex(j).topInt = app.vd.minLoc(i,3);       % (3) Topographical intensity
                    app.vd.vertex(j).refImg = app.vd.minLoc(i,4);       % (4) Index of the reference image that detected this vertex
                end
            end
            app.vd.vertexOld = app.vd.vertex;
            % Initialize iceMagnets as a structure
            % IceMagnets is now magnet
            initializeMagnet(app);
            app.vd.oldMagnet = app.vd.magnet;
            % Within structure vd, create substructure app.vd.postProcessFlag.* to indicate what items to generate for postprocessing
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_NeighborScan;
            app.arrow_nbrScan.Visible = 1;
            app.steps_nbrScan.Enable = 1;
            app.arrow_cleanUp.Visible = 0;
            app.steps_cleanUp.Enable = 0;
            
            % Make sure the irrelevant options in the "Neighbor Scan" tab are disabled
            switch app.vd.typeASI
                case 'Kagome'
                    app.scanDiameter.Enable = 1;
                    app.scanCross1Angle.Enable = 0;
                    app.scanCross2Angle.Enable = 0;
                    app.scanCross1Height.Enable = 0;
                    app.scanCross2Height.Enable = 0;
                    app.scanCross1Width.Enable = 0;
                    app.scanCross2Width.Enable = 0;
                case {'Square','Brickwork','Tetris'}
                    app.scanDiameter.Enable = 0;
                    app.scanCross1Angle.Enable = 1;
                    app.scanCross2Angle.Enable = 1;
                    app.scanCross1Height.Enable = 1;
                    app.scanCross2Height.Enable = 1;
                    app.scanCross1Width.Enable = 1;
                    app.scanCross2Width.Enable = 1;
            end
        end

        % Button pushed function: next_nbrScan
        function imgProcessButton_fromNbrToLattice(app, event)
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_Finalinspection;
            app.arrow_finalInspect.Visible = 1;
            app.steps_finalInspect.Enable = 1;
            app.arrow_nbrScan.Visible = 0;
            app.steps_nbrScan.Enable = 0;
        end

        % Button pushed function: next_latticeIdx
        function imgProcessButton_fromLatticeToFinal(app, event)
            % For this reference magnet (0,0), determine the start and end vertices to calculate the reference vector for magnet00
            % First, set magnet00 to be equal to app.vd.magnet00
            magnet00 = app.vd.magnet00;
            
            % MAKE SURE YOU SELECT A TYPE 3 MAGNET FIRST "\"
            if app.vd.vertex(app.vd.magnet(magnet00).nbrVertexInd(1)).colXPos > app.vd.magnet(magnet00).colXPos % If the vertex lies to the right of magnet00
                vertexMagEnd = app.vd.magnet(magnet00).nbrVertexInd(1);
                vertexMagStart = app.vd.magnet(magnet00).nbrVertexInd(2); 
            else
                vertexMagEnd = app.vd.magnet(magnet00).nbrVertexInd(2);
                vertexMagStart = app.vd.magnet(magnet00).nbrVertexInd(1);
            end
            % Look for the magnets neighboring magnet00's "end" vertex
            magNeigh = app.vd.vertex(vertexMagEnd).nbrMagnetInd;
            % Remove magnets that are null or includes the reference magnet
            magNeigh(magNeigh == 0 | magNeigh == magnet00) = [];
            
            % Maps the ASI magnets to a perfect lattice with corresponding lattice coordinates
            switch app.vd.typeASI
                case 'Kagome'
                    kagomeMapper(app,magNeigh,vertexMagStart,vertexMagEnd,magnet00);
                case {'Square','Tetris'}
                    squareMapper(app,magNeigh,vertexMagStart,vertexMagEnd,magnet00);
                case 'Brickwork'
                    squareMapper(app,magNeigh,vertexMagStart,vertexMagEnd,magnet00);
                    % Special case for brickwork: If the bricktype is "/", rotate the lattice by 90 degrees and shift by -1,1
                    %for i = 1:length(app.vd.magnet)
                    %    aInd_Rot90 = app.vd.magnet(i).bInd;
                    %    bInd_Rot90 = -app.vd.magnet(i).aInd;
                    %    app.vd.magnet(i).aInd = aInd_Rot90-1;
                    %    app.vd.magnet(i).bInd = bInd_Rot90+1;
                    %end
            end
            
            % Rather than showing the full plot in the UI, create a high-resolution image from an invisible figure
            plotDialog = uiprogressdlg(app.IceScannerUI,'Title','Plotting lattice points','Message',...
                'Plotting the mapped lattice points with corresponding lattice coordinates.','Indeterminate','on');
            
            f = figure('visible','off','Position',[0,0,app.vd.gridWidth*2,app.vd.gridHeight*2]);
            ax1 = axes(f,'Position',[0,0,1,1],'Visible','off');
            hold(ax1,'on');
            imshow(app.vd.xasGrid(:,:,1),'Parent',ax1);
            axis(ax1,'image');
            for magIndex = 1:length(app.vd.magnet)
                x = app.vd.magnet(magIndex).colXPos;
                y = app.vd.magnet(magIndex).rowYPos;
                a = app.vd.magnet(magIndex).aInd;
                b = app.vd.magnet(magIndex).bInd;
                plot(ax1,x,y,'r.','MarkerSize',20);
                text(ax1,x,y,sprintf('(%i,%i)',a,b),'FontSize',7,'Color','green');
            end
            hold(ax1,'off');
            f_img = frame2im(getframe(f));
            app.vd.latticeMap = f_img;
            close(f);
            close(plotDialog);
            
            % Plot the lattice points in the main window
            app.extWindow.axes.XLim = [0 app.vd.gridHeight*10];
            app.extWindow.axes.YLim = [0 app.vd.gridWidth*10];
            general_imageDisplay(app,app.extWindow.axes,f_img);
            axis(app.extWindow.axes,'image');
            
            % Show a uiconfirm if the program detects duplicate lattice coordinates
            latticePts = [horzcat(app.vd.magnet.aInd);horzcat(app.vd.magnet.bInd)];
            [uniquePairs,~,pairIdx] = unique(latticePts','rows');
            numOccurences = accumarray(pairIdx,1);
            repeatPairIdx = find(numOccurences ~= 1);
            
            % Save a copy of the current app.vd.magnet state in case a magnetization is erroneously deleted
            saveDialog = uiprogressdlg(app.IceScannerUI,'Title','Backing up data','Message',...
                'Backing up magnetization data. Please wait..','Indeterminate','on');
            app.vd.oldMagnet = app.vd.magnet;
            close(saveDialog);
            
            determineSpinVectorComp(app);
            
            if length(repeatPairIdx) > 1
                duplicateDialog = uiconfirm(app.IceScannerUI,...
                    sprintf('%d duplicate lattice points found.',length(repeatPairIdx)),...
                    'Warning','Options',{'Go back','Proceed'},'Icon','warning');
                
                close(duplicateDialog);
                switch duplicateDialog.SelectedOption
                    case 'Go back'
                        return;
                end
            end
            
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_Postprocessing;
            app.arrow_postProcess.Visible = 1;
            app.steps_postProcess.Enable = 1;
            app.arrow_latticeIdx.Visible = 0;
            app.steps_latticeIdx.Enable = 0;
        end

        % Button pushed function: next_finalInspect
        function imgProcessButton_fromFinalToPostprocess(app, event)
            %%%%%%%%%% Move to the next tab and determine spin vector components for magnetic structure factor and correlation length %%%%%%%%%%
            
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_LatticeIndexing;
            app.arrow_finalInspect.Visible = 0;
            app.steps_finalInspect.Enable = 0;
            app.arrow_latticeIdx.Visible = 1;
            app.steps_latticeIdx.Enable = 1;
            % Disable mapping button in the lattice indexing tab
            app.next_latticeIdx.Enable = 0;
        end

        % Button pushed function: next_postProcess
        function imgProcessButton_fromPostprocessToEnd(app, event)
            % Generates all requested postprocessing material
            % First, ask user where to save the files to
            oldDirImages = app.dirImages;
            [FileName,app.dirImages] = uiputfile(strcat(app.dirImages,'*.mat'),'PostProcessing data file');
            if ~ischar(FileName)
                app.dirImages = oldDirImages;
                return;
            end
            
            try
                % If MSF option was selected, make MSF plots
                if app.GenerateMSFCheckBox.Value == 1
                    generateMSF(app,app.dirImages,'on','on');
                end
                
                % If pair-wise correlation option was selected, calculate correlations
                if app.correlation_SpinDot.Value == 1 || app.correlation_DotBinary.Value == 1 ||...
                        app.correlation_Magnetostatic.Value == 1 || app.correlation_localMap.Value == 1
                    % First, determine the n-th neighbors for each magnet
                    neighborIdxLocator(app);
                    if app.correlation_localMap.Value == 1
                        % Calculate local spin-spin correlations
                        correlationCalcLocal(app,app.dirImages);
                    end
                    % For each neighbor type, compile a list of index pairs
                    % to eliminate redundant index pairs
                    removeRedundantNbrIdx(app);
                    
                    if app.correlation_SpinDot.Value == 1 || app.correlation_DotBinary.Value == 1 ||...
                        app.correlation_Magnetostatic.Value == 1
                        % Calculate global spin-spin correlations
                        correlationCalcGlobal(app,app.dirImages);
                    end
                end
                
                % If the vertex count option was selected, plot the vertex statistics
                if app.VertexcountsCheckBox.Value == 1
                    exportVertexStatistics(app,app.dirImages);
                end
                
                % If the mapped lattice point option is selected, save app.vd.latticeMap
                if app.LatticecoordinatesCheckBox.Value == 1
                    currentStatus = uiprogressdlg(app.IceScannerUI,'Title','Generating image','Message',...
                        'Generating topography image with superimposed lattice coordinates.','Indeterminate','on');
                    imwrite(app.vd.latticeMap,sprintf('%sLattice coordinates.tif',app.dirImages),'tif',"Resolution",[10000,10000]);
                end
                
                % If the contrast + vertex type check box is selected, save that image
                if app.ContrastvertextypeCheckBox.Value == 1
                    currentStatus = uiprogressdlg(app.IceScannerUI,'Title','Generating image','Message',...
                        'Generating contrast image with vertex types superimposed.','Indeterminate','on');
                    [imgHeight,imgWidth,~] = size(mat2gray(app.vd.xmcd));
                    f = figure('visible','off','Name','Magnetic Structure Factor', 'Position', [100,100,imgWidth,imgHeight]);
                    ax1 = axes(f,'Visible','off','Position',[0,0,1,1],'units','normalized');
                    axis image;
                    general_imageDisplay(app,ax1,mat2gray(app.vd.xmcd));
                    overlayVertexType(app,ax1);
                    print(f,sprintf('%sContrast and vertex types.tif',app.dirImages),'-dtiffn','-r600');
                    close(f);
                    close(currentStatus);
                end
                
                % If the contrast + vertex type check box is selected, save that image
                if app.ContrastdetectedMCheckBox.Value == 1
                    currentStatus = uiprogressdlg(app.IceScannerUI,'Title','Generating image','Message',...
                        'Generating contrast image with vertex types and detected magnetizations superimposed.','Indeterminate','on');
                    [imgHeight,imgWidth,~] = size(mat2gray(app.vd.xmcd));
                    f = figure('visible','off','Name','Magnetic Structure Factor', 'Position', [100,100,imgWidth,imgHeight]);
                    ax1 = axes(f,'Visible','off','Position',[0,0,1,1],'units','normalized');
                    axis image;
                    general_imageDisplay(app,ax1,mat2gray(app.vd.xmcd));
                    overlayVertexType(app,ax1);
                    overlayMagnetization(app,ax1);
                    print(f,sprintf('%sContrast and magnetizations.tif',app.dirImages),'-dtiffn','-r600');
                    close(f);
                    close(currentStatus);
                end
    
                currentStatus = uiprogressdlg(app.IceScannerUI,'Title','Saving data','Message',...
                    'Saving analysis data to MAT-file.','Indeterminate','on');
                vd = app.vd;
                if ischar(FileName)
                    save(fullfile(app.dirImages,FileName),'-struct','vd');
                end
                close(currentStatus)
                app.TabGroup_ImgProcess.SelectedTab = app.Tab_Finish;
                app.arrow_finish.Visible = 1;
                app.steps_finish.Enable = 1;
                app.arrow_postProcess.Visible = 0;
                app.steps_postProcess.Enable = 0;
                uialert(app.IceScannerUI,'Requested files have been saved.','Save completed','Icon','success')
            catch ME
                errorNotice(app,ME)
                return;
            end
        end

        % Button pushed function: back_cleanUp
        function imgProcessButton_fromCleanToImport(app, event)
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_Import;
            app.arrow_import.Visible = 1;
            app.steps_import.Enable = 1;
            app.arrow_cleanUp.Visible = 0;
            app.steps_cleanUp.Enable = 0;
        end

        % Button pushed function: back_nbrScan
        function imgProcessButton_fromNbrToClean(app, event)
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_Finalinspection;
            app.arrow_nbrScan.Visible = 0;
            app.steps_nbrScan.Enable = 0;
            app.arrow_finalInspect.Visible = 1;
            app.steps_finalInspect.Enable = 1;
            
            % Reset vertex and magnet
            app.vd = rmfield(app.vd,'vertex');
            app.vd = rmfield(app.vd,'magnet');
        end

        % Button pushed function: back_latticeIdx
        function imgProcessButton_fromLatticeToNbr(app, event)
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_Finalinspection;
            app.arrow_finalInspect.Visible = 1;
            app.steps_finalInspect.Enable = 1;
            app.arrow_latticeIdx.Visible = 0;
            app.steps_latticeIdx.Enable = 0;
        end

        % Button pushed function: back_finalInspect
        function imgProcessButton_fromFinalToLattice(app, event)
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_NeighborScan;
            app.arrow_finalInspect.Visible = 0;
            app.steps_finalInspect.Enable = 0;
            app.arrow_nbrScan.Visible = 1;
            app.steps_nbrScan.Enable = 1;
        end

        % Button pushed function: back_postProcess
        function imgProcessButton_fromPostprocessToFinal(app, event)
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_LatticeIndexing;
            app.arrow_latticeIdx.Visible = 1;
            app.steps_latticeIdx.Enable = 1;
            app.arrow_postProcess.Visible = 0;
            app.steps_postProcess.Enable = 0;
        end

        % Button pushed function: startAgain_finish
        function imgProcessButton_StartOver(app, event)
            % Variable reset
            app.Field_magImagePath.Value = '';
            app.Field_detectionPath.Value = '';
            app.EditFieldVertexReduction.Value = 0;
            %app.oldVersion.Value = '-';
            app.EditFieldVertexIndex.Value = 0;
            app.contrastMode.Value = 'XMCD-PEEM';
            app.scanCross1Width.Value = 0;
            app.scanCross2Width.Value = 0;
            app.scanCross1Height.Value = 0;
            app.scanCross2Height.Value = 0;
            app.scanCross1Angle.Value = 0;
            app.scanCross2Angle.Value = 0;
            app.scanDiameter.Value = 0;
            app.drop_inspectionImage.Value = '-';
            app.MSFSteps.Value = 1200;
            app.MSFStart.Value = -6;
            app.MSFEnd.Value = 6;
            app.processedBefore.Value = 'No';
            
            % Delete structures app.vd.vertex and app.vd.magnet
            clear app.vd.vertex;
            clear app.vd.magnet;
            
            % Reset UI
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_Import;
            %app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
            app.arrow_finish.Visible = 0;
            app.steps_finish.Enable = 0;
            app.arrow_import.Visible = 1;
            app.steps_import.Enable = 1;
            app.ButtonRemovePts.Enable = 0;
            app.ButtonAddPts.Enable = 0;
            app.next_cleanUp.Enable = 0;
            app.ButtonPreviewScan.Enable = 0;
            app.ButtonNeighborDetect.Enable = 0;
            app.next_nbrScan.Enable = 0;
            app.next_latticeIdx.Enable = 0;
            app.browse_importMagContrast.Enable = 1;
            app.Field_magImagePath.Enable = 1;
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app)
        end

        % Close request function: IceScannerUI
        function IceScannerUICloseRequest(app, event)
            delete(app)
        end

        % Value changed function: processedBefore
        function processedBeforeValueChanged(app, event)
            % Namely this just disables the import magnetic contrast image option since
            % we're assuming that the contrast image has already been saved in the imported
            % mat file
            switch app.processedBefore.Value
                case 'Yes'
                    % Turn off contrast image import
                    app.browse_importMagContrast.Enable = 0;
                    app.Field_magImagePath.Enable = 0;
                case 'No'
                    % Turn on contrast image import
                    app.browse_importMagContrast.Enable = 1;
                    app.Field_magImagePath.Enable = 1;
            end
        end

        % Button pushed function: Reprocess_Back
        function Reprocess_BackButtonPushed(app, event)
            % Goes back to beginning
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_Import;
        end

        % Callback function
        function Reprocess_CleanupButtonPushed(app, event)
            % For reprocessing, moves the user to the vertex clean up step
            % This assumes that the user has already assigned vertices beforehand
            % Reset vertex and magnet
            app.vd = rmfield(app.vd,'vertex');
            app.vd = rmfield(app.vd,'magnet');
            % Move to the clean-up step
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_CleanUp;
            app.arrow_import.Visible = 0;
            app.steps_import.Enable = 0;
            app.arrow_cleanUp.Visible = 1;
            app.steps_cleanUp.Enable = 1;
            app.next_cleanUp.Enable = 1;
            app.ButtonAddPts.Enable = 1;
            app.ButtonRemovePts.Enable = 1;
        end

        % Callback function
        function Reprocess_NeighborButtonPushed(app, event)
            % For reprocessing, moves the user to the neighbor detection step
            app.vd = rmfield(app.vd,'vertex');
            app.vd.vertex(1).type = [];                             % (5) Vertex type
            app.vd.vertex(1).charge = [];                           %     Vertex charge state
            app.vd.vertex(1).nbrVertexInd = [];                     % (6-9) Index of neighboring vertices
            app.vd.vertex(1).nbrMagnetInd = [];                     % (10-13) Index of neighboring magnets in magnet
            app.vd.vertex(1).rowYPos = [];     
            app.vd.vertex(1).colXPos = []; 
            j = 1;
            for i = 1:length(app.vd.minLoc)
                if app.vd.minLoc(i,4) ~= 0
                    if ~isempty(app.vd.vertex(1).rowYPos)
                        j = j + 1;
                    end
                    app.vd.vertex(j).rowYPos = app.vd.minLoc(i,1);      % Row position / y-position of colXPos
                    app.vd.vertex(j).colXPos = app.vd.minLoc(i,2);      % Column position / x-position of vertex
                    app.vd.vertex(j).topInt = app.vd.minLoc(i,3);       % (3) Topographical intensity
                    app.vd.vertex(j).refImg = app.vd.minLoc(i,4);       % (4) Index of the reference image that detected this vertex
                end
            end
            app.vd.vertexOld = app.vd.vertex;
            % Initialize iceMagnets as a structure
            % IceMagnets is now magnet
            initializeMagnet(app);
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_NeighborScan;
            app.arrow_nbrScan.Visible = 1;
            app.steps_nbrScan.Enable = 1;
            app.arrow_import.Visible = 0;
            app.steps_import.Enable = 0;
            % Make sure the irrelevant options in the "Neighbor Scan" tab are disabled
            switch app.vd.typeASI
                case 'Kagome'
                    app.scanDiameter.Enable = 1;
                    app.scanCross1Angle.Enable = 0;
                    app.scanCross2Angle.Enable = 0;
                    app.scanCross1Height.Enable = 0;
                    app.scanCross2Height.Enable = 0;
                    app.scanCross1Width.Enable = 0;
                    app.scanCross2Width.Enable = 0;
                case {'Square','Brickwork','Tetris'}
                    app.scanDiameter.Enable = 0;
                    app.scanCross1Angle.Enable = 1;
                    app.scanCross2Angle.Enable = 1;
                    app.scanCross1Height.Enable = 1;
                    app.scanCross2Height.Enable = 1;
                    app.scanCross1Width.Enable = 1;
                    app.scanCross2Width.Enable = 1;
            end
        end

        % Callback function
        function Reprocess_IndexingButtonPushed(app, event)
            % For reprocessing, moves the user to the lattice indexing step
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_LatticeIndexing;
            app.arrow_import.Visible = 0;
            app.steps_import.Enable = 0;
            app.arrow_latticeIdx.Visible = 1;
            app.steps_latticeIdx.Enable = 1;
            % Disable mapping button in the lattice indexing tab
            app.next_latticeIdx.Enable = 0;
        end

        % Button pushed function: Reprocess_Inspect
        function Reprocess_InspectButtonPushed(app, event)
            % For reprocessing, moves the user to the inspection step
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_Finalinspection;
            app.arrow_finalInspect.Visible = 1;
            app.steps_finalInspect.Enable = 1;
            app.arrow_import.Visible = 0;
            app.steps_import.Enable = 0;
        end

        % Button pushed function: Reprocess_Postprocess
        function Reprocess_PostprocessButtonPushed(app, event)
            % For reprocessing, moves the user to the post-processing step
            app.TabGroup_ImgProcess.SelectedTab = app.Tab_Postprocessing;
            app.arrow_import.Visible = 0;
            app.steps_import.Enable = 0;
            app.arrow_postProcess.Visible = 1;
            app.steps_postProcess.Enable = 1;
        end

        % Value changed function: EMD_flatfieldSigma
        function EMD_flatfieldSigmaValueChanged(app, event)
            % If the topography image has been imported, change the flatfield sigma value
            app.iceGrid = vertexDetect_imgPreprocess(app,imflatfield(app.xasGrid_raw,app.EMD_flatfieldSigma.Value));
            app.xasGrid = abs(mat2gray(imflatfield(app.xasGrid_raw,app.EMD_flatfieldSigma.Value)));
            % Display image in figure window
            general_imageDisplay(app,app.extWindow.axes,app.xasGrid(:,:,1));
            % Modifying topography images to prevent crashing in EMD calculation associated with zeros
            app.iceGrid(app.iceGrid == 0) = 0.01;
            app.xasGrid(app.xasGrid == 0) = 0.01;
            app.xasGrid(:,:,5) = zeros(size(app.xasGrid));
            delete(app.emdROI);
            app.emdROI = drawrectangle(app.extWindow.axes,'AspectRatio',1,'Deletable',false,'FaceAlpha',0.1,...
                'InteractionsAllowed','translate','MarkerSize',5,...
                'Position',[round(app.gridHeight/2),round(app.gridWidth/2),...
                app.EMD_edgeLengthROI.Value,app.EMD_edgeLengthROI.Value]);
        end

        % Value changed function: EMD_edgeLengthROI
        function EMD_edgeLengthROIValueChanged(app, event)
            % Make sure the ROI edge size is an odd number. Complain if the edge size is even
            if mod(app.EMD_edgeLengthROI.Value,2) == 0
                uialert(app.IceScannerUI,'ROI pixel edge length must be an odd number','Error');
            else
                delete(app.emdROI);
                app.emdROI = drawrectangle(app.extWindow.axes,'AspectRatio',1,'Deletable',false,'FaceAlpha',0.1,...
                    'InteractionsAllowed','translate','MarkerSize',5,...
                    'Position',[round(app.gridHeight/2),round(app.gridWidth/2),...
                    app.EMD_edgeLengthROI.Value,app.EMD_edgeLengthROI.Value]);
            end
        end

        % Value changed function: magContrast_adjust
        function magContrast_adjustValueChanged(app, event)
            % Alters the magnetic contrast image depending on whether or not the user wants to
            switch app.magContrast_adjust.Value
                case 1
                    % Enable editing functions
                    app.magContrast_sigma.Enable = 1;
                    app.magContrast_cutoff.Enable = 1;
                    alterMagneticContrast(app);
                case 0
                    % Disable editing functions and reset contrast image
                    app.vd.xmcd = app.vd.xmcdOriginal;
                    app.magContrast_sigma.Enable = 1;
                    app.magContrast_cutoff.Enable = 1;
                    general_imageDisplay(app,app.extWindow.axes,mat2gray(app.vd.xmcd));
            end
        end

        % Value changed function: magContrast_sigma
        function magContrast_sigmaValueChanged(app, event)
            % Changes the magnetic contrast image based on the provided flatfield sigma
            alterMagneticContrast(app);
        end

        % Value changed function: magContrast_cutoff
        function magContrast_cutoffValueChanged(app, event)
            % Changes the magnetic contrast image based on the provided threshold
            alterMagneticContrast(app);
        end

        % Value changed function: scanCross1Angle
        function scanCross1AngleValueChanged(app, event)
            % Set the value of the cross2angle to be -90
            app.scanCross2Angle.Value = app.scanCross1Angle.Value - 90;
        end

        % Button pushed function: Button_CST
        function Button_CSTPushed(app, event)
            %%%%%%%%%% Flag specific magnets in an image as CSTs %%%%%%%%%%
            alterMagnet(app,convertCharsToStrings(app.drop_CST.Value));
        end

        % Value changed function: BrickmodeDropDown
        function BrickmodeDropDownValueChanged(app, event)
            app.brickMode.Value = app.BrickmodeDropDown.Value;
        end

        % Button pushed function: Button_previewMagnet
        function Button_previewMagnetPushed(app, event)
            try
                % Plot the preview of the nanoisland ROI
                % Show the analysis window
                %app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
                % Show the topography image
                general_imageDisplay(app,app.extWindow.axes,app.vd.xasGrid(:,:,1));
                delete(app.magnetROI);
                [imageWidth,imageHeight] = size(app.vd.xasGrid(:,:,1));
                app.magnetROI = drawrectangle(app.extWindow.axes,'Deletable',false,'FaceAlpha',0.1,...
                    'InteractionsAllowed','translate','Rotatable',true,'MarkerSize',5,'Position',[round(imageHeight/2),...
                    round(imageWidth/2),app.magnetWidth.Value,app.magnetHeight.Value]);
            catch ME
                errorNotice(app,ME);
            end
        end

        % Value changed function: contrastMode
        function contrastModeValueChanged(app, event)
            switch app.contrastMode.Value
                case 'MFM'
                    app.tipPole.Enable = true;
                otherwise
                    app.tipPole.Enable = false;
            end
        end

        % Value changed function: drop_inspectionImage
        function drop_inspectionImageValueChanged(app, event)
            % Generate the image figure in the main UI
            switch app.drop_inspectionImage.Value
                case '-'
                    return;
                case 'Topography'
                    backgroundImage = app.vd.xasGrid(:,:,1);
                case 'Magnetic contrast'
                    backgroundImage = mat2gray(app.vd.xmcd);
                case 'Boosted magnetic contrast'
                    backgroundImage = mat2gray(mat2gray(app.vd.xmcd),[0.25,0.75]);
                case 'Magnetic contrast magnitude'
                    backgroundImage = mat2gray(abs(app.vd.xmcdBinary));
                case 'Trinary contrast'
                    backgroundImage = mat2gray(app.vd.xmcdBinary);
                case 'ROI - Regular'
                    backgroundImage = mat2gray(app.vd.xmcdOriginalSkeleton);
                case 'ROI - Averaged'
                    backgroundImage = mat2gray(app.vd.magnetInterpretCombinedImg);
                case 'ROI - Trinary'
                    backgroundImage = mat2gray(app.vd.xmcdBinarySkeleton);
            end
            
            general_imageDisplay(app,app.extWindow.axes,backgroundImage);
            overlayVertexType(app,app.extWindow.axes);
            % If the "show magnetization vector" option is true, then show the magnetization
            if app.showMagVector.Value
                overlayMagnetization(app,app.extWindow.axes);    
            end
        end

        % Value changed function: topographyMode
        function topographyModeValueChanged(app, event)
            switch app.topographyMode.Value
                case 'XA-PEEM'
                    app.EMD_flatfieldSigma.Enable = true;
                case 'AFM'
                    app.EMD_flatfieldSigma.Enable = false;
            end
        end

        % Button pushed function: 
        % ShowstandarddeviationhistogramButton
        function ShowstandarddeviationhistogramButtonPushed(app, event)
            % Shows a histogram containing the standard deviation of trinarized intensity throughout
            % the nanomagnet ROI
            figure();
            hold on;
            histogram(vertcat(app.vd.magnet.xmcdSTD));
            title('STD of trinarized pixel intensity within magnet ROI');
            xlabel('Standard deviation');
            ylabel('Counts');
            hold off;
        end

        % Button pushed function: ShowdetectednonIsingstatesButton
        function ShowdetectednonIsingstatesButtonPushed(app, event)
            % Displays detected non-Ising states based on a user-defined threshold app.nonIsingThreshold.Value
            alterMagnet(app,'displayNonIsing');
        end

        % Button pushed function: ShowdetectedIsingstatesButton
        function ShowdetectedIsingstatesButtonPushed(app, event)
            % Displays detected Ising states based on a user-defined threshold app.nonIsingThreshold.Value
            alterMagnet(app,'displayIsing');
        end

        % Button pushed function: 
        % ZeroizedetectednonIsingstatesButton
        function ZeroizedetectednonIsingstatesButtonPushed(app, event)
            % Zeroizes detected non-Ising states based on a user-defined threshold app.nonIsingThreshold.Value
            alterMagnet(app,'autoZeroize');
        end

        % Selection changed function: isingDetectMethod
        function isingDetectMethodSelectionChanged(app, event)
            selectedButton = app.isingDetectMethod.SelectedObject;
            switch selectedButton.Text
                case 'STDEV'
                    app.nonIsingThreshold.Enable = true;
                case 'Unique intensities'
                    app.nonIsingThreshold.Enable = false;
            end
        end

        % Menu selected function: ReopenexternalwindowMenu
        function ReopenexternalwindowMenuSelected(app, event)
            % Reopens the external window figure 
            close all;
            app.extWindow.figure = figure("Name","IceScanner - External Window",...
                "NumberTitle","off");
            app.extWindow.axes = axes(app.extWindow.figure,...
                'Position',[0,0,1,1]);
            movegui(app.extWindow.figure,'center');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IceScannerUI = uifigure('Visible', 'off');
            app.IceScannerUI.Name = 'IceScanner - Artificial Spin Ice Analysis';
            app.IceScannerUI.CloseRequestFcn = createCallbackFcn(app, @IceScannerUICloseRequest, true);
            app.Menu = uimenu(app.IceScannerUI);
            app.Menu.Text = 'Menu';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.OptionsMenu = uimenu(app.IceScannerUI);
            app.OptionsMenu.Text = 'Options';
            app.ReopenexternalwindowMenu = uimenu(app.OptionsMenu);
            app.ReopenexternalwindowMenu.MenuSelectedFcn = createCallbackFcn(app, @ReopenexternalwindowMenuSelected, true);
            app.ReopenexternalwindowMenu.Text = 'Re-open external window';
            app.MenuImgProcSaveState = uimenu(app.OptionsMenu);
            app.MenuImgProcSaveState.MenuSelectedFcn = createCallbackFcn(app, @menuAdvanced_earlyExportResults, true);
            app.MenuImgProcSaveState.Text = 'Save current state (*.mat)';
            app.MenuDiagnosticImport = uimenu(app.OptionsMenu);
            app.MenuDiagnosticImport.MenuSelectedFcn = createCallbackFcn(app, @menuAdvanced_earlyImportResults, true);
            app.MenuDiagnosticImport.Text = 'Diagnostic import';
            app.TabGroup_Main = uitabgroup(app.IceScannerUI);
            app.TabGroup_VertexDetect = uitab(app.TabGroup_Main);
            app.TabGroup_VertexDetect.Title = 'Vertex detection';
            app.TabGroupEMD = uitabgroup(app.TabGroup_VertexDetect);
            app.Tab_Reference = uitab(app.TabGroupEMD);
            app.Tab_Reference.Title = 'Image';
            app.GridLayout7_6 = uigridlayout(app.Tab_Reference);
            app.AxesRefFrame2 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame2.XTick = [];
            app.AxesRefFrame2.XTickLabel = '';
            app.AxesRefFrame2.YTick = [];
            app.AxesRefFrame2.YTickLabel = '';
            app.AxesRefFrame1 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame1.XTick = [];
            app.AxesRefFrame1.XTickLabel = '';
            app.AxesRefFrame1.YTick = [];
            app.AxesRefFrame1.YTickLabel = '';
            app.AxesRefFrame3 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame3.XTick = [];
            app.AxesRefFrame3.XTickLabel = '';
            app.AxesRefFrame3.YTick = [];
            app.AxesRefFrame3.YTickLabel = '';
            app.AxesRefFrame4 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame4.XTick = [];
            app.AxesRefFrame4.XTickLabel = '';
            app.AxesRefFrame4.YTick = [];
            app.AxesRefFrame4.YTickLabel = '';
            app.AxesRefFrame5 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame5.XTick = [];
            app.AxesRefFrame5.XTickLabel = '';
            app.AxesRefFrame5.YTick = [];
            app.AxesRefFrame5.YTickLabel = '';
            app.AxesRefFrame6 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame6.XTick = [];
            app.AxesRefFrame6.XTickLabel = '';
            app.AxesRefFrame6.YTick = [];
            app.AxesRefFrame6.YTickLabel = '';
            app.AxesRefFrame7 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame7.XTick = [];
            app.AxesRefFrame7.XTickLabel = '';
            app.AxesRefFrame7.YTick = [];
            app.AxesRefFrame7.YTickLabel = '';
            app.AxesRefFrame8 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame8.XTick = [];
            app.AxesRefFrame8.XTickLabel = '';
            app.AxesRefFrame8.YTick = [];
            app.AxesRefFrame8.YTickLabel = '';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_37 = uilabel(app.GridLayout7_6);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_37.Text = {'Define the type of ASI array being analyzed and load the topography image. Then, using the'; 'square ROI, select regions that best represent each unique vertex configuration in the ASI.'; 'For brickwork, the first reference must look like a backwards y.'};
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_38 = uilabel(app.GridLayout7_6);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_38.Text = 'ASI:';
            app.Scale1041Label = uilabel(app.GridLayout7_6);
            app.Scale1041Label.Text = 'Scale (1-0.41):';
            app.EMD_scaleFactor = uieditfield(app.GridLayout7_6, 'numeric');
            app.EMD_scaleFactor.Value = 0.41;
            app.SkipofpixelsLabel = uilabel(app.GridLayout7_6);
            app.SkipofpixelsLabel.Text = 'Skip # of pixels';
            app.EMD_skipInterval = uieditfield(app.GridLayout7_6, 'numeric');
            app.EMD_skipInterval.Value = 2;
            app.ButtonImportRefImg1 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg1.ButtonPushedFcn = createCallbackFcn(app, @vertexDetect_importRefImg1, true);
            app.ButtonImportRefImg1.Text = 'Select 1st ref.';
            app.TopographyimageButton = uibutton(app.GridLayout7_6, 'push');
            app.TopographyimageButton.ButtonPushedFcn = createCallbackFcn(app, @vertexDetect_importTopography, true);
            app.TopographyimageButton.Text = 'Topography image';
            app.ButtonImportRefImg2 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg2.ButtonPushedFcn = createCallbackFcn(app, @vertexDetect_importRefImg2, true);
            app.ButtonImportRefImg2.Enable = 'off';
            app.ButtonImportRefImg2.Text = 'Select 2nd ref.';
            app.next_image = uibutton(app.GridLayout7_6, 'push');
            app.next_image.ButtonPushedFcn = createCallbackFcn(app, @vertexDetectButton_fromReferenceToThreshold, true);
            app.next_image.Text = 'Next (start EMD) >';
            app.ButtonImportRefImg3 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg3.ButtonPushedFcn = createCallbackFcn(app, @vertexDetect_importRefImg3, true);
            app.ButtonImportRefImg3.Enable = 'off';
            app.ButtonImportRefImg3.Text = 'Select 3rd ref.';
            app.ButtonImportRefImg4 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg4.ButtonPushedFcn = createCallbackFcn(app, @vertexDetect_importRefImg4, true);
            app.ButtonImportRefImg4.Enable = 'off';
            app.ButtonImportRefImg4.Text = 'Select 4th ref.';
            app.ButtonImportRefImg5 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg5.ButtonPushedFcn = createCallbackFcn(app, @vertexDetect_importRefImg5, true);
            app.ButtonImportRefImg5.Enable = 'off';
            app.ButtonImportRefImg5.Text = 'Select 5th ref.';
            app.ButtonImportRefImg6 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg6.ButtonPushedFcn = createCallbackFcn(app, @vertexDetect_importRefImg6, true);
            app.ButtonImportRefImg6.Enable = 'off';
            app.ButtonImportRefImg6.Text = 'Select 6th ref.';
            app.ButtonImportRefImg7 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg7.ButtonPushedFcn = createCallbackFcn(app, @vertexDetect_importRefImg7, true);
            app.ButtonImportRefImg7.Enable = 'off';
            app.ButtonImportRefImg7.Text = 'Select 7th ref.';
            app.ButtonImportRefImg8 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg8.ButtonPushedFcn = createCallbackFcn(app, @vertexDetect_importRefImg8, true);
            app.ButtonImportRefImg8.Enable = 'off';
            app.ButtonImportRefImg8.Text = 'Select 8th ref.';
            app.DropDownASIType = uidropdown(app.GridLayout7_6);
            app.DropDownASIType.Items = {'-', 'Square', 'Brickwork', 'Kagome', 'Tetris'};
            app.DropDownASIType.ValueChangedFcn = createCallbackFcn(app, @vertexDetect_adjTypeASI, true);
            app.DropDownASIType.Value = '-';
            app.FlatfieldsigmaLabel = uilabel(app.GridLayout7_6);
            app.FlatfieldsigmaLabel.Text = 'Flatfield sigma';
            app.EMD_flatfieldSigma = uieditfield(app.GridLayout7_6, 'numeric');
            app.EMD_flatfieldSigma.ValueChangedFcn = createCallbackFcn(app, @EMD_flatfieldSigmaValueChanged, true);
            app.EMD_flatfieldSigma.Value = 100;
            app.ROIedgesizeLabel = uilabel(app.GridLayout7_6);
            app.ROIedgesizeLabel.Text = 'ROI edge size';
            app.EMD_edgeLengthROI = uieditfield(app.GridLayout7_6, 'numeric');
            app.EMD_edgeLengthROI.ValueChangedFcn = createCallbackFcn(app, @EMD_edgeLengthROIValueChanged, true);
            app.EMD_edgeLengthROI.Value = 35;
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_48 = uilabel(app.GridLayout7_6);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_48.Text = 'Image:';
            app.topographyMode = uidropdown(app.GridLayout7_6);
            app.topographyMode.Items = {'XA-PEEM', 'AFM'};
            app.topographyMode.ValueChangedFcn = createCallbackFcn(app, @topographyModeValueChanged, true);
            app.topographyMode.Value = 'XA-PEEM';
            app.Tab_Threshold = uitab(app.TabGroupEMD);
            app.Tab_Threshold.Title = 'Threshold';
            app.GridLayout7_7 = uigridlayout(app.Tab_Threshold);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_39 = uilabel(app.GridLayout7_7);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_39.Text = {'Adjust the detection threshold to remove as many extraneous and incorrect detection points'; 'as possible. Further processing of these "raw" points can be performed in the image'; 'processing wizard tab once the vertex detection wizard has exported the results. The image'; 'window will automatically update when you change the threshold value.'};
            app.streferencethresholdLabel = uilabel(app.GridLayout7_7);
            app.streferencethresholdLabel.Text = '1st reference threshold:';
            app.img1Threshold = uieditfield(app.GridLayout7_7, 'numeric');
            app.img1Threshold.ValueChangedFcn = createCallbackFcn(app, @vertexDetect_adjThresholdFactor1, true);
            app.img1Threshold.Value = 1;
            app.ndreferencethresholdLabel = uilabel(app.GridLayout7_7);
            app.ndreferencethresholdLabel.Text = '2nd reference threshold:';
            app.img2Threshold = uieditfield(app.GridLayout7_7, 'numeric');
            app.img2Threshold.ValueChangedFcn = createCallbackFcn(app, @vertexDetect_adjThresholdFactor2, true);
            app.img2Threshold.Enable = 'off';
            app.img2Threshold.Value = 1;
            app.SaveresultsButton = uibutton(app.GridLayout7_7, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @vertexDetectButton_fromThresholdToFinish, true);
            app.SaveresultsButton.Text = 'Save results';
            app.back_threshold = uibutton(app.GridLayout7_7, 'push');
            app.back_threshold.ButtonPushedFcn = createCallbackFcn(app, @vertexDetectButton_fromThresholdToReference, true);
            app.back_threshold.Text = '< Back';
            app.rdreferencethresholdLabel = uilabel(app.GridLayout7_7);
            app.rdreferencethresholdLabel.Text = '3rd reference threshold:';
            app.img3Threshold = uieditfield(app.GridLayout7_7, 'numeric');
            app.img3Threshold.ValueChangedFcn = createCallbackFcn(app, @vertexDetect_adjThresholdFactor3, true);
            app.img3Threshold.Enable = 'off';
            app.img3Threshold.Value = 1;
            app.threferencethresholdLabel = uilabel(app.GridLayout7_7);
            app.threferencethresholdLabel.Text = '4th reference threshold:';
            app.img4Threshold = uieditfield(app.GridLayout7_7, 'numeric');
            app.img4Threshold.ValueChangedFcn = createCallbackFcn(app, @vertexDetect_adjThresholdFactor4, true);
            app.img4Threshold.Enable = 'off';
            app.img4Threshold.Value = 1;
            app.threferencethresholdLabel_2 = uilabel(app.GridLayout7_7);
            app.threferencethresholdLabel_2.Text = '5th reference threshold:';
            app.img5Threshold = uieditfield(app.GridLayout7_7, 'numeric');
            app.img5Threshold.ValueChangedFcn = createCallbackFcn(app, @vertexDetect_adjThresholdFactor5, true);
            app.img5Threshold.Enable = 'off';
            app.img5Threshold.Value = 1;
            app.threferencethresholdLabel_3 = uilabel(app.GridLayout7_7);
            app.threferencethresholdLabel_3.Text = '6th reference threshold:';
            app.img6Threshold = uieditfield(app.GridLayout7_7, 'numeric');
            app.img6Threshold.ValueChangedFcn = createCallbackFcn(app, @vertexDetect_adjThresholdFactor6, true);
            app.img6Threshold.Enable = 'off';
            app.img6Threshold.Value = 1;
            app.threferencethresholdLabel_4 = uilabel(app.GridLayout7_7);
            app.threferencethresholdLabel_4.Text = '7th reference threshold:';
            app.img7Threshold = uieditfield(app.GridLayout7_7, 'numeric');
            app.img7Threshold.ValueChangedFcn = createCallbackFcn(app, @vertexDetect_adjThresholdFactor7, true);
            app.img7Threshold.Enable = 'off';
            app.img7Threshold.Value = 1;
            app.threferencethresholdLabel_5 = uilabel(app.GridLayout7_7);
            app.threferencethresholdLabel_5.Text = '8th reference threshold:';
            app.img8Threshold = uieditfield(app.GridLayout7_7, 'numeric');
            app.img8Threshold.ValueChangedFcn = createCallbackFcn(app, @vertexDetect_adjThresholdFactor8, true);
            app.img8Threshold.Enable = 'off';
            app.img8Threshold.Value = 1;
            app.Tab_FinishEMD = uitab(app.TabGroupEMD);
            app.Tab_FinishEMD.Title = 'Finish';
            app.GridLayout7_8 = uigridlayout(app.Tab_FinishEMD);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_40 = uilabel(app.GridLayout7_8);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_40.Text = {'The wizard has completed the vertex detection sequence. You may use the exported results'; 'to perform analysis in the image processing wizard. If you would like to analyze another'; 'image click "Scan new image".'};
            app.finish_scanNewImage = uibutton(app.GridLayout7_8, 'push');
            app.finish_scanNewImage.ButtonPushedFcn = createCallbackFcn(app, @vertexDetectButton_fromFinishToReference, true);
            app.finish_scanNewImage.Text = 'Scan new image';
            app.StepsPanel_VertexDetect = uipanel(app.TabGroup_VertexDetect);
            app.StepsPanel_VertexDetect.Title = 'Steps';
            app.VertexDetectPanel_Grid = uigridlayout(app.StepsPanel_VertexDetect);
            app.steps_importImg = uilabel(app.VertexDetectPanel_Grid);
            app.steps_importImg.Text = 'Import images';
            app.arrow_importImg = uilabel(app.VertexDetectPanel_Grid);
            app.arrow_importImg.Text = '>';
            app.steps_thresholding = uilabel(app.VertexDetectPanel_Grid);
            app.steps_thresholding.Enable = 'off';
            app.steps_thresholding.Text = 'Thresholding';
            app.arrow_thresholding = uilabel(app.VertexDetectPanel_Grid);
            app.arrow_thresholding.Visible = 'off';
            app.arrow_thresholding.Text = '>';
            app.steps_finishEMD = uilabel(app.VertexDetectPanel_Grid);
            app.steps_finishEMD.Enable = 'off';
            app.steps_finishEMD.Text = 'Finish';
            app.arrow_finishEMD = uilabel(app.VertexDetectPanel_Grid);
            app.arrow_finishEMD.Visible = 'off';
            app.arrow_finishEMD.Text = '>';
            app.TabGroup_ImageProcess = uitab(app.TabGroup_Main);
            app.TabGroup_ImageProcess.Title = 'Image processing';
            app.TabGroup_ImgProcess = uitabgroup(app.TabGroup_ImageProcess);
            app.Tab_Import = uitab(app.TabGroup_ImgProcess);
            app.Tab_Import.Title = 'Import';
            app.GridLayout = uigridlayout(app.Tab_Import);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel = uilabel(app.GridLayout);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel.Text = {'Select the *.mat file containing the vertex detection data as well as the magnetic contrast '; 'image used for magnetization detection.'};
            app.DetectionresultfileEditFieldLabel = uilabel(app.GridLayout);
            app.DetectionresultfileEditFieldLabel.Text = 'Detection result file:';
            app.Field_detectionPath = uieditfield(app.GridLayout, 'text');
            app.Field_detectionPath.Editable = 'off';
            app.browse_importEMDResults = uibutton(app.GridLayout, 'push');
            app.browse_importEMDResults.ButtonPushedFcn = createCallbackFcn(app, @imgProcess_importEMDResults, true);
            app.browse_importEMDResults.Text = 'Browse';
            app.MagneticimagefileEditFieldLabel = uilabel(app.GridLayout);
            app.MagneticimagefileEditFieldLabel.Text = 'Magnetic image file:';
            app.Field_magImagePath = uieditfield(app.GridLayout, 'text');
            app.Field_magImagePath.Editable = 'off';
            app.browse_importMagContrast = uibutton(app.GridLayout, 'push');
            app.browse_importMagContrast.ButtonPushedFcn = createCallbackFcn(app, @imgProcess_importMagContrast, true);
            app.browse_importMagContrast.Text = 'Browse';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_2 = uilabel(app.GridLayout);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_2.Text = 'Have the files been processed through this wizard before?';
            app.processedBefore = uidropdown(app.GridLayout);
            app.processedBefore.Items = {'No', 'Yes'};
            app.processedBefore.ValueChangedFcn = createCallbackFcn(app, @processedBeforeValueChanged, true);
            app.processedBefore.Value = 'No';
            app.next_import = uibutton(app.GridLayout, 'push');
            app.next_import.ButtonPushedFcn = createCallbackFcn(app, @imgProcessButton_fromImportToClean, true);
            app.next_import.Text = 'Next >';
            app.FlatfieldsigmaLabel_2 = uilabel(app.GridLayout);
            app.FlatfieldsigmaLabel_2.Enable = 'off';
            app.FlatfieldsigmaLabel_2.Text = 'Flatfield sigma:';
            app.magContrast_sigma = uieditfield(app.GridLayout, 'numeric');
            app.magContrast_sigma.ValueChangedFcn = createCallbackFcn(app, @magContrast_sigmaValueChanged, true);
            app.magContrast_sigma.Enable = 'off';
            app.magContrast_sigma.Value = 100;
            app.IntensitycutoffstdLabel = uilabel(app.GridLayout);
            app.IntensitycutoffstdLabel.Enable = 'off';
            app.IntensitycutoffstdLabel.Text = 'Intensity cutoff (std):';
            app.magContrast_cutoff = uieditfield(app.GridLayout, 'numeric');
            app.magContrast_cutoff.ValueChangedFcn = createCallbackFcn(app, @magContrast_cutoffValueChanged, true);
            app.magContrast_cutoff.Enable = 'off';
            app.magContrast_cutoff.Value = 5;
            app.magContrast_adjust = uicheckbox(app.GridLayout);
            app.magContrast_adjust.ValueChangedFcn = createCallbackFcn(app, @magContrast_adjustValueChanged, true);
            app.magContrast_adjust.Text = 'Adjust magnetic contrast';
            app.contrastMode = uidropdown(app.GridLayout);
            app.contrastMode.Items = {'XMCD-PEEM', 'MFM'};
            app.contrastMode.ValueChangedFcn = createCallbackFcn(app, @contrastModeValueChanged, true);
            app.contrastMode.Value = 'XMCD-PEEM';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_46 = uilabel(app.GridLayout);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_46.Text = 'Imaging technique:';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_47 = uilabel(app.GridLayout);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_47.Text = 'Tip pole:';
            app.tipPole = uidropdown(app.GridLayout);
            app.tipPole.Items = {'N', 'S'};
            app.tipPole.Enable = 'off';
            app.tipPole.Value = 'N';
            app.Tab_CleanUp = uitab(app.TabGroup_ImgProcess);
            app.Tab_CleanUp.Title = 'Clean-up';
            app.GridLayout2 = uigridlayout(app.Tab_CleanUp);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_3 = uilabel(app.GridLayout2);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_3.Text = {'Specify the minimum distance between two adjacent vertices; this distance will be used'; 'by the wizard to perform reduction of the EMD-detected points and provide a best '; 'estimate of the true vertex center locations. A good starting value is 15. Once the '; 'wizard has completed the initial guesswork, you may manually add/remove vertices.'};
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_4 = uilabel(app.GridLayout2);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_4.Text = 'Minimum distance between two adjacent vertices (in pixels):';
            app.EditFieldVertexReduction = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldVertexReduction.ValueChangedFcn = createCallbackFcn(app, @imgProcess_reduceEMDPoints, true);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_5 = uilabel(app.GridLayout2);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_5.Text = 'Select points to remove';
            app.ButtonRemovePts = uibutton(app.GridLayout2, 'push');
            app.ButtonRemovePts.ButtonPushedFcn = createCallbackFcn(app, @imgProcess_removeVertices, true);
            app.ButtonRemovePts.Enable = 'off';
            app.ButtonRemovePts.Text = 'Select';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_6 = uilabel(app.GridLayout2);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_6.Text = 'Select points to add';
            app.ButtonAddPts = uibutton(app.GridLayout2, 'push');
            app.ButtonAddPts.ButtonPushedFcn = createCallbackFcn(app, @imgProcess_addVertices, true);
            app.ButtonAddPts.Enable = 'off';
            app.ButtonAddPts.Text = 'Select';
            app.back_cleanUp = uibutton(app.GridLayout2, 'push');
            app.back_cleanUp.ButtonPushedFcn = createCallbackFcn(app, @imgProcessButton_fromCleanToImport, true);
            app.back_cleanUp.Text = '< Back';
            app.next_cleanUp = uibutton(app.GridLayout2, 'push');
            app.next_cleanUp.ButtonPushedFcn = createCallbackFcn(app, @imgProcessButton_fromCleanToNbr, true);
            app.next_cleanUp.Enable = 'off';
            app.next_cleanUp.Text = 'Next (clean-up) >';
            app.Tab_NeighborScan = uitab(app.TabGroup_ImgProcess);
            app.Tab_NeighborScan.Title = 'Nbr Scan';
            app.GridLayout3 = uigridlayout(app.Tab_NeighborScan);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_7 = uilabel(app.GridLayout3);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_7.Text = {'To map the vertex positions to an ideal lattice, the wizard will first attempt to define, for each '; 'vertex, the relative positions of all nearest-neighboring vertices. This is done with the use of '; 'an area scan whose geometric properties can be adjusted. Preview an individual vertex by '; 'providing an arbitrary index value (i.e., 90). Then adjust the area scan parameter and '; 'preview scan area until the ROI contains the nearest neighboring vertices only.'};
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_8 = uilabel(app.GridLayout3);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_8.Text = 'Legacy?:';
            app.oldVersion = uidropdown(app.GridLayout3);
            app.oldVersion.Items = {'No', 'Yes'};
            app.oldVersion.Value = 'No';
            app.InputascanningdistanceLabel_11 = uilabel(app.GridLayout3);
            app.InputascanningdistanceLabel_11.Text = 'Width';
            app.InputascanningdistanceLabel_12 = uilabel(app.GridLayout3);
            app.InputascanningdistanceLabel_12.Text = 'Height';
            app.InputascanningdistanceLabel_13 = uilabel(app.GridLayout3);
            app.InputascanningdistanceLabel_13.Text = 'Angle';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_9 = uilabel(app.GridLayout3);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_9.Text = 'Index #:';
            app.EditFieldVertexIndex = uieditfield(app.GridLayout3, 'numeric');
            app.EditFieldVertexIndex.ValueChangedFcn = createCallbackFcn(app, @imgProcess_displayScanPoint, true);
            app.InputascanningdistanceLabel_10 = uilabel(app.GridLayout3);
            app.InputascanningdistanceLabel_10.Text = 'Cross 1';
            app.InputascanningdistanceLabel_14 = uilabel(app.GridLayout3);
            app.InputascanningdistanceLabel_14.Text = 'Cross 2';
            app.InputascanningdistanceLabel_15 = uilabel(app.GridLayout3);
            app.InputascanningdistanceLabel_15.Text = 'Circle';
            app.scanCross1Width = uieditfield(app.GridLayout3, 'numeric');
            app.scanCross1Width.ValueChangedFcn = createCallbackFcn(app, @imgProcess_scanCross1WidthValueChanged, true);
            app.scanCross1Height = uieditfield(app.GridLayout3, 'numeric');
            app.scanCross1Height.ValueChangedFcn = createCallbackFcn(app, @imgProcess_scanCross1HeightValueChanged, true);
            app.scanCross1Angle = uieditfield(app.GridLayout3, 'numeric');
            app.scanCross1Angle.ValueChangedFcn = createCallbackFcn(app, @scanCross1AngleValueChanged, true);
            app.scanCross2Width = uieditfield(app.GridLayout3, 'numeric');
            app.scanCross2Width.ValueChangedFcn = createCallbackFcn(app, @imgProcess_scanCross2WidthValueChanged, true);
            app.scanCross2Height = uieditfield(app.GridLayout3, 'numeric');
            app.scanCross2Height.ValueChangedFcn = createCallbackFcn(app, @imgProcess_scanCross2HeightValueChanged, true);
            app.scanCross2Angle = uieditfield(app.GridLayout3, 'numeric');
            app.scanCross2Angle.ValueChangedFcn = createCallbackFcn(app, @imgProcess_scanCross2AngleValueChanged, true);
            app.scanDiameter = uieditfield(app.GridLayout3, 'numeric');
            app.scanDiameter.ValueChangedFcn = createCallbackFcn(app, @imgProcess_scanDiameterValueChanged, true);
            app.ButtonPreviewScan = uibutton(app.GridLayout3, 'push');
            app.ButtonPreviewScan.ButtonPushedFcn = createCallbackFcn(app, @imgProcess_displayScanArea, true);
            app.ButtonPreviewScan.Enable = 'off';
            app.ButtonPreviewScan.Text = 'Preview scan area';
            app.back_nbrScan = uibutton(app.GridLayout3, 'push');
            app.back_nbrScan.ButtonPushedFcn = createCallbackFcn(app, @imgProcessButton_fromNbrToClean, true);
            app.back_nbrScan.Text = '< Back';
            app.next_nbrScan = uibutton(app.GridLayout3, 'push');
            app.next_nbrScan.ButtonPushedFcn = createCallbackFcn(app, @imgProcessButton_fromNbrToLattice, true);
            app.next_nbrScan.Enable = 'off';
            app.next_nbrScan.Text = 'Next >';
            app.ButtonNeighborDetect = uibutton(app.GridLayout3, 'push');
            app.ButtonNeighborDetect.ButtonPushedFcn = createCallbackFcn(app, @imgProcess_executeNeighborDetection, true);
            app.ButtonNeighborDetect.Enable = 'off';
            app.ButtonNeighborDetect.Text = 'Neighbor detection';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_44 = uilabel(app.GridLayout3);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_44.Text = 'BrickMode';
            app.brickMode = uidropdown(app.GridLayout3);
            app.brickMode.Items = {'\', '/'};
            app.brickMode.Value = '\';
            app.InputascanningdistanceLabel_28 = uilabel(app.GridLayout3);
            app.InputascanningdistanceLabel_28.Text = 'Magnet';
            app.magnetWidth = uieditfield(app.GridLayout3, 'numeric');
            app.magnetWidth.Value = 8;
            app.magnetHeight = uieditfield(app.GridLayout3, 'numeric');
            app.magnetHeight.Value = 22;
            app.Button_previewMagnet = uibutton(app.GridLayout3, 'push');
            app.Button_previewMagnet.ButtonPushedFcn = createCallbackFcn(app, @Button_previewMagnetPushed, true);
            app.Button_previewMagnet.Text = 'Preview magnet';
            app.Tab_Finalinspection = uitab(app.TabGroup_ImgProcess);
            app.Tab_Finalinspection.Title = 'Final inspection';
            app.GridLayout5 = uigridlayout(app.Tab_Finalinspection);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_12 = uilabel(app.GridLayout5);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_12.Text = 'Inspect the nanoislands and verify that the correct magnetization has been assigned. ';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_13 = uilabel(app.GridLayout5);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_13.Text = 'Image:';
            app.drop_inspectionImage = uidropdown(app.GridLayout5);
            app.drop_inspectionImage.Items = {'-', 'Topography', 'Magnetic contrast', 'Boosted magnetic contrast', 'Magnetic contrast magnitude', 'Trinary contrast', 'ROI - Regular', 'ROI - Averaged', 'ROI - Trinary'};
            app.drop_inspectionImage.ValueChangedFcn = createCallbackFcn(app, @drop_inspectionImageValueChanged, true);
            app.drop_inspectionImage.Value = '-';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_14 = uilabel(app.GridLayout5);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_14.Text = 'Zeroize moment:';
            app.Button_ZeroizeMagnet = uibutton(app.GridLayout5, 'push');
            app.Button_ZeroizeMagnet.ButtonPushedFcn = createCallbackFcn(app, @imgProcess_zeroizeMagnet, true);
            app.Button_ZeroizeMagnet.Text = 'Select';
            app.next_finalInspect = uibutton(app.GridLayout5, 'push');
            app.next_finalInspect.ButtonPushedFcn = createCallbackFcn(app, @imgProcessButton_fromFinalToPostprocess, true);
            app.next_finalInspect.Text = 'Next >';
            app.back_finalInspect = uibutton(app.GridLayout5, 'push');
            app.back_finalInspect.ButtonPushedFcn = createCallbackFcn(app, @imgProcessButton_fromFinalToLattice, true);
            app.back_finalInspect.Text = '< Back';
            app.undoAll_finalInspect = uibutton(app.GridLayout5, 'push');
            app.undoAll_finalInspect.ButtonPushedFcn = createCallbackFcn(app, @imgProcess_undoMagnetizationChange, true);
            app.undoAll_finalInspect.Text = 'Undo all';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_41 = uilabel(app.GridLayout5);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_41.Text = 'Remove magnet:';
            app.Button_RemoveMagnet = uibutton(app.GridLayout5, 'push');
            app.Button_RemoveMagnet.ButtonPushedFcn = createCallbackFcn(app, @imgProcess_removeMagnet, true);
            app.Button_RemoveMagnet.Text = 'Select';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_42 = uilabel(app.GridLayout5);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_42.Text = 'Flip spin';
            app.Button_FlipSpin = uibutton(app.GridLayout5, 'push');
            app.Button_FlipSpin.ButtonPushedFcn = createCallbackFcn(app, @imgProcess_flipMagnet, true);
            app.Button_FlipSpin.Text = 'Select';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_43 = uilabel(app.GridLayout5);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_43.Text = 'Mark as CST';
            app.Button_CST = uibutton(app.GridLayout5, 'push');
            app.Button_CST.ButtonPushedFcn = createCallbackFcn(app, @Button_CSTPushed, true);
            app.Button_CST.Text = 'Select';
            app.drop_CST = uidropdown(app.GridLayout5);
            app.drop_CST.Items = {'OWIB', 'OBIW', 'TWBB', 'TBBW'};
            app.drop_CST.Value = 'OWIB';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_49 = uilabel(app.GridLayout5);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_49.Text = 'Show moment vectors:';
            app.showMagVector = uicheckbox(app.GridLayout5);
            app.showMagVector.Text = '';
            app.showMagVector.Value = true;
            app.STDEVthresholdLabel = uilabel(app.GridLayout5);
            app.STDEVthresholdLabel.Text = 'STDEV threshold';
            app.nonIsingThreshold = uieditfield(app.GridLayout5, 'numeric');
            app.ShowdetectednonIsingstatesButton = uibutton(app.GridLayout5, 'push');
            app.ShowdetectednonIsingstatesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowdetectednonIsingstatesButtonPushed, true);
            app.ShowdetectednonIsingstatesButton.Text = 'Show detected non-Ising states';
            app.ShowdetectedIsingstatesButton = uibutton(app.GridLayout5, 'push');
            app.ShowdetectedIsingstatesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowdetectedIsingstatesButtonPushed, true);
            app.ShowdetectedIsingstatesButton.Text = 'Show detected Ising states';
            app.ZeroizedetectednonIsingstatesButton = uibutton(app.GridLayout5, 'push');
            app.ZeroizedetectednonIsingstatesButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroizedetectednonIsingstatesButtonPushed, true);
            app.ZeroizedetectednonIsingstatesButton.Text = 'Zeroize detected non-Ising states';
            app.ShowstandarddeviationhistogramButton = uibutton(app.GridLayout5, 'push');
            app.ShowstandarddeviationhistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ShowstandarddeviationhistogramButtonPushed, true);
            app.ShowstandarddeviationhistogramButton.Text = 'Show standard deviation histogram';
            app.isingDetectMethod = uibuttongroup(app.GridLayout5);
            app.isingDetectMethod.SelectionChangedFcn = createCallbackFcn(app, @isingDetectMethodSelectionChanged, true);
            app.isingDetectMethod.Title = 'Automated Ising-detection method';
            app.STDEVButton = uiradiobutton(app.isingDetectMethod);
            app.STDEVButton.Text = 'STDEV';
            app.STDEVButton.Value = true;
            app.UniqueintensitiesButton = uiradiobutton(app.isingDetectMethod);
            app.UniqueintensitiesButton.Text = 'Unique intensities';
            app.Tab_LatticeIndexing = uitab(app.TabGroup_ImgProcess);
            app.Tab_LatticeIndexing.Title = 'Lattice indexing';
            app.GridLayout4 = uigridlayout(app.Tab_LatticeIndexing);
            app.Image = uiimage(app.GridLayout4);
            app.Image.ImageSource = 'BrickRef.png';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_11 = uilabel(app.GridLayout4);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_11.Text = {'To assign 2D lattice coordinates to the ASI array, select the location of a reference magnet '; 'based on the criteria for the following systems:'; ''; 'Square and Kagome ASI: Select the center of any magnet (ideally with horizontal orientation)'; ''; 'Brickwork ASI: Select the center of the magnet that is circled in green with neighbor magnets'; '   arranged in the manner shown in the red region (image below).'};
            app.ForthesquareandKagomeLabel = uilabel(app.GridLayout4);
            app.ForthesquareandKagomeLabel.Text = 'Choose reference:';
            app.ButtonRefMagSelect = uibutton(app.GridLayout4, 'push');
            app.ButtonRefMagSelect.ButtonPushedFcn = createCallbackFcn(app, @imgProcess_selectRefMagnet, true);
            app.ButtonRefMagSelect.Text = 'Select';
            app.next_latticeIdx = uibutton(app.GridLayout4, 'push');
            app.next_latticeIdx.ButtonPushedFcn = createCallbackFcn(app, @imgProcessButton_fromLatticeToFinal, true);
            app.next_latticeIdx.Enable = 'off';
            app.next_latticeIdx.Text = 'Next >';
            app.back_latticeIdx = uibutton(app.GridLayout4, 'push');
            app.back_latticeIdx.ButtonPushedFcn = createCallbackFcn(app, @imgProcessButton_fromLatticeToNbr, true);
            app.back_latticeIdx.Text = '< Back';
            app.Tab_Postprocessing = uitab(app.TabGroup_ImgProcess);
            app.Tab_Postprocessing.Title = 'Postprocessing';
            app.GridLayout6 = uigridlayout(app.Tab_Postprocessing);
            app.VertexandmagnetizationdetectionPanel = uipanel(app.GridLayout6);
            app.VertexandmagnetizationdetectionPanel.Title = 'Vertex and magnetization detection';
            app.ContrastvertextypeCheckBox = uicheckbox(app.VertexandmagnetizationdetectionPanel);
            app.ContrastvertextypeCheckBox.Text = 'Contrast + vertex type';
            app.ContrastvertextypeCheckBox.Value = true;
            app.LatticecoordinatesCheckBox = uicheckbox(app.VertexandmagnetizationdetectionPanel);
            app.LatticecoordinatesCheckBox.Text = 'Lattice coordinates';
            app.LatticecoordinatesCheckBox.Value = true;
            app.ContrastdetectedMCheckBox = uicheckbox(app.VertexandmagnetizationdetectionPanel);
            app.ContrastdetectedMCheckBox.Text = 'Contrast + detected M';
            app.ContrastdetectedMCheckBox.Value = true;
            app.VertexcountsCheckBox = uicheckbox(app.VertexandmagnetizationdetectionPanel);
            app.VertexcountsCheckBox.Text = 'Vertex counts';
            app.VertexcountsCheckBox.Value = true;
            app.MagneticstructurefactorPanel = uipanel(app.GridLayout6);
            app.MagneticstructurefactorPanel.Title = 'Magnetic structure factor';
            app.InputascanningdistanceLabel_26 = uilabel(app.MagneticstructurefactorPanel);
            app.InputascanningdistanceLabel_26.Text = 'Start (q*pi)';
            app.MSFEnd = uieditfield(app.MagneticstructurefactorPanel, 'numeric');
            app.MSFEnd.Value = 6;
            app.InputascanningdistanceLabel_27 = uilabel(app.MagneticstructurefactorPanel);
            app.InputascanningdistanceLabel_27.Text = 'End (q*pi)';
            app.MSFStart = uieditfield(app.MagneticstructurefactorPanel, 'numeric');
            app.MSFStart.Value = -6;
            app.MSFSteps = uieditfield(app.MagneticstructurefactorPanel, 'numeric');
            app.MSFSteps.Value = 600;
            app.InputascanningdistanceLabel_25 = uilabel(app.MagneticstructurefactorPanel);
            app.InputascanningdistanceLabel_25.Text = 'Steps';
            app.GenerateMSFCheckBox = uicheckbox(app.MagneticstructurefactorPanel);
            app.GenerateMSFCheckBox.Text = 'Generate MSF';
            app.GenerateMSFCheckBox.Value = true;
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_15 = uilabel(app.GridLayout6);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_15.Text = {'Select which post-processing procedure you would like to perform with the processed ASI'; 'image. The files will be saved to the same directory that you selected the magnetic contrast'; 'image from.'};
            app.back_postProcess = uibutton(app.GridLayout6, 'push');
            app.back_postProcess.ButtonPushedFcn = createCallbackFcn(app, @imgProcessButton_fromPostprocessToFinal, true);
            app.back_postProcess.Text = '< Back';
            app.next_postProcess = uibutton(app.GridLayout6, 'push');
            app.next_postProcess.ButtonPushedFcn = createCallbackFcn(app, @imgProcessButton_fromPostprocessToEnd, true);
            app.next_postProcess.Text = 'Next >';
            app.SpinspincorrelatorPanel = uipanel(app.GridLayout6);
            app.SpinspincorrelatorPanel.Title = 'Spin-spin correlator';
            app.correlation_nbrMovie = uicheckbox(app.SpinspincorrelatorPanel);
            app.correlation_nbrMovie.Text = 'Identified neighbors (movie)';
            app.correlation_nbrMovie.Value = true;
            app.correlation_SpinDot = uicheckbox(app.SpinspincorrelatorPanel);
            app.correlation_SpinDot.Text = 'Dot product';
            app.correlation_SpinDot.Value = true;
            app.correlation_DotBinary = uicheckbox(app.SpinspincorrelatorPanel);
            app.correlation_DotBinary.Text = 'Binary dot product';
            app.correlation_DotBinary.Value = true;
            app.correlation_Magnetostatic = uicheckbox(app.SpinspincorrelatorPanel);
            app.correlation_Magnetostatic.Text = 'Binary magnetostatics';
            app.correlation_Magnetostatic.Value = true;
            app.correlation_localMap = uicheckbox(app.SpinspincorrelatorPanel);
            app.correlation_localMap.Text = 'Local correlation map';
            app.PairwiseTxt = uilabel(app.SpinspincorrelatorPanel);
            app.PairwiseTxt.Text = 'Correlation definition:';
            app.Tab_Finish = uitab(app.TabGroup_ImgProcess);
            app.Tab_Finish.Title = 'Finish';
            app.GridLayout7 = uigridlayout(app.Tab_Finish);
            app.Dialog1 = uilabel(app.GridLayout7);
            app.Dialog1.Text = {'Wizard has finished processing the ASI image. Double-check the generated figures to make'; 'sure the results make sense.'};
            app.startAgain_finish = uibutton(app.GridLayout7, 'push');
            app.startAgain_finish.ButtonPushedFcn = createCallbackFcn(app, @imgProcessButton_StartOver, true);
            app.startAgain_finish.Text = 'Start again >>';
            app.Tab_Reprocess = uitab(app.TabGroup_ImgProcess);
            app.Tab_Reprocess.Title = 'Tab';
            app.GridLayout7_9 = uigridlayout(app.Tab_Reprocess);
            app.Dialog1_2 = uilabel(app.GridLayout7_9);
            app.Dialog1_2.Text = 'Select which processing step you would like to perform with the pre-analyzed data.';
            app.Reprocess_Back = uibutton(app.GridLayout7_9, 'push');
            app.Reprocess_Back.ButtonPushedFcn = createCallbackFcn(app, @Reprocess_BackButtonPushed, true);
            app.Reprocess_Back.Text = '< Back';
            app.Reprocess_Inspect = uibutton(app.GridLayout7_9, 'push');
            app.Reprocess_Inspect.ButtonPushedFcn = createCallbackFcn(app, @Reprocess_InspectButtonPushed, true);
            app.Reprocess_Inspect.Text = 'Final inspection';
            app.Reprocess_Postprocess = uibutton(app.GridLayout7_9, 'push');
            app.Reprocess_Postprocess.ButtonPushedFcn = createCallbackFcn(app, @Reprocess_PostprocessButtonPushed, true);
            app.Reprocess_Postprocess.Text = 'Post-processing';
            app.BrickmodeDropDownLabel = uilabel(app.GridLayout7_9);
            app.BrickmodeDropDownLabel.Text = 'Brickmode:';
            app.BrickmodeDropDown = uidropdown(app.GridLayout7_9);
            app.BrickmodeDropDown.Items = {'-', '/', '\'};
            app.BrickmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @BrickmodeDropDownValueChanged, true);
            app.BrickmodeDropDown.Value = '-';
            app.StepsPanel_ImageProcess = uipanel(app.TabGroup_ImageProcess);
            app.StepsPanel_ImageProcess.Title = 'Steps';
            app.StepsPanel_Grid = uigridlayout(app.StepsPanel_ImageProcess);
            app.steps_import = uilabel(app.StepsPanel_Grid);
            app.steps_import.Text = 'Import';
            app.arrow_import = uilabel(app.StepsPanel_Grid);
            app.arrow_import.Text = '>';
            app.steps_cleanUp = uilabel(app.StepsPanel_Grid);
            app.steps_cleanUp.Enable = 'off';
            app.steps_cleanUp.Text = 'Vertex clean-up';
            app.arrow_cleanUp = uilabel(app.StepsPanel_Grid);
            app.arrow_cleanUp.Visible = 'off';
            app.arrow_cleanUp.Text = '>';
            app.steps_nbrScan = uilabel(app.StepsPanel_Grid);
            app.steps_nbrScan.Enable = 'off';
            app.steps_nbrScan.Text = 'Neighbor scan';
            app.arrow_nbrScan = uilabel(app.StepsPanel_Grid);
            app.arrow_nbrScan.Visible = 'off';
            app.arrow_nbrScan.Text = '>';
            app.steps_latticeIdx = uilabel(app.StepsPanel_Grid);
            app.steps_latticeIdx.Enable = 'off';
            app.steps_latticeIdx.Text = 'Lattice indexing';
            app.arrow_latticeIdx = uilabel(app.StepsPanel_Grid);
            app.arrow_latticeIdx.Visible = 'off';
            app.arrow_latticeIdx.Text = '>';
            app.steps_finalInspect = uilabel(app.StepsPanel_Grid);
            app.steps_finalInspect.Enable = 'off';
            app.steps_finalInspect.Text = 'Inspect magnets';
            app.arrow_finalInspect = uilabel(app.StepsPanel_Grid);
            app.arrow_finalInspect.Visible = 'off';
            app.arrow_finalInspect.Text = '>';
            app.steps_postProcess = uilabel(app.StepsPanel_Grid);
            app.steps_postProcess.Enable = 'off';
            app.steps_postProcess.Text = 'Post-processing';
            app.arrow_postProcess = uilabel(app.StepsPanel_Grid);
            app.arrow_postProcess.Visible = 'off';
            app.arrow_postProcess.Text = '>';
            app.steps_finish = uilabel(app.StepsPanel_Grid);
            app.steps_finish.Enable = 'off';
            app.steps_finish.Text = 'Finish';
            app.arrow_finish = uilabel(app.StepsPanel_Grid);
            app.arrow_finish.Visible = 'off';
            app.arrow_finish.Text = '>';
            app.ContextMenu = uicontextmenu(app.IceScannerUI);
            app.Menu_2 = uimenu(app.ContextMenu);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu);
            app.Menu2_2.Text = 'Menu2';
            % Show the figure after all components are created
            app.IceScannerUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IceScanner_Version_1_5

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.IceScannerUI)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.IceScannerUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IceScannerUI)
        end
    end
end