classdef IceScanner_Version_1_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IceScannerUI                    matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        AdvancedMenu                    matlab.ui.container.Menu
        MenuImgProcSaveState            matlab.ui.container.Menu
        MenuDiagnosticImport            matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        VertexdetectionTab              matlab.ui.container.Tab
        MsgVertexDetect                 matlab.ui.control.TextArea
        VertexDetectPanel               matlab.ui.container.Panel
        VertexDetectPanel_Grid          matlab.ui.container.GridLayout
        arrow_finishEMD                 matlab.ui.control.Label
        steps_finishEMD                 matlab.ui.control.Label
        arrow_thresholding              matlab.ui.control.Label
        steps_thresholding              matlab.ui.control.Label
        arrow_importImg                 matlab.ui.control.Label
        steps_importImg                 matlab.ui.control.Label
        TabGroupEMD                     matlab.ui.container.TabGroup
        ImageTab                        matlab.ui.container.Tab
        GridLayout7_6                   matlab.ui.container.GridLayout
        ButtonImportRefImg8             matlab.ui.control.Button
        ButtonImportRefImg7             matlab.ui.control.Button
        ButtonImportRefImg6             matlab.ui.control.Button
        ButtonImportRefImg5             matlab.ui.control.Button
        ButtonImportRefImg4             matlab.ui.control.Button
        ButtonImportRefImg3             matlab.ui.control.Button
        next_image                      matlab.ui.control.Button
        ButtonImportRefImg2             matlab.ui.control.Button
        TopographyimageButton           matlab.ui.control.Button
        ButtonImportRefImg1             matlab.ui.control.Button
        FieldSkipInterval               matlab.ui.control.NumericEditField
        SkipofpixelsLabel               matlab.ui.control.Label
        FieldScaleFactor                matlab.ui.control.NumericEditField
        Scale1041Label                  matlab.ui.control.Label
        DropDownASIType                 matlab.ui.control.DropDown
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_38  matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_37  matlab.ui.control.Label
        AxesRefFrame2                   matlab.ui.control.UIAxes
        AxesRefFrame1                   matlab.ui.control.UIAxes
        AxesRefFrame3                   matlab.ui.control.UIAxes
        AxesRefFrame4                   matlab.ui.control.UIAxes
        AxesRefFrame5                   matlab.ui.control.UIAxes
        AxesRefFrame6                   matlab.ui.control.UIAxes
        AxesRefFrame7                   matlab.ui.control.UIAxes
        AxesRefFrame8                   matlab.ui.control.UIAxes
        ThresholdTab                    matlab.ui.container.Tab
        GridLayout7_7                   matlab.ui.container.GridLayout
        EditFieldRefImg8Thresh          matlab.ui.control.NumericEditField
        threferencethresholdLabel_5     matlab.ui.control.Label
        EditFieldRefImg7Thresh          matlab.ui.control.NumericEditField
        threferencethresholdLabel_4     matlab.ui.control.Label
        EditFieldRefImg6Thresh          matlab.ui.control.NumericEditField
        threferencethresholdLabel_3     matlab.ui.control.Label
        EditFieldRefImg5Thresh          matlab.ui.control.NumericEditField
        threferencethresholdLabel_2     matlab.ui.control.Label
        EditFieldRefImg4Thresh          matlab.ui.control.NumericEditField
        threferencethresholdLabel       matlab.ui.control.Label
        EditFieldRefImg3Thresh          matlab.ui.control.NumericEditField
        rdreferencethresholdLabel       matlab.ui.control.Label
        back_threshold                  matlab.ui.control.Button
        SaveresultsButton               matlab.ui.control.Button
        EditFieldRefImg2Thresh          matlab.ui.control.NumericEditField
        ndreferencethresholdLabel       matlab.ui.control.Label
        EditFieldRefImg1Thresh          matlab.ui.control.NumericEditField
        streferencethresholdLabel       matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_39  matlab.ui.control.Label
        FinishTabEMD                    matlab.ui.container.Tab
        GridLayout7_8                   matlab.ui.container.GridLayout
        finish_scanNewImage             matlab.ui.control.Button
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_40  matlab.ui.control.Label
        AxesASITopography               matlab.ui.control.UIAxes
        ImageprocessingTab              matlab.ui.container.Tab
        StepsPanel                      matlab.ui.container.Panel
        StepsPanel_Grid                 matlab.ui.container.GridLayout
        arrow_finish                    matlab.ui.control.Label
        steps_finish                    matlab.ui.control.Label
        arrow_postProcess               matlab.ui.control.Label
        steps_postProcess               matlab.ui.control.Label
        arrow_finalInspect              matlab.ui.control.Label
        steps_finalInspect              matlab.ui.control.Label
        arrow_latticeIdx                matlab.ui.control.Label
        steps_latticeIdx                matlab.ui.control.Label
        arrow_nbrScan                   matlab.ui.control.Label
        steps_nbrScan                   matlab.ui.control.Label
        arrow_cleanUp                   matlab.ui.control.Label
        steps_cleanUp                   matlab.ui.control.Label
        arrow_import                    matlab.ui.control.Label
        steps_import                    matlab.ui.control.Label
        MsgImgProcess                   matlab.ui.control.TextArea
        TabGroupImgProcess              matlab.ui.container.TabGroup
        ImportTab                       matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        next_import                     matlab.ui.control.Button
        drop_firstTimeProcessQuery      matlab.ui.control.DropDown
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_2  matlab.ui.control.Label
        browse_importMagContrast        matlab.ui.control.Button
        Field_magImagePath              matlab.ui.control.EditField
        MagneticimagefileEditFieldLabel  matlab.ui.control.Label
        browse_importEMDResults         matlab.ui.control.Button
        Field_detectionPath             matlab.ui.control.EditField
        DetectionresultfileEditFieldLabel  matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel  matlab.ui.control.Label
        CleanupTab                      matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        next_cleanUp                    matlab.ui.control.Button
        back_cleanUp                    matlab.ui.control.Button
        ButtonAddPts                    matlab.ui.control.Button
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_6  matlab.ui.control.Label
        ButtonRemovePts                 matlab.ui.control.Button
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_5  matlab.ui.control.Label
        EditFieldVertexReduction        matlab.ui.control.NumericEditField
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_4  matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_3  matlab.ui.control.Label
        NbrScanTab                      matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        ButtonNeighborDetect            matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_10  matlab.ui.control.Label
        next_nbrScan                    matlab.ui.control.Button
        back_nbrScan                    matlab.ui.control.Button
        ButtonPreviewScan               matlab.ui.control.Button
        Field_scanDiameter              matlab.ui.control.NumericEditField
        Field_scanCross2Angle           matlab.ui.control.NumericEditField
        Field_scanCross2Height          matlab.ui.control.NumericEditField
        Field_scanCross2Width           matlab.ui.control.NumericEditField
        Field_scanCross1Angle           matlab.ui.control.NumericEditField
        Field_scanCross1Height          matlab.ui.control.NumericEditField
        Field_scanCross1Width           matlab.ui.control.NumericEditField
        InputascanningdistanceLabel_15  matlab.ui.control.Label
        InputascanningdistanceLabel_14  matlab.ui.control.Label
        InputascanningdistanceLabel_10  matlab.ui.control.Label
        EditFieldVertexIndex            matlab.ui.control.NumericEditField
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_9  matlab.ui.control.Label
        InputascanningdistanceLabel_13  matlab.ui.control.Label
        InputascanningdistanceLabel_12  matlab.ui.control.Label
        InputascanningdistanceLabel_11  matlab.ui.control.Label
        DropDownOldVersionDetect        matlab.ui.control.DropDown
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_8  matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_7  matlab.ui.control.Label
        LatticeindexingTab              matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        back_latticeIdx                 matlab.ui.control.Button
        next_latticeIdx                 matlab.ui.control.Button
        ButtonRefMagSelect              matlab.ui.control.Button
        ForthesquareandKagomeLabel      matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_11  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        FinalinspectionTab              matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        undoAll_finalInspect            matlab.ui.control.Button
        back_finalInspect               matlab.ui.control.Button
        next_finalInspect               matlab.ui.control.Button
        ButtonRefMagSelect_2            matlab.ui.control.Button
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_14  matlab.ui.control.Label
        drop_inspectionImage            matlab.ui.control.DropDown
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_13  matlab.ui.control.Label
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_12  matlab.ui.control.Label
        PostprocessingTab               matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        PairwisecorrelationCheckBox     matlab.ui.control.CheckBox
        MappedlatticepointsCheckBox     matlab.ui.control.CheckBox
        next_postProcess                matlab.ui.control.Button
        back_postProcess                matlab.ui.control.Button
        ContrastdetectedMCheckBox       matlab.ui.control.CheckBox
        ContrastvertextypeCheckBox      matlab.ui.control.CheckBox
        VertexcountsCheckBox            matlab.ui.control.CheckBox
        MSFEnd                          matlab.ui.control.NumericEditField
        MSFStart                        matlab.ui.control.NumericEditField
        MSFSteps                        matlab.ui.control.NumericEditField
        InputascanningdistanceLabel_27  matlab.ui.control.Label
        InputascanningdistanceLabel_26  matlab.ui.control.Label
        InputascanningdistanceLabel_25  matlab.ui.control.Label
        MagneticstructurefactorCheckBox  matlab.ui.control.CheckBox
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_15  matlab.ui.control.Label
        FinishTab                       matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        startAgain_finish               matlab.ui.control.Button
        BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_16  matlab.ui.control.Label
        Tab                             matlab.ui.container.Tab
        TabGroupAnalysis                matlab.ui.container.TabGroup
        AnalysisTab                     matlab.ui.container.Tab
        AxesAnalysis                    matlab.ui.control.UIAxes
        ImportedmagneticcontrastTab     matlab.ui.container.Tab
        AxesImportMagneticContrast      matlab.ui.control.UIAxes
        TopographyTab                   matlab.ui.container.Tab
        AxesTopography                  matlab.ui.control.UIAxes
        InterpretedMTab                 matlab.ui.container.Tab
        AxesInterpret                   matlab.ui.control.UIAxes
        BinaryMTab                      matlab.ui.container.Tab
        AxesBinary                      matlab.ui.control.UIAxes
        LatticeIndexTab                 matlab.ui.container.Tab
        AxesLatticeIndex                matlab.ui.control.UIAxes
        MagneticstructurefactorTab      matlab.ui.container.Tab
        AxesMSF                         matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu_2                          matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
    end

    % Variable dungeon
    properties (Access = public)
        % General

        % For vertex detection
        dirMexOpenCV = 'C:\';   % (char)    Directory for MexOpenCV
        dirImages = 'C:\';      % (char)    Directory for image files
        emdGlobalRef1           % (matrix)  Stores all the values of the EMD intensity associated with reference 1 and the ASI topography
        emdGlobalRef2           % (matrix)  Stores all the values of the EMD intensity associated with reference 2 and the ASI topography
        emdGlobalRef3
        emdGlobalRef4
        emdGlobalRef5
        emdGlobalRef6
        emdGlobalRef7
        emdGlobalRef8
        gridHeight              % (int)     Height of xasGrid
        gridWidth               % (int)     Width of xasGrid
        iceGrid                 % (image)   Duplicate of xasGrid image to be used in EMD calculation
        iRangeSize              % (int)     i-scanning range for EMD calculation
        isSquareIce             % (logical) Logical to describe whether the analyzed array is a square ASI or not.
        jRangeSize              % (int)     j-scanning range for EMD calculation
        refFrame1               % (image)   First reference image of a vertex. Used for square, brick, and kagome
        refFrame2               % (image)   Second reference image of a vertex. Used only for brick and kagome
        refFrame3
        refFrame4
        refFrame5
        refFrame6
        refFrame7
        refFrame8
        scalingFactor = 0.41;   % (double)  Scaling factor of the images used for EMD detection (0.41 default)
        skipInterval = 2;       % (int)     Described the interval at which pixels are skipped in the grid scan
        thresholdFactor1 = 1;   % (double)  Cutoff EMD intensity threshold for reference image 1 (1 default)
        thresholdFactor2 = 1;   % (double)  Cutoff EMD intensity threshold for reference image 2 (1 default)
        thresholdFactor3 = 1;
        thresholdFactor4 = 1;
        thresholdFactor5 = 1;
        thresholdFactor6 = 1;
        thresholdFactor7 = 1;
        thresholdFactor8 = 1;
        typeASI                 % (str)     Type of ASI: 1 for square, 2 for brick, 3 for Kagome
        xasGrid                 % (matrix)  Topography image of the ASI structure. XAS since this was built around PEEM images.
                                %           [row,column,1 > grayscale intensity, 2 > detection source
                                %           3 > crude detection intensity, 4 > refined detect int]
                                        
        % For processing vertex detection data (both pre- and post-processing)
        dialogSel               % (char?)   Dialogue selection for determining next steps for vertex processing
        dialogTitle             % (char?)   Title for dialog box
        dialogMsg               % (char?)   Message for dialog box
        dialogButtons           % (cell)    Different button options for dialog box
        vd = struct('name','')  % (struct)  Pre- or post-processed vertex detection data (vertex detection output)
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            currentStatus = uiprogressdlg(app.IceScannerUI,'Message','Please wait.','Indeterminate',"on");
            pause(5);
            close(currentStatus);
        end

        % Callback function
        function imageProcess_importMAT(app, event)
            % Get the file name and directory for the MAT file containing either the pre-
            % or post-processed vertex EMD data
            [FileName,PathName] = uigetfile('*.mat','Select the pre- or post-processed vertex output file');
            if FileName == 0
                return
            end
            app.vertexEMDOutput = load(fullfile(PathName,FileName));
        end

        % Button pushed function: ButtonImportRefImg1
        function vertexDetect_importRefImg1(app, event)
            % Get the file name and directory for the image file containing the first reference image
            % of a vertex (for square, brickwork, and kagome)
            oldDirImages = app.dirImages;
            [FileName,app.dirImages] = uigetfile(strcat(app.dirImages,'*.tif'),'Select the first vertex reference image');
            if FileName == 0
                app.dirImages = oldDirImages;
                return;
            end
            app.refFrame1 = imread(fullfile(app.dirImages,FileName));
            
            % Initial processing of the reference frame
            app.refFrame1 = vertexDetect_imgPreprocess(app,app.refFrame1);
            
            % Modifying reference image to prevent crashing in EMD calculation associated with zeros
            app.refFrame1(app.refFrame1 == 0) = 0.01;
            
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame1,app.refFrame1)
            
            % Update status window
            statusUpdate_vertexDetect(app,'Imported first vertex reference image');
        end

        % Button pushed function: ButtonImportRefImg2
        function vertexDetect_importRefImg2(app, event)
            % Get the file name and directory for the image file containing the second reference image
            % of a vertex (for square, brickwork, and kagome)
            oldDirImages = app.dirImages;
            [FileName,app.dirImages] = uigetfile(strcat(app.dirImages,'*.tif'),'Select the first vertex reference image');
            if FileName == 0
                app.dirImages = oldDirImages;
                return;
            end
            app.refFrame2 = imread(fullfile(app.dirImages,FileName));
            
            % Initial processing of the reference frame
            app.refFrame2 = vertexDetect_imgPreprocess(app,app.refFrame2);
            
            % Modifying reference image to prevent crashing in EMD calculation associated with zeros
            app.refFrame2(app.refFrame2 == 0) = 0.01;
            
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame2,app.refFrame2)
            
            % Update status window
            statusUpdate_vertexDetect(app,'Imported second vertex reference image');
        end

        % Value changed function: FieldScaleFactor
        function vertexDetect_adjScaleFactor(app, event)
            % Scaling factor of the images used for EMD detection
            app.scalingFactor = app.FieldScaleFactor.Value;
            
            % Update status window
            statusUpdate_vertexDetect(app,'Scaling factor updated');
        end

        % Button pushed function: TopographyimageButton
        function vertexDetect_importTopography(app, event)
            % Get the file name and directory for the image file containing the ASI topography
            oldDirImages = app.dirImages;
            [FileName,app.dirImages] = uigetfile(strcat(app.dirImages,'*.tif'),'Select the ASI topography image');
            if FileName == 0
                app.dirImages = oldDirImages;
                return
            end
            app.xasGrid = imread(fullfile(app.dirImages,FileName));
            
            % Initial processing of the topography image
            app.iceGrid = vertexDetect_imgPreprocess(app,app.xasGrid);
            app.xasGrid = abs(mat2gray(app.xasGrid));
            
            % Display image in figure window
            %imshow(app.xasGrid,'Parent',app.AxesASITopography,'InitialMagnification','fit')
            general_imageDisplay(app,app.AxesASITopography,app.xasGrid)
            
            % Modifying topography images to prevent crashing in EMD calculation associated with zeros
            app.iceGrid(app.iceGrid == 0) = 0.01;
            app.xasGrid(app.xasGrid == 0) = 0.01;
            
            % For XAS Grid, we need to introduce additional dimensions for subsequent processing via EMD
            app.xasGrid(:,:,5) = zeros(size(app.xasGrid));
            
            % Update the height and width of xasGrid
            [app.gridHeight,app.gridWidth,~] = size(app.xasGrid);
            
            % Update status window
            statusUpdate_vertexDetect(app,'Imported ASI topography image');
        end

        % Value changed function: DropDownASIType
        function vertexDetect_adjTypeASI(app, event)
            % Determine the type of ASI analyzed for vertex detection
            app.typeASI = app.DropDownASIType.Value;
            
            % Updates the value of isSquareIce to denote that more than 1 unique vertex is required to fully describe system
            app.isSquareIce = strcmp(app.typeASI,'Square');
            
            % Update status window
            switch app.typeASI
                case 'Square'
                    app.ButtonImportRefImg2.Enable = 'off';
                    app.ButtonImportRefImg3.Enable = 'off';
                    app.ButtonImportRefImg4.Enable = 'off';
                    app.ButtonImportRefImg5.Enable = 'off';
                    app.ButtonImportRefImg6.Enable = 'off';
                    app.ButtonImportRefImg7.Enable = 'off';
                    app.ButtonImportRefImg8.Enable = 'off';
                    app.EditFieldRefImg2Thresh.Enable = 'off';
                    app.EditFieldRefImg3Thresh.Enable = 'off';
                    app.EditFieldRefImg4Thresh.Enable = 'off';
                    app.EditFieldRefImg5Thresh.Enable = 'off';
                    app.EditFieldRefImg6Thresh.Enable = 'off';
                    app.EditFieldRefImg7Thresh.Enable = 'off';
                    app.EditFieldRefImg8Thresh.Enable = 'off';
                case {'Brickwork','Kagome'}
                    app.ButtonImportRefImg2.Enable = 'on';
                    app.ButtonImportRefImg3.Enable = 'off';
                    app.ButtonImportRefImg4.Enable = 'off';
                    app.ButtonImportRefImg5.Enable = 'off';
                    app.ButtonImportRefImg6.Enable = 'off';
                    app.ButtonImportRefImg7.Enable = 'off';
                    app.ButtonImportRefImg8.Enable = 'off';
                    app.EditFieldRefImg2Thresh.Enable = 'on';
                    app.EditFieldRefImg3Thresh.Enable = 'off';
                    app.EditFieldRefImg4Thresh.Enable = 'off';
                    app.EditFieldRefImg5Thresh.Enable = 'off';
                    app.EditFieldRefImg6Thresh.Enable = 'off';
                    app.EditFieldRefImg7Thresh.Enable = 'off';
                    app.EditFieldRefImg8Thresh.Enable = 'off';
                case 'Tetris'
                    app.ButtonImportRefImg2.Enable = 'on';
                    app.ButtonImportRefImg3.Enable = 'on';
                    app.ButtonImportRefImg4.Enable = 'on';
                    app.ButtonImportRefImg5.Enable = 'on';
                    app.ButtonImportRefImg6.Enable = 'on';
                    app.ButtonImportRefImg7.Enable = 'on';
                    app.ButtonImportRefImg8.Enable = 'on';
                    app.EditFieldRefImg2Thresh.Enable = 'on';
                    app.EditFieldRefImg3Thresh.Enable = 'on';
                    app.EditFieldRefImg4Thresh.Enable = 'on';
                    app.EditFieldRefImg5Thresh.Enable = 'on';
                    app.EditFieldRefImg6Thresh.Enable = 'on';
                    app.EditFieldRefImg7Thresh.Enable = 'on';
                    app.EditFieldRefImg8Thresh.Enable = 'on';
                otherwise
                    statusUpdate_vertexDetect(app,'Please change the ASI type.');
                    app.ButtonImportRefImg2.Enable = 'off';
                    app.ButtonImportRefImg3.Enable = 'off';
                    app.ButtonImportRefImg4.Enable = 'off';
                    app.ButtonImportRefImg5.Enable = 'off';
                    app.ButtonImportRefImg6.Enable = 'off';
                    app.ButtonImportRefImg7.Enable = 'off';
                    app.ButtonImportRefImg8.Enable = 'off';
                    app.EditFieldRefImg2Thresh.Enable = 'off';
                    app.EditFieldRefImg3Thresh.Enable = 'off';
                    app.EditFieldRefImg4Thresh.Enable = 'off';
                    app.EditFieldRefImg5Thresh.Enable = 'off';
                    app.EditFieldRefImg6Thresh.Enable = 'off';
                    app.EditFieldRefImg7Thresh.Enable = 'off';
                    app.EditFieldRefImg8Thresh.Enable = 'off';
            end
        end

        % Button pushed function: next_image
        function vertexDetect_executeEMD(app, event)
            % Set x and y limits for each analyzed image
            [ySizeGrid,xSizeGrid] = size(app.iceGrid);                          % Initial x and y limits for ASI topography image
            [ySizeRef1,xSizeRef1] = size(app.refFrame1);                        % Initial x and y limits for first vertex reference image
                
            % Set initial scanning length limits relative to a scanning center (your current index (i,j))
            yBranchLen = floor(ySizeRef1/2);                                    % Scanning length along y direction, relative to the scanning center
            xBranchLen = floor(xSizeRef1/2);                                    % Scanning length along x direction, relative to the scanning center
            
            % Convert reference images into format readable by cv.EMD (the "signatures")
            numFrameElements = numel(app.refFrame1);                                % Total number of pixels inside the first reference image
            refInfo1 = zeros(numFrameElements,3);                               % Information regarding the reference image intensity and location
                                                                                % refInfo(1,:) = [grayscale intensity, x coordinate, y coordinate]
            app.refFrame1 = app.refFrame1 / sum(app.refFrame1,'all');           % Normalize the reference frame for comparison in EMD
            refInfo1(:,1) = app.refFrame1(1:numFrameElements);                  % Store the relevant information in refInfo1
            [refInfo1(:,2),refInfo1(:,3)] = ...
                ind2sub([ySizeRef1,xSizeRef1],1:numFrameElements);
            
            % In the V7 version of the code, the X and Y were switched. Keep this in mind if you process data acquired from that version
            % or earlier...
            currentInfoX = refInfo1(:,2);                                       % Saves the x/Col positions of the reference frame (i.e., refInfo1 and 2)
            currentInfoY = refInfo1(:,3);                                       % Saves the y/Row positions of the reference frame (i.e., refInfo1 and 2)
            
            % Initialize the scanning range vector and extent to which points are skipped during the scan at regular intervals 
            jRange = yBranchLen + 1:app.skipInterval:ySizeGrid - yBranchLen;
            iRange = xBranchLen + 1:app.skipInterval:xSizeGrid - xBranchLen;
            
            % Initialize a matrix earthMoversDistance that stores the calculated cv.EMD values = [EMD intensity, x position, y position]
            privateJRangeSize = numel(jRange);
            privateIRangeSize = numel(iRange);
            app.jRangeSize = privateJRangeSize;
            app.iRangeSize = privateIRangeSize;
            privateEmdGlobalRef1 = NaN(app.jRangeSize,app.iRangeSize,3);           % With the parfor loop, a single instance of distance bank cannot be used to store
            privateEmdGlobalRef2 = NaN(app.jRangeSize,app.iRangeSize,3);           % the distances associated with multiple different reference frames.
            privateEmdGlobalRef3 = NaN(app.jRangeSize,app.iRangeSize,3);           % the distances associated with multiple different reference frames.
            privateEmdGlobalRef4 = NaN(app.jRangeSize,app.iRangeSize,3);           % the distances associated with multiple different reference frames.
            privateEmdGlobalRef5 = NaN(app.jRangeSize,app.iRangeSize,3);           % the distances associated with multiple different reference frames.
            privateEmdGlobalRef6 = NaN(app.jRangeSize,app.iRangeSize,3);           % the distances associated with multiple different reference frames.
            privateEmdGlobalRef7 = NaN(app.jRangeSize,app.iRangeSize,3);           % the distances associated with multiple different reference frames.
            privateEmdGlobalRef8 = NaN(app.jRangeSize,app.iRangeSize,3);           % the distances associated with multiple different reference frames.
            
            % Duplicate values from app to allow for parfor calculation
            privateIceGrid = app.iceGrid;
            privateScalingFactor = app.scalingFactor;
            
            % Message for initializing parallel core utilization
            app.MsgVertexDetect.Value = [sprintf('%s > Finished image preprocessing. Begin EMD calculation.',datestr(now,'HH:MM:SS'));...
                app.MsgVertexDetect.Value];
            
            app.MsgVertexDetect.Value = [sprintf('%s > Starting parallel pool.',datestr(now,'HH:MM:SS'));...
                app.MsgVertexDetect.Value];
            pause(1);
           
            % Initialize parallel pool
            parPoolStatus = uiprogressdlg(app.IceScannerUI,'Title','Starting parallel pool','Message',...
                sprintf('%s\n\n%s',...
                'Hang on a bit. MATLAB is initializing multiple CPU cores to accelerate the analysis.',...
                'If MATLAB requests access to your network, you can reject the request (asked while starting up parallelization).'),...
                'Indeterminate','on');
            parpool;
            app.MsgVertexDetect.Value = [sprintf('%s > Calculating local EMD. This will take several minutes.',datestr(now,'HH:MM:SS'));...
                app.MsgVertexDetect.Value];
            close(parPoolStatus);
            pause(1);
            
            % Make a nice waitbar to tell how far the calculation has progressed
            parDataQ = parallel.pool.DataQueue;
            statusEMD = uiprogressdlg(app.IceScannerUI,'Title','EMD calculation','Message',...
               'Calculating how dissimilar the reference images are compared to regions in the topography image. This will take several minutes.');
            afterEach(parDataQ, @nUpdateWaitbar);
            p = 1;
            try
                % The meat and potatoes: EMD Calculation
                switch app.typeASI
                    case 'Square' % Square ASI system
                        parfor j = 1:privateJRangeSize
                            % Initialize a matrix of zeros to temporarily store a small frame of iceGrid
                            currentFrame = zeros(ySizeRef1,xSizeRef1);          
                            % Initialize a matrix of zeros to temporarily store the locally determined EMD w/r to ref 1 and ref 2 -> [EMD intensity, x position, y position]
                            emdLocalRef1 = zeros(1,privateIRangeSize,3);
                            % Store the current y-position value
                            jPar = jRange(j);                                   
                            for i = 1:privateIRangeSize
                                % Store the current x-position value
                                iPar = iRange(i);                               
                                % Define/redefine an [j-yBranchLen:j+yBranchLen] X [i-xBranchLen:i+xBranchLen] frame to compare with the reference frame
                                currentFrame = privateIceGrid((jPar-yBranchLen:jPar+yBranchLen),(iPar-xBranchLen:iPar+xBranchLen));
                                % Normalize local frame
                                currentFrame = currentFrame./sum(currentFrame,'all');
                                currentFrame(currentFrame == 0) = 0.01;
                                % Convert the matrix into a "signature" (according to EMD), where all rows contain the grayscale intensity along with the x and
                                % y coordinates
                                currentInfo = [(currentFrame(1:numFrameElements)/sum(currentFrame,'all'))',currentInfoX,currentInfoY];
                                % Calculate the Earth Movers Distance
                                emdLocalRef1(1,i,1) = cv.EMD(refInfo1,currentInfo,'DistType','L1');
                                % Recalculate the original unscaled position values associated with the calculated EMD intensities
                                emdLocalRef1(1,i,2) = floor(iPar / privateScalingFactor);
                                emdLocalRef1(1,i,3) = floor(jPar / privateScalingFactor);
                            end
                            % Update values in the global EMD storage matrices
                            privateEmdGlobalRef1(j,:,:) = emdLocalRef1;
                            send(parDataQ,j);
                        end
                        
                    case {'Brickwork','Kagome'} % Other than square ASI
                        refInfo2 = zeros(numFrameElements,3);                       % Information regarding the reference image intensity and location
                        app.refFrame2 = app.refFrame2 / sum(app.refFrame2,'all');   % Normalize the reference frame for comparison in EMD
                        refInfo2(:,1) = app.refFrame2(1:numFrameElements);              % Store the relevant information in refInfo2
                        [refInfo2(:,2),refInfo2(:,3)] = ...
                            ind2sub([ySizeRef1,xSizeRef1],1:numFrameElements);
                        
                        % Start calculation
                        parfor j = 1:privateJRangeSize
                            % Initialize a matrix of zeros to temporarily store a small frame of iceGrid
                            currentFrame = zeros(ySizeRef1,xSizeRef1);          
                            % Initialize a matrix of zeros to temporarily store the locally determined EMD w/r to ref 1 and ref 2 -> [EMD intensity, x position, y position]
                            emdLocalRef1 = zeros(1,privateIRangeSize,3);
                            emdLocalRef2 = zeros(1,privateIRangeSize,3);   
                            % Store the current y-position value
                            jPar = jRange(j);                                   
                            for i = 1:privateIRangeSize
                                % Store the current x-position value
                                iPar = iRange(i);                               
                                % Define/redefine an [j-yBranchLen:j+yBranchLen] X [i-xBranchLen:i+xBranchLen] frame to compare with the reference frame
                                currentFrame = privateIceGrid((jPar-yBranchLen:jPar+yBranchLen),(iPar-xBranchLen:iPar+xBranchLen));
                                currentFrame = currentFrame./sum(currentFrame,'all');
                                currentFrame(currentFrame == 0) = 0.01;
                                % Convert the matrix into a "signature" (according to EMD), where all rows contain the grayscale intensity along with the x and
                                % y coordinates
                                currentInfo = [(currentFrame(1:numFrameElements)/sum(currentFrame,'all'))',currentInfoX,currentInfoY];
                                % Calculate the Earth Movers Distance
                                emdLocalRef1(1,i,1) = cv.EMD(refInfo1,currentInfo,'DistType','L1');
                                emdLocalRef2(1,i,1) = cv.EMD(refInfo2,currentInfo,'DistType','L1');
                                % Recalculate the original unscaled position values associated with the calculated EMD intensities
                                emdLocalRef1(1,i,2) = floor(iPar / privateScalingFactor);
                                emdLocalRef1(1,i,3) = floor(jPar / privateScalingFactor);
                            end
                            emdLocalRef2(1,:,2) = emdLocalRef1(1,:,2);
                            emdLocalRef2(1,:,3) = emdLocalRef1(1,:,3);
                            % Update values in the global EMD storage matrices
                            privateEmdGlobalRef1(j,:,:) = emdLocalRef1;
                            privateEmdGlobalRef2(j,:,:) = emdLocalRef2;
                            send(parDataQ,j);
                        end
                        app.emdGlobalRef2 = privateEmdGlobalRef2;
                        
                    case 'Tetris'
                        refInfo2 = zeros(numFrameElements,3);                       % Information regarding the reference image intensity and location
                        app.refFrame2 = app.refFrame2 / sum(app.refFrame2,'all');   % Normalize the reference frame for comparison in EMD
                        refInfo2(:,1) = app.refFrame2(1:numFrameElements);              % Store the relevant information in refInfo2
                        [refInfo2(:,2),refInfo2(:,3)] = ...
                            ind2sub([ySizeRef1,xSizeRef1],1:numFrameElements);
                        
                        refInfo3 = zeros(numFrameElements,3);                       % Information regarding the reference image intensity and location
                        app.refFrame3 = app.refFrame3 / sum(app.refFrame3,'all');   % Normalize the reference frame for comparison in EMD
                        refInfo3(:,1) = app.refFrame3(1:numFrameElements);              % Store the relevant information in refInfo3
                        [refInfo3(:,2),refInfo3(:,3)] = ...
                            ind2sub([ySizeRef1,xSizeRef1],1:numFrameElements);
                        
                        refInfo4 = zeros(numFrameElements,3);                       % Information regarding the reference image intensity and location
                        app.refFrame4 = app.refFrame4 / sum(app.refFrame4,'all');   % Normalize the reference frame for comparison in EMD
                        refInfo4(:,1) = app.refFrame4(1:numFrameElements);              % Store the relevant information in refInfo4
                        [refInfo4(:,2),refInfo4(:,3)] = ...
                            ind2sub([ySizeRef1,xSizeRef1],1:numFrameElements);
                        
                        refInfo5 = zeros(numFrameElements,3);                       % Information regarding the reference image intensity and location
                        app.refFrame5 = app.refFrame5 / sum(app.refFrame5,'all');   % Normalize the reference frame for comparison in EMD
                        refInfo5(:,1) = app.refFrame5(1:numFrameElements);              % Store the relevant information in refInfo5
                        [refInfo5(:,2),refInfo5(:,3)] = ...
                            ind2sub([ySizeRef1,xSizeRef1],1:numFrameElements);
                        
                        refInfo6 = zeros(numFrameElements,3);                       % Information regarding the reference image intensity and location
                        app.refFrame6 = app.refFrame6 / sum(app.refFrame6,'all');   % Normalize the reference frame for comparison in EMD
                        refInfo6(:,1) = app.refFrame6(1:numFrameElements);              % Store the relevant information in refInfo6
                        [refInfo6(:,2),refInfo6(:,3)] = ...
                            ind2sub([ySizeRef1,xSizeRef1],1:numFrameElements);
                        
                        refInfo7 = zeros(numFrameElements,3);                       % Information regarding the reference image intensity and location
                        app.refFrame7 = app.refFrame7 / sum(app.refFrame7,'all');   % Normalize the reference frame for comparison in EMD
                        refInfo7(:,1) = app.refFrame7(1:numFrameElements);              % Store the relevant information in refInfo7
                        [refInfo7(:,2),refInfo7(:,3)] = ...
                            ind2sub([ySizeRef1,xSizeRef1],1:numFrameElements);
                        
                        refInfo8 = zeros(numFrameElements,3);                       % Information regarding the reference image intensity and location
                        app.refFrame8 = app.refFrame8 / sum(app.refFrame8,'all');   % Normalize the reference frame for comparison in EMD
                        refInfo8(:,1) = app.refFrame8(1:numFrameElements);              % Store the relevant information in refInfo8
                        [refInfo8(:,2),refInfo8(:,3)] = ...
                            ind2sub([ySizeRef1,xSizeRef1],1:numFrameElements);
                        
                        % Start calculation
                        parfor j = 1:privateJRangeSize
                            % Initialize a matrix of zeros to temporarily store a small frame of iceGrid
                            currentFrame = zeros(ySizeRef1,xSizeRef1);          
                            % Initialize a matrix of zeros to temporarily store the locally determined EMD w/r to ref 1 and ref 2 -> [EMD intensity, x position, y position]
                            emdLocalRef1 = zeros(1,privateIRangeSize,3);
                            emdLocalRef2 = zeros(1,privateIRangeSize,3);   
                            emdLocalRef3 = zeros(1,privateIRangeSize,3); 
                            emdLocalRef4 = zeros(1,privateIRangeSize,3); 
                            emdLocalRef5 = zeros(1,privateIRangeSize,3);
                            emdLocalRef6 = zeros(1,privateIRangeSize,3); 
                            emdLocalRef7 = zeros(1,privateIRangeSize,3); 
                            emdLocalRef8 = zeros(1,privateIRangeSize,3); 
                            % Store the current y-position value
                            jPar = jRange(j);                                   
                            for i = 1:privateIRangeSize
                                % Store the current x-position value
                                iPar = iRange(i);                               
                                % Define/redefine an [j-yBranchLen:j+yBranchLen] X [i-xBranchLen:i+xBranchLen] frame to compare with the reference frame
                                currentFrame = privateIceGrid((jPar-yBranchLen:jPar+yBranchLen),(iPar-xBranchLen:iPar+xBranchLen));
                                currentFrame = currentFrame./sum(currentFrame,'all');
                                currentFrame(currentFrame == 0) = 0.01;
                                % Convert the matrix into a "signature" (according to EMD), where all rows contain the grayscale intensity along with the x and
                                % y coordinates
                                currentInfo = [(currentFrame(1:numFrameElements)/sum(currentFrame,'all'))',currentInfoX,currentInfoY];
                                % Calculate the Earth Movers Distance
                                emdLocalRef1(1,i,1) = cv.EMD(refInfo1,currentInfo,'DistType','L1');
                                emdLocalRef2(1,i,1) = cv.EMD(refInfo2,currentInfo,'DistType','L1');
                                emdLocalRef3(1,i,1) = cv.EMD(refInfo3,currentInfo,'DistType','L1');
                                emdLocalRef4(1,i,1) = cv.EMD(refInfo4,currentInfo,'DistType','L1');
                                emdLocalRef5(1,i,1) = cv.EMD(refInfo5,currentInfo,'DistType','L1');
                                emdLocalRef6(1,i,1) = cv.EMD(refInfo6,currentInfo,'DistType','L1');
                                emdLocalRef7(1,i,1) = cv.EMD(refInfo7,currentInfo,'DistType','L1');
                                emdLocalRef8(1,i,1) = cv.EMD(refInfo8,currentInfo,'DistType','L1');
                                % Recalculate the original unscaled position values associated with the calculated EMD intensities
                                emdLocalRef1(1,i,2) = floor(iPar / privateScalingFactor);
                                emdLocalRef1(1,i,3) = floor(jPar / privateScalingFactor);
                            end
                            emdLocalRef2(1,:,2) = emdLocalRef1(1,:,2);
                            emdLocalRef3(1,:,2) = emdLocalRef1(1,:,2);
                            emdLocalRef4(1,:,2) = emdLocalRef1(1,:,2);
                            emdLocalRef5(1,:,2) = emdLocalRef1(1,:,2);
                            emdLocalRef6(1,:,2) = emdLocalRef1(1,:,2);
                            emdLocalRef7(1,:,2) = emdLocalRef1(1,:,2);
                            emdLocalRef8(1,:,2) = emdLocalRef1(1,:,2);
                            
                            emdLocalRef2(1,:,3) = emdLocalRef1(1,:,3);
                            emdLocalRef3(1,:,3) = emdLocalRef1(1,:,3);
                            emdLocalRef4(1,:,3) = emdLocalRef1(1,:,3);
                            emdLocalRef5(1,:,3) = emdLocalRef1(1,:,3);
                            emdLocalRef6(1,:,3) = emdLocalRef1(1,:,3);
                            emdLocalRef7(1,:,3) = emdLocalRef1(1,:,3);
                            emdLocalRef8(1,:,3) = emdLocalRef1(1,:,3);
                            
                            % Update values in the global EMD storage matrices
                            privateEmdGlobalRef1(j,:,:) = emdLocalRef1;
                            privateEmdGlobalRef2(j,:,:) = emdLocalRef2;
                            privateEmdGlobalRef3(j,:,:) = emdLocalRef3;
                            privateEmdGlobalRef4(j,:,:) = emdLocalRef4;
                            privateEmdGlobalRef5(j,:,:) = emdLocalRef5;
                            privateEmdGlobalRef6(j,:,:) = emdLocalRef6;
                            privateEmdGlobalRef7(j,:,:) = emdLocalRef7;
                            privateEmdGlobalRef8(j,:,:) = emdLocalRef8;
                            send(parDataQ,j);
                        end
                        app.emdGlobalRef2 = privateEmdGlobalRef2;
                        app.emdGlobalRef3 = privateEmdGlobalRef3;
                        app.emdGlobalRef4 = privateEmdGlobalRef4;
                        app.emdGlobalRef5 = privateEmdGlobalRef5;
                        app.emdGlobalRef6 = privateEmdGlobalRef6;
                        app.emdGlobalRef7 = privateEmdGlobalRef7;
                        app.emdGlobalRef8 = privateEmdGlobalRef8;
                end
                close(statusEMD);
                
                % Transfer parpool calculations to app structure
                app.emdGlobalRef1 = privateEmdGlobalRef1;
                
                % Close parallel pool
                pause(1)
                
                statusUpdate_vertexDetect(app,'Calculation completed. Closing parallel pool.');
                parPoolStatus = uiprogressdlg(app.IceScannerUI,'Title','Closing parallel pool','Message',...
                    'Hang on a bit. MATLAB is freeing up your cores.',...
                    'Indeterminate','on');
                delete(gcp('nocreate'));
                close(parPoolStatus);
                pause(1);
                plotStatus = uiprogressdlg(app.IceScannerUI,'Title','Plotting','Message',...
                    'Processing the rough detection points.',...
                    'Indeterminate','on');
                vertexDetect_roughDetection(app);
                statusUpdate_vertexDetect(app,'Plotted detection clusters for specified EMD threshold.');
                close(plotStatus);
            catch ME
                close(statusEMD)
                parPoolStatus = uiprogressdlg(app.IceScannerUI,'Title','Closing parallel pool','Message',...
                    'Something screwed up. Closing parallel pool.',...
                    'Indeterminate','on');
                delete(gcp('nocreate'));
                close(parPoolStatus);
                errorNotice(app,ME);
                return;
            end
            app.TabGroupEMD.SelectedTab = app.ThresholdTab;
            app.arrow_importImg.Visible = 0;
            app.steps_importImg.Enable = 0;
            app.arrow_thresholding.Visible = 1;
            app.steps_thresholding.Enable = 1;
            
            % Function for the waitbar
            function nUpdateWaitbar(~)
                statusEMD.Value = p/privateJRangeSize;
                p = p+1;
            end
        end

        % Value changed function: FieldSkipInterval
        function vertexDetect_adjSkipInterval(app, event)
            % Changes the skipped pixel interval for the EMD scan
            app.skipInterval = app.FieldSkipInterval.Value;
            
            % Message
            statusUpdate_vertexDetect(app,'Updated interval for pixel skipping.');
        end

        % Callback function
        function vertexDetect_adjDirMEXOPENCV(app, event)
            % Determine directory for mexopencv
            app.dirMexOpenCV = uigetdir;
            if app.dirMexOpenCV == 0
                return;
            end
            addpath(app.dirMexOpenCV);
            statusUpdate_vertexDetect(app,'Added MexOpenCV directory');
            statusUpdate_vertexDetect(app,sprintf('%s',app.dirMexOpenCV));
            app.Field_directoryCV.Value = sprintf('%s',app.dirMexOpenCV);
        end

        % Value changed function: EditFieldRefImg1Thresh
        function vertexDetect_adjThresholdFactor1(app, event)
            % Change the value of thresholdFactor1
            app.thresholdFactor1 = app.EditFieldRefImg1Thresh.Value;
            
            % Plot the rough detection points
            plotStatus = uiprogressdlg(app.IceScannerUI,'Title','Plotting','Message',...
                'Plotting the points. If this is taking a long time you should decrease the threshold.',...
                'Indeterminate','on');
            vertexDetect_roughDetection(app);
            close(plotStatus);
            
            % Message
            statusUpdate_vertexDetect(app,'Threshold 1 adjusted. Rough detection points plotted.');
        end

        % Value changed function: EditFieldRefImg2Thresh
        function vertexDetect_adjThresholdFactor2(app, event)
            % Change the value of thresholdFactor1
            app.thresholdFactor2 = app.EditFieldRefImg2Thresh.Value;

            % Plot the rough detection points
            vertexDetect_roughDetection(app);
            
            % Message
            statusUpdate_vertexDetect(app,'Threshold 2 adjusted. Rough detection points plotted.');
        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
            % Save all the relevant "app" variables to the local structure "vd"
            vd.dirMexOpenCV = app.dirMexOpenCV;   
            vd.dirImages = app.dirImages;      
            vd.emdGlobalRef1 = app.emdGlobalRef1;           
            vd.emdGlobalRef2 = app.emdGlobalRef2;
            vd.gridHeight = app.gridHeight;        
            vd.gridWidth = app.gridWidth;              
            vd.iceGrid = app.iceGrid;                 
            vd.iRangeSize = app.iRangeSize;              
            vd.isSquareIce = app.isSquareIce;             
            vd.jRangeSize = app.jRangeSize;              
            vd.refFrame1 = app.refFrame1;               
            vd.refFrame2 = app.refFrame2;               
            vd.scalingFactor = app.scalingFactor;  
            vd.skipInterval = app.skipInterval;       
            vd.thresholdFactor1 = app.thresholdFactor1;   
            vd.thresholdFactor2 = app.thresholdFactor2;   
            vd.typeASI = app.typeASI;                 
            vd.xasGrid = app.xasGrid;
            
            % Exports the results
            [FileName,PathName] = uiputfile('*.mat','Save vertex output file');
            if ischar(FileName)
                save(fullfile(PathName,FileName),'-struct','vd');
            end
            
            % Message
            statusUpdate_vertexDetect(app,'Results saved.');
            
            app.TabGroupEMD.SelectedTab = app.FinishTabEMD;
            app.arrow_finishEMD.Visible = 1;
            app.steps_finishEMD.Enable = 1;
            app.arrow_thresholding.Visible = 0;
            app.steps_thresholding.Enable = 0;
        end

        % Button pushed function: browse_importEMDResults
        function browse_importEMDResultsPushed(app, event)
            % Open dialogue for importing vertex detection data
            oldDirImages = app.dirImages;
            [FileName,app.dirImages] = uigetfile(strcat(app.dirImages,'*.mat'),'Select vertex detection *.mat file');
            if ~ischar(FileName)
                app.dirImages = oldDirImages;
                return;
            end
            app.vd = load(fullfile(app.dirImages,FileName));
            
            % Make xas grid have 5 dimensions (in case it hasn't already been done.
            app.vd.xasGrid(:,:,5) = 0;
            
            % Save a variable xasOld
            app.vd.xasOld = app.vd.xasGrid;
            
            % Extract the locations of the detected vertex clusters in minLoc
            [app.vd.minLocJ,app.vd.minLocI] = find(app.vd.xasGrid(:,:,3) ~= 0);
            app.vd.minLoc = [app.vd.minLocJ,app.vd.minLocI,zeros(length(app.vd.minLocI),2)];
            for i = 1:length(app.vd.minLoc)
                % Store intensity
                app.vd.minLoc(i,3) = app.vd.xasGrid(app.vd.minLoc(i,1),app.vd.minLoc(i,2),3);
                % Store which reference image was utilized for detection
                app.vd.minLoc(i,4) = app.vd.xasGrid(app.vd.minLoc(i,1),app.vd.minLoc(i,2),2);
            end
            
            % Save an old version of minLoc
            app.vd.minLocOld = app.vd.minLoc;
            
            % Initialize matrices to capture ROI images that will be acquired later in the analysis process.
            app.vd.magnetMask = zeros(app.vd.gridHeight,app.vd.gridWidth);
            app.vd.magnetInterpretReg = app.vd.magnetMask;
            app.vd.magnetInterpretCombined = app.vd.magnetMask;
            app.vd.magnetEntropy = app.vd.magnetMask;
            
            % Additional values to initialize for later use in the analysis
            app.vd.thetaRange = linspace(0,2*pi,100);
            
            % Show path to the EMD file
            app.Field_detectionPath.Value = sprintf('%s%s',app.dirImages,FileName);
           
            statusUpdate_imagePro(app,'Imported vertex detection results and initialized relevant variables.');
            
            % Show XAS image on topography tab axes and analysis tab axes
            general_imageDisplay(app,app.AxesTopography,app.vd.xasGrid(:,:,1));
            general_imageDisplay(app,app.AxesAnalysis,app.vd.xasGrid(:,:,1));
            statusUpdate_imagePro(app,'Image shown in Analysis and Topography tabs.');
        end

        % Button pushed function: browse_importMagContrast
        function browse_importMagContrastPushed(app, event)
            % Open dialogue for importing XMCD image
            oldDirImages = app.dirImages;
            [FileName,app.dirImages] = uigetfile(strcat(app.dirImages,'*.tif'),'Select magnetic contrast file');
            if ~ischar(FileName)
                app.dirImages = oldDirImages;
                return;
            end
            
            % Import xmcd raw xmcd image
            app.vd.xmcd = imread(fullfile(app.dirImages,FileName));
            
            % Duplicate xmcd image for viewing
            app.vd.xmcdOriginal = mat2gray(app.vd.xmcd,[-double(max(app.vd.xmcd,[],'all')/2),double(max(app.vd.xmcd,[],'all')/2)]);
            
            % Show the initial asymmetry image
            app.vd.xmcdBoost = imadjust(app.vd.xmcdOriginal,[0.4,0.6],[],0.5);
            general_imageDisplay(app,app.AxesImportMagneticContrast,mat2gray(app.vd.xmcd));
            general_imageDisplay(app,app.AxesAnalysis,mat2gray(app.vd.xmcd));
            
            % Save a binarized image
            app.vd.xmcdBinary = app.vd.xmcd;
            app.vd.xmcdBinary(app.vd.xmcdBinary > 0) = 1;
            app.vd.xmcdBinary(app.vd.xmcdBinary < 0) = -1;
            app.vd.xmcdBinary = mat2gray(app.vd.xmcdBinary);
            
            % Show path to the magnetic contrast image file
            app.Field_magImagePath.Value = sprintf('%s%s',app.dirImages,FileName);
            
            statusUpdate_imagePro(app,'Imported magnetic contrast image.');
            statusUpdate_imagePro(app,'Image shown in Analysis and Imported magnetic contrast tabs.');
        end

        % Callback function
        function DropDownASITypeImgProcessValueChanged(app, event)
            
        end

        % Value changed function: drop_firstTimeProcessQuery
        function drop_firstTimeProcessQueryValueChanged(app, event)
            % Indicates if the data has been processed through the program.
            % This allows certain analysis steps to be bypassed as necessary.
            app.vd.cleanedBefore = app.drop_firstTimeProcessQuery.Value;
            % Update status window
            if strcmp(app.vd.cleanedBefore,'-') == 0
                app.next_import.Enable = 1;
                switch app.vd.cleanedBefore
                    case 'No'
                        statusUpdate_imagePro(app,'User has indicated that data has not been processed.');
                    case 'Yes'
                        statusUpdate_imagePro(app,'User has indicated that data has already been processed.');
                end
            else
                app.next_import.Enable = 1;
                statusUpdate_imagePro(app,'Please indicate the processing status of the imported data.');
            end
        end

        % Value changed function: EditFieldVertexReduction
        function EditFieldVertexReductionValueChanged(app, event)
            % Performs the vertex reduction by setting a minimum distance between vertices
            switch app.vd.cleanedBefore
                case 'No' % For the case where the data has not been processed before
                    % First, reset xasGrid
                    app.vd.xasGrid = app.vd.xasOld;
                    
                    % Reset minLoc
                    app.vd.minLoc = app.vd.minLocOld;
                    
                    % Save the scan distance
                    scnDst = app.EditFieldVertexReduction.Value;
                    statusUpdate_imagePro(app,sprintf('Threshold vertex distance of %d has been applied.',scnDst));
                    statusUpdate_imagePro(app,'Vertex reduction running. Please wait.');
                    pause(1);
                    f = uiprogressdlg(app.IceScannerUI,'Title','Vertex reduction running');
            
                    % Establish radial scan area
                    initialScanX = scnDst * cos(app.vd.thetaRange);
                    initialScanY = scnDst * sin(app.vd.thetaRange);
                    % Double scan to eliminate any remaining duplicates after the first scan
                    for j = 1:2
                        for i = 1:length(app.vd.minLoc)
                            f.Value = (i-1)/(2*length(app.vd.minLoc))+(j-1)*0.5;
                            ii = 0;
                            if app.vd.minLoc(i,1) ~= 0
                                % Find all other points close to this and determine which represents the true local minimum
                                iiMin = 0;
                                localInitScanX = initialScanX + app.vd.minLoc(i,1);
                                localInitScanY = initialScanY + app.vd.minLoc(i,2);
                                % Obtain all the indices of the vertices sitting within the polygon
                                switch app.vd.typeASI
                                    case {'Square','Kagome','Tetris'}
                                        ii = find(inpolygon(app.vd.minLoc(:,1),app.vd.minLoc(:,2),localInitScanX,localInitScanY) == 1);                                     
                                    case 'Brickwork'
                                        ii = find(inpolygon(app.vd.minLoc(:,1),app.vd.minLoc(:,2),localInitScanX,localInitScanY) == 1 & ...
                                           app.vd.minLoc(:,4) == app.vd.minLoc(i,4));
                                    otherwise
                                        statusUpdate_imagePro(app,'Please change the ASI type.');
                                        return;
                                end
        
                                % Figure out which of the selected indices contains the local minima
                                % If only one vertex was found within the polygon
                                if length(ii) == 1
                                    app.vd.xasGrid(app.vd.minLoc(ii,1),app.vd.minLoc(ii,2),4) = app.vd.minLoc(ii,3);
                                % If multiple vertices were detected within the polygon
                                else
                                    % Within "ii", find the vertex/vertices that are the local minimum/minima
                                    iiMin = find(app.vd.minLoc(ii,3) == min(app.vd.minLoc(ii,3)));
        
                                    % If only one minimum exists
                                    if length(iiMin) == 1
                                        app.vd.minLoc(ii(app.vd.minLoc(ii,3) ~= min(app.vd.minLoc(ii(iiMin),3))),:) = 0;
                                        app.vd.xasGrid(app.vd.minLoc(ii(iiMin),1),app.vd.minLoc(ii(iiMin),2),4) = app.vd.minLoc(ii(iiMin),3);
                                    
                                        % If multiple minima exists
                                    else
                                        % Condition if, for the second scan, vertices of different references exist
                                        if all(app.vd.minLoc(ii,4) == 1) || all(app.vd.minLoc(ii,4) == 2)
                                            app.vd.minLoc(ii(app.vd.minLoc(ii,3) ~= min(app.vd.minLoc(ii(iiMin),3))),:) = 0;
                                            avgMinX = floor(sum(app.vd.minLoc(ii(iiMin),1),'all')/length(iiMin));
                                            avgMinY = floor(sum(app.vd.minLoc(ii(iiMin),2),'all')/length(iiMin));
                                            app.vd.xasGrid(avgMinX,avgMinY,4) = app.vd.minLoc(ii(iiMin,3));
                                        else
                                            app.vd.minLoc(ii,4) = 0;
                                        end
                                    end
                                end
                            end
                        end
                        minLocator(app);
                        if j == 1
                            app.vd.xasGrid(:,:,4) = 0;
                        end
                    end
                    close(f);
                    
                    % Plot the vertices
                    vertexPlot(app,app.AxesAnalysis);
                    statusUpdate_imagePro(app,'Candidate vertex positions plotted. Please check for missing or duplicate vertices.');
                    
                case 'Yes' % For the case where the data has been processed before
                    % Plot the vertices
                    vertexPlot(app,app.AxesAnalysis);
                    statusUpdate_imagePro(app,'Candidate vertex positions plotted. Please check for missing or duplicate vertices.');
                otherwise
                    statusUpdate_imagePro(app,'Please indicate the processing status of the imported data.');
                    uialert(app.IceScannerUI,'Title','Invalid processing status','Message',...
                        'Please indicate the processing status of the imported data.');
            end
            
            % Enable the manual point editing buttons
            app.ButtonRemovePts.Enable = 1;
            app.ButtonAddPts.Enable = 1;
            app.next_cleanUp.Enable = 1;
        end

        % Button pushed function: ButtonRemovePts
        function ButtonRemovePtsPushed(app, event)
            %%%%%%%%%% Manual removal of garbage points %%%%%%%%%%
            % Show the analysis window
            app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
            app.AxesAnalysis.XLim = [0 app.vd.gridHeight];
            app.AxesAnalysis.YLim = [0 app.vd.gridWidth];
            
            deletionFigure = figure('Name','Select vertices to delete');
            deletionAxes = axes(deletionFigure);
            movegui(deletionFigure,'center');
            vertexPlot(app,deletionAxes)
            set(deletionAxes,'position',[0 0 1 1],'units','normalized');
            app.MsgImgProcess.Value = [sprintf('%s > Window created.',datestr(now,'HH:MM:SS'));app.MsgImgProcess.Value];
            app.MsgImgProcess.Value = [sprintf('%s > Awaiting user to input deletion points.',datestr(now,'HH:MM:SS'));app.MsgImgProcess.Value];
            
            % Display message box indicating to user the operation of the point removal/adder step
            removalDialog = uiprogressdlg(app.IceScannerUI,'Title','Please view the pop-up figure window','Message',...
                strcat('In the pop-up figure window, click near the existing vertex detection points you wish to remove. To undo the last',...
                ' selection, press the delete/backspace key. You may also resize this window. Once you are done, press the enter key. For this to',...
                ' work, the last object you click must be somewhere within the analysis window.'),...
                'Indeterminate','on');
            
            % Deletion script
            try
                [iRemove, jRemove] = getpts(deletionAxes);
                jRemove = floor(jRemove);
                iRemove = floor(iRemove);
                for i = 1:length(jRemove)
                    app.vd.xasGrid(jRemove(i)-10:jRemove(i)+10,iRemove(i)-10:iRemove(i)+10,4) = 0;
                end
            catch ME
                errorNotice(app,ME);
                close(deletionFigure);
                close(removalDialog);
                return;
            end
            close(deletionFigure);
            close(removalDialog);
            
            minLocator(app);
            vertexPlot(app,app.AxesAnalysis);
            statusUpdate_imagePro(app,'Candidate vertex positions plotted. Please check for missing or duplicate vertices.');
        end

        % Button pushed function: ButtonAddPts
        function ButtonAddPtsPushed(app, event)
            %%%%%%%%%% Manual addition of vertex points %%%%%%%%%%
            try
            
            app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
            app.AxesAnalysis.XLim = [0 app.vd.gridHeight];
            app.AxesAnalysis.YLim = [0 app.vd.gridWidth];
                        
            switch app.vd.typeASI
                case {'Square','Kagome','Tetris'}
                    removalDialog = uiprogressdlg(app.IceScannerUI,'Title','Please view the pop-up figure window','Message',...
                        strcat('In the pop-up figure window, click the points where you would like to add a vertex. To undo the last selection, ',...
                        'press the delete/backspace key. Once you are done, press the enter key. For this to work, the last object you click ',...
                        'must be somewhere within the analysis window.'),'Indeterminate','on');
                    [iAdd, jAdd] = additionPlotter(app);
                    jAdd = floor(jAdd);
                    iAdd = floor(iAdd);
                    for i = 1:length(jAdd)
                        app.vd.xasGrid(jAdd(i),iAdd(i),4) = 1;
                    end 
                case 'Brickwork'
                    removalDialog = uiprogressdlg(app.IceScannerUI,'Title','Please view the pop-up figure window','Message',...
                        strcat('In the pop-up figure window, click the points where you would like to add a vertex associated with the FIRST ',...
                        ' reference frame (BLUE PLUS). To undo the last selection, press the delete/backspace key. Once you are done, press the ',...
                        'enter key. For this to work, the last object you click must be somewhere within the analysis window.'),...
                        'Indeterminate','on');
                    [iAdd, jAdd] = additionPlotter(app);
                    jAdd = floor(jAdd);
                    iAdd = floor(iAdd);
                    for i = 1:length(jAdd)
                        app.vd.xasGrid(jAdd(i),iAdd(i),4) = 1;
                        app.vd.xasGrid(jAdd(i),iAdd(i),2) = 1; %#ok<*SAGROW>
                    end 
                    minLocator(app);
                    
                    close(removalDialog);
                    pause(1);
                    
                    removalDialog = uiprogressdlg(app.IceScannerUI,'Title','Please view the pop-up figure window','Message',...
                        strcat('In the pop-up figure window, click the points where you would like to add a vertex associated with the FIRST ',...
                        ' reference frame (GREEN X). To undo the last selection, press the delete/backspace key. Once you are done, press the ',...
                        'enter key. For this to work, the last object you click must be somewhere within the analysis window.'),...
                        'Indeterminate','on');
                    [iAdd, jAdd] = additionPlotter(app);
                    jAdd = floor(jAdd);
                    iAdd = floor(iAdd);
                    for i = 1:length(jAdd)
                        app.vd.xasGrid(jAdd(i),iAdd(i),4) = 1;
                        app.vd.xasGrid(jAdd(i),iAdd(i),2) = 2; %#ok<*SAGROW>
                    end
                    pause(1);
            end
            catch ME
                errorNotice(app,ME);
            end
            close(removalDialog);
            minLocator(app);
            vertexPlot(app,app.AxesAnalysis);
            statusUpdate_imagePro(app,'Manual point addition sequence finished.');
        end

        % Button pushed function: next_cleanUp
        function next_cleanUpPushed(app, event)
            % Once the user is happy with the vertex layout, then several variables will be initialized for use in the analysis
            % Initialize trueMinLoc as a structure and import minLoc values
            % TrueMinLoc is now Vertex
            app.vd.vertex(1).type = [];                             % (5) Vertex type
            app.vd.vertex(1).charge = [];                           %     Vertex charge state
            app.vd.vertex(1).nbrVertexInd = [];                     % (6-9) Index of neighboring vertices
            app.vd.vertex(1).nbrMagnetInd = [];                     % (10-13) Index of neighboring magnets in magnet
            for i = 1:length(app.vd.minLoc)
                app.vd.vertex(i).rowYPos = app.vd.minLoc(i,1);      % Row position / y-position of colXPos
                app.vd.vertex(i).colXPos = app.vd.minLoc(i,2);      % Column position / x-position of vertex
                app.vd.vertex(i).topInt = app.vd.minLoc(i,3);       % (3) Topographical intensity
                app.vd.vertex(i).refImg = app.vd.minLoc(i,4);       % (4) Index of the reference image that detected this vertex
            end
            app.vd.vertexOld = app.vd.vertex;
            
            % Initialize iceMagnets as a structure
            % IceMagnets is now magnet
            initializeMagnet(app);
            
            % Within structure vd, create substructure app.vd.postProcessFlag.* to indicate what items to generate for postprocessing
            app.vd.postProcess.MSF.create = 0;
            app.vd.postProcess.MSF.steps = 1200;
            app.vd.postProcess.MSF.qMin = -6;
            app.vd.postProcess.MSF.qMax = 6;
            app.vd.postProcess.MSF.colormap = 'Plasma';
            app.vd.postProcess.vertexPop.create = 0;
            app.vd.postProcess.overlayType.create = 0;
            app.vd.postProcess.overlayMDetect.create = 0;
            app.vd.cross1Width = 0;
            app.vd.cross1Height = 0;
            app.vd.cross1Angle = 0;
            app.vd.cross2Width = 0;
            app.vd.cross2Height = 0;
            app.vd.cross2Angle = 0;
            app.vd.diameter = 0;
            statusUpdate_imagePro(app,'Initial vertex cleaning completed. Initialized relevant variables.');
            
            app.TabGroupImgProcess.SelectedTab = app.NbrScanTab;
            app.arrow_nbrScan.Visible = 1;
            app.steps_nbrScan.Enable = 1;
            app.arrow_cleanUp.Visible = 0;
            app.steps_cleanUp.Enable = 0;
            
            % Make sure the irrelevant options in the "Neighbor Scan" tab are disabled
            switch app.vd.typeASI
                case 'Kagome'
                    app.Field_scanCross1Angle.Enable = 0;
                    app.Field_scanCross2Angle.Enable = 0;
                    app.Field_scanCross1Height.Enable = 0;
                    app.Field_scanCross2Height.Enable = 0;
                    app.Field_scanCross1Width.Enable = 0;
                    app.Field_scanCross2Width.Enable = 0;
                case {'Square','Brickwork','Tetris'}
                    app.Field_scanDiameter.Enable = 0;
            end
        end

        % Value changed function: DropDownOldVersionDetect
        function DropDownOldVersionDetectValueChanged(app, event)
            % Determine whether an old version of vertex detect was used
            app.vd.oldVersion = app.DropDownOldVersionDetect.Value;
            
            % Update status window
            switch app.vd.oldVersion
                case 'No'
                    statusUpdate_imagePro(app,'User has indicated that an old detection version was not used.');
                case 'Yes'
                    statusUpdate_imagePro(app,'User has indicated that an old detection version was used.');
                otherwise
                    statusUpdate_imagePro(app,'Please indicate the the version of vertex detection used.');
            end
        end

        % Value changed function: EditFieldVertexIndex
        function EditFieldVertexIndexValueChanged(app, event)
            % Selects the index of the vertex to be used to previewing the neighbor scan
            app.vd.pointSelect = app.EditFieldVertexIndex.Value;
            
            % Filter to reject zero or values beyond length of vertex
            if app.vd.pointSelect > 0 && app.vd.pointSelect <= length(app.vd.vertex)
                app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
                general_imageDisplay(app,app.AxesAnalysis,app.vd.xasGrid(:,:,1))
                hold(app.AxesAnalysis,'on')
                xPos = app.vd.vertex(app.vd.pointSelect).colXPos;
                yPos = app.vd.vertex(app.vd.pointSelect).rowYPos;
                plot(app.AxesAnalysis,xPos,yPos,'b.','MarkerSize',20);
                app.AxesAnalysis.XLim = [0 app.vd.gridHeight];
                app.AxesAnalysis.YLim = [0 app.vd.gridWidth];
                
                statusUpdate_imagePro(app,'Vertex with index %d is shown as a blue dot.');
            else
                statusUpdate_imagePro(app,sprintf('Please input a value between 1 and %d (max index value).', length(app.vd.vertex)));
            end
        end

        % Value changed function: Field_scanCross1Width
        function Field_scanCross1WidthValueChanged(app, event)
            % Cross 1 width
            app.vd.cross1Width = app.Field_scanCross1Width.Value;
            % If this value becomes zero, disable the preview scan area and neighbor detect buttons
            nbrScanButtonEnable(app);
        end

        % Value changed function: Field_scanCross1Height
        function Field_scanCross1HeightValueChanged(app, event)
            % Cross 1 height
            app.vd.cross1Height = app.Field_scanCross1Height.Value;
            % If this value becomes zero, disable the preview scan area and neighbor detect buttons
            nbrScanButtonEnable(app);
        end

        % Value changed function: Field_scanCross1Angle
        function Field_scanCross1AngleValueChanged(app, event)
            % Cross 1 angle
            app.vd.cross1Angle = app.Field_scanCross1Angle.Value;
        end

        % Value changed function: Field_scanCross2Width
        function Field_scanCross2WidthValueChanged(app, event)
            % Cross 2 height
            app.vd.cross2Height = app.Field_scanCross2Width.Value;
            % If this value becomes zero, disable the preview scan area and neighbor detect buttons
            nbrScanButtonEnable(app);
        end

        % Value changed function: Field_scanCross2Height
        function Field_scanCross2HeightValueChanged(app, event)
            % Cross 2 width
            app.vd.cross2Width = app.Field_scanCross2Height.Value;
            % If this value becomes zero, disable the preview scan area and neighbor detect buttons
            nbrScanButtonEnable(app);
        end

        % Value changed function: Field_scanCross2Angle
        function Field_scanCross2AngleValueChanged(app, event)
            % Cross 2 angle
            app.vd.cross2Angle = app.Field_scanCross2Angle.Value;
            % If this value becomes zero, disable the preview scan area and neighbor detect buttons
            nbrScanButtonEnable(app);
        end

        % Value changed function: Field_scanDiameter
        function Field_scanDiameterValueChanged(app, event)
            % Circle scan radius
            app.vd.diameter = app.Field_scanDiameter.Value;
            % If this value becomes zero, disable the preview scan area and neighbor detect buttons
            nbrScanButtonEnable(app);
        end

        % Button pushed function: ButtonPreviewScan
        function ButtonPreviewScanPushed(app, event)
            try
                % Plot the preview of the scan area around the chosen vertex index
              
                % Show the analysis window
                app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
                
                % Show the topography image
                general_imageDisplay(app,app.AxesAnalysis,app.vd.xasGrid(:,:,1));
                hold(app.AxesAnalysis,'on');
                vertex = app.vd.vertex;
                pointSelect = app.vd.pointSelect;
                switch app.vd.typeASI
                    case {'Square', 'Brickwork','Tetris'}
                        % Variable duplication
                        cross1Width = app.vd.cross1Width;
                        cross1Height = app.vd.cross1Height;
                        cross1Angle = app.vd.cross1Angle;
                        cross2Width = app.vd.cross2Width;
                        cross2Height = app.vd.cross2Height;
                        cross2Angle = app.vd.cross2Angle;
                        cross1PerimeterX = cross1Width * [-1:0.02:1,ones(1,length(-1:0.02:1)),1:-0.02:-1,-ones(1,length(-1:0.02:1))];
                        cross1PerimeterY = cross1Height * [ones(1,length(-1:0.02:1)),1:-0.02:-1,-ones(1,length(-1:0.02:1)),-1:0.02:1];
                        cross1AreaScanX = cosd(-cross1Angle)*cross1PerimeterX - sind(-cross1Angle)*cross1PerimeterY + ...
                            vertex(pointSelect).colXPos;
                        cross1AreaScanY = sind(-cross1Angle)*cross1PerimeterX + cosd(-cross1Angle)*cross1PerimeterY + ...
                            vertex(pointSelect).rowYPos;
                        cross1LocalArea = [cross1AreaScanX',cross1AreaScanY'];
                        % Remove any portions of the circle that would exceed the edge
                        cross1LocalArea(cross1LocalArea(:,1) < 1 | cross1LocalArea(:,1) > app.vd.gridWidth) = NaN;
                        cross1LocalArea(cross1LocalArea(:,2) < 1 | cross1LocalArea(:,2) > app.vd.gridHeight) = NaN;
                        cross1LocalArea(any(isnan(cross1LocalArea),2) == 1,:) = [];
                        
                        cross2PerimeterX = cross2Width * [-1:0.02:1,ones(1,length(-1:0.02:1)),1:-0.02:-1,-ones(1,length(-1:0.02:1))];
                        cross2PerimeterY = cross2Height * [ones(1,length(-1:0.02:1)),1:-0.02:-1,-ones(1,length(-1:0.02:1)),-1:0.02:1];
                        cross2AreaScanX = cosd(-cross2Angle)*cross2PerimeterX - sind(-cross2Angle)*cross2PerimeterY + ...
                            vertex(pointSelect).colXPos;
                        cross2AreaScanY = sind(-cross2Angle)*cross2PerimeterX + cosd(-cross2Angle)*cross2PerimeterY + ...
                            vertex(pointSelect).rowYPos;
                        cross2LocalArea = [cross2AreaScanX',cross2AreaScanY'];
                        % Remove any portions of the circle that would exceed the edge
                        cross2LocalArea(cross2LocalArea(:,1) < 1 | cross2LocalArea(:,1) > app.vd.gridWidth) = NaN;
                        cross2LocalArea(cross2LocalArea(:,2) < 1 | cross2LocalArea(:,2) > app.vd.gridHeight) = NaN;
                        cross2LocalArea(any(isnan(cross2LocalArea),2) == 1,:) = [];
                        
                        localArea = [cross1LocalArea;cross2LocalArea];
                        
                        app.vd.cross1PerimeterX = cross1PerimeterX;
                        app.vd.cross1PerimeterY = cross1PerimeterY;
                        app.vd.cross2PerimeterX = cross2PerimeterX;
                        app.vd.cross2PerimeterY = cross2PerimeterY;
                    case 'Kagome'
                        areaScanX = app.vd.diameter*cos(app.vd.thetaRange) + vertex(pointSelect).colXPos;
                        areaScanY = app.vd.diameter*sin(app.vd.thetaRange) + vertex(pointSelect).rowYPos;
                        localArea = [areaScanX',areaScanY']; 
                end
                
                % Remove any portions of the scan area that would exceed the edge
                localArea(localArea(:,1) < 1 | localArea(:,1) > app.vd.gridWidth) = NaN;
                localArea(localArea(:,2) < 1 | localArea(:,2) > app.vd.gridHeight) = NaN;
                localArea(any(isnan(localArea),2) == 1,:) = [];
                
                % Plot
                plot(app.AxesAnalysis,localArea(:,1),localArea(:,2));
                xPos = vertex(pointSelect).colXPos;
                yPos = vertex(pointSelect).rowYPos;
                plot(app.AxesAnalysis,xPos,yPos,'b.','MarkerSize',20);
                hold(app.AxesAnalysis,'off');
                
                statusUpdate_imagePro(app,'Scan area preview plotted.');
                
                % Enable the neighbor detection button if it is currently disabled
                if app.ButtonNeighborDetect.Enable == 0
                    app.ButtonNeighborDetect.Enable = 1;
                end
            catch ME
                errorNotice(app,ME);
            end
        end

        % Button pushed function: ButtonNeighborDetect
        function ButtonNeighborDetectPushed(app, event)
            try
                % Scan for neighboring vertices
                % First create a waitbar to get an idea of where we're at
                statusUpdate_imagePro(app,'Running neighbor detection and magnet assignment.');
                f = uiprogressdlg(app.IceScannerUI,'Title','Neighbor search','Message',...
                    'Searching for neighboring vertices, assigning magnet positions, and reading magnetizations.');
                % Reset vertex
                app.vd = rmfield(app.vd,'vertex');
                app.vd.vertex = app.vd.vertexOld;
                
                % Clear and initialize magnet
                initializeMagnet(app)
                
                % Variable duplication
                trueMinXPos = vertcat(app.vd.vertex.colXPos);
                trueMinYPos = vertcat(app.vd.vertex.rowYPos);
                thetaRange = app.vd.thetaRange;   
                switch app.vd.typeASI
                    case {'Square','Brickwork','Tetris'}
                        diameter = 0;
                        cross1Angle = app.vd.cross1Angle;
                        cross2Angle = app.vd.cross2Angle;
                        cross1PerimeterX = app.vd.cross1PerimeterX;
                        cross1PerimeterY = app.vd.cross1PerimeterY;
                        cross2PerimeterX = app.vd.cross2PerimeterX;
                        cross2PerimeterY = app.vd.cross2PerimeterY;
                    case 'Kagome'
                        diameter = app.vd.diameter;
                        cross1Angle = 0;
                        cross2Angle = 0;
                        cross1PerimeterX = 0;
                        cross1PerimeterY = 0;
                        cross2PerimeterX = 0;
                        cross2PerimeterY = 0;
                end
                
                % Conduct area scan to search for nearest neighboring vertices
                for currIndVtx = 1:length(app.vd.vertex)
                    f.Value = currIndVtx/length(app.vd.vertex);
                    % First, locate the neighboring vertices
                    neighborVertexLocator(app,currIndVtx,cross1Angle,cross1PerimeterX,cross1PerimeterY,cross2Angle,...
                        cross2PerimeterX,cross2PerimeterY,trueMinXPos,trueMinYPos,thetaRange,diameter);
                    
                    % Second, define magnets between vertices
                    magnetScanFilter(app,currIndVtx); % This seems like it's adding additional entries into nbrMagnetInd...
                    
                    % Third, assign vertex types
                    vertexTypeAssignment(app,currIndVtx); % This seems like it's adding additional entries into nbrMagnetInd...
                end
                close(f);
                statusUpdate_imagePro(app,'Neighbor detection and magnet assignment completed.');
                
                % Once the analysis has been completed, plot the results
                statusUpdate_imagePro(app,'Plotting interpreted vertex states.');
               
                % First, show the analysis window with the XMCD image
                app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
                general_imageDisplay(app,app.AxesAnalysis,mat2gray(app.vd.xmcd));
                overlayVertexType(app,app.AxesAnalysis);
                overlayMagnetization(app,app.AxesAnalysis);
                statusUpdate_imagePro(app,'Plotting of vertex states completed.');
                pause(1);
                
                % Output ROI matrices
                app.vd.xmcdBinarySkeleton = app.vd.xmcdBinary;
                app.vd.xmcdBinarySkeleton(app.vd.magnetMask == 0) = 1/2;
                app.vd.xmcdBoostSkeleton = app.vd.xmcdBoost;
                app.vd.xmcdBoostSkeleton(app.vd.magnetMask == 0) = 1/2;
                app.vd.xmcdOriginalSkeleton = app.vd.xmcdOriginal;
                app.vd.xmcdOriginalSkeleton(app.vd.magnetMask == 0) = 1/2;
                app.vd.magnetInterpretCombinedImg = ...
                    mat2gray(app.vd.magnetInterpretCombined,[-double(max(app.vd.magnetInterpretCombined,[],'all')/2),...
                    double(max(app.vd.magnetInterpretCombined,[],'all')/2)]);
                
                % Display the magnetinterpretcombinedimg
                general_imageDisplay(app,app.AxesInterpret,mat2gray(app.vd.magnetInterpretCombinedImg));
                overlayVertexType(app,app.AxesInterpret);
                overlayMagnetization(app,app.AxesInterpret);
                
                % Display the binarized image
                general_imageDisplay(app,app.AxesBinary,mat2gray(app.vd.xmcdBinarySkeleton));
                overlayVertexType(app, app.AxesBinary);
                overlayMagnetization(app,app.AxesBinary)
               
                statusUpdate_imagePro(app,'Magnetization interpretation and binary images are ready for previewing.');
                uialert(app.IceScannerUI,'Images of the program-interpreted magnetization and binary contrast images are ready for previewing',...
                    'Ready for previewing','Icon','success');
                % Enable the next button if it is currently disabled
                if app.next_nbrScan.Enable == 0
                    app.next_nbrScan.Enable = 1;
                end
            catch ME
                errorNotice(app,ME)
                return;
            end
        end

        % Button pushed function: ButtonRefMagSelect
        function ButtonRefMagSelectPushed(app, event)
            % Lets the user select the reference magnet state used for performing lattice indexing.
            selectionFigure = figure('Name','Select reference magnet');
            selectionAxes = axes(selectionFigure);
            movegui(selectionFigure,'center');
            vertexPlot(app,selectionAxes)
            set(selectionAxes,'position',[0 0 1 1],'units','normalized');
            statusUpdate_imagePro(app,'Window created.');
            statusUpdate_imagePro(app,'Awaiting user to select the reference magnet.');
            
            % Display message box indicating to user the operation of the point removal/adder step
            latticeDialog = uiprogressdlg(app.IceScannerUI,'Title','Please view the pop-up figure window','Message',...
                strcat('In the pop-up figure window, click near the center of the magnet you wish to choose as the reference magnet. ',...
                'To undo the last selection, press the delete/backspace key. Once you are done, press the enter key.',...
                'For this to work, the last object you click must be somewhere within the analysis window.'),...
                'Indeterminate','on');
            
            % Selection script
            try
                [colXRef, rowYRef] = getpts(selectionAxes);
                colXRef = floor(colXRef);
                rowYRef = floor(rowYRef);
            catch ME
                errorNotice(app,ME);
                close(selectionFigure);
                close(latticeDialog);
                return;
            end
            close(selectionFigure);
            close(latticeDialog);
            % Initialize the magnet index that will reside at the origin of the generated lattice
            magnet00 = find((vertcat(app.vd.magnet.rowYPos) <= rowYRef + 10 & vertcat(app.vd.magnet.rowYPos) >= rowYRef - 10) &...
                vertcat(app.vd.magnet.colXPos) <= colXRef + 10 & vertcat(app.vd.magnet.colXPos) >= colXRef - 10);
            app.vd.magnet00 = magnet00;
            
            % Before doing anything else, initialize all index flags to be zero (old code work based on assumption that all index flags start as 0)
            for magIdx = 1:length(app.vd.magnet)
                app.vd.magnet(magIdx).indexFlag = 0;
            end
            
            % Initialize the origin state
            app.vd.magnet(magnet00).aInd = 0;
            app.vd.magnet(magnet00).bInd = 0;
            app.vd.magnet(magnet00).indexFlag = 2;
            switch app.vd.typeASI
                case 'Kagome'
                    app.vd.magnet(magnet00).orient = 1;
                    app.vd.magnet(magnet00).forkType = 1;
                case {'Brickwork','Square','Tetris'}
                    app.vd.magnet(magnet00).orient = 3;
                    app.vd.magnet(magnet00).forkType = 0;
            end
            
            % Plot the first lattice point
            app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
            app.AxesAnalysis.XLim = [0 app.vd.gridHeight];
            app.AxesAnalysis.YLim = [0 app.vd.gridWidth];
            general_imageDisplay(app,app.AxesAnalysis,app.vd.xasGrid(:,:,1));
            
            hold(app.AxesAnalysis,'on');
            plot(app.AxesAnalysis,app.vd.magnet(magnet00).colXPos,app.vd.magnet(magnet00).rowYPos,'r.','MarkerSize',10);
            text(app.AxesAnalysis,app.vd.magnet(magnet00).colXPos,app.vd.magnet(magnet00).rowYPos,...
                sprintf('(%i,%i)',app.vd.magnet(magnet00).aInd,app.vd.magnet(magnet00).bInd),'Color','r')
            
            statusUpdate_imagePro(app,'Origin lattice point plotted.');
            
            % Enable mapping button
            app.next_latticeIdx.Enable = 1;
        end

        % Menu selected function: MenuImgProcSaveState
        function MenuImgProcSaveStateSelected(app, event)
            % Save the current image processing state
            [FileName,PathName] = uiputfile('*.mat','Save data');
            vd = app.vd;
            if ischar(FileName)
                save(fullfile(PathName,FileName),'-struct','vd');
            end
            
            % Message
            statusUpdate_imagePro(app,'Results saved.');
        end

        % Menu selected function: MenuDiagnosticImport
        function MenuDiagnosticImportSelected(app, event)
            % Diagnostic import of data file
            [FileName,app.dirImages] = uigetfile(strcat(app.dirImages,'*.tif'),'Select vertex detection *.mat file');
            if ischar(FileName)
                app.vd = load(fullfile(app.dirImages,FileName));
                statusUpdate_imagePro(app,'File imported.');
            else
                statusUpdate_imagePro(app,'Import canceled');
            end
        end

        % Button pushed function: next_latticeIdx
        function next_latticeIdxPushed(app, event)
            % For this reference magnet (0,0), determine the start and end vertices to calculate the reference vector for magnet00
            % First, set magnet00 to be equal to app.vd.magnet00
            magnet00 = app.vd.magnet00;
            
            % MAKE SURE YOU SELECT A TYPE 3 MAGNET FIRST "\"
            if app.vd.vertex(app.vd.magnet(magnet00).nbrVertexInd(1)).colXPos > app.vd.magnet(magnet00).colXPos % If the vertex lies to the right of magnet00
                vertexMagEnd = app.vd.magnet(magnet00).nbrVertexInd(1);
                vertexMagStart = app.vd.magnet(magnet00).nbrVertexInd(2); 
            else
                vertexMagEnd = app.vd.magnet(magnet00).nbrVertexInd(2);
                vertexMagStart = app.vd.magnet(magnet00).nbrVertexInd(1);
            end
            % Look for the magnets neighboring magnet00's "end" vertex
            magNeigh = app.vd.vertex(vertexMagEnd).nbrMagnetInd;
            % Remove magnets that are null or includes the reference magnet
            magNeigh(magNeigh == 0 | magNeigh == magnet00) = [];
            
            % Maps the ASI magnets to a perfect lattice with corresponding lattice coordinates
            switch app.vd.typeASI
                case 'Kagome'
                    kagomeMapper(app,magNeigh,vertexMagStart,vertexMagEnd,magnet00);
                case {'Brickwork','Square','Tetris'}
                    squareMapper(app,magNeigh,vertexMagStart,vertexMagEnd,magnet00);
            end
            
            % Rather than showing the full plot in the UI, create a high-resolution image from an invisible figure
            plotDialog = uiprogressdlg(app.IceScannerUI,'Title','Plotting lattice points','Message',...
                'Plotting the mapped lattice points with corresponding lattice coordinates.','Indeterminate','on');
            
            f = figure('visible','off','Position',[0,0,app.vd.gridWidth*50,app.vd.gridHeight*50]);
            ax1 = axes(f,'Position',[0,0,1,1],'Visible','off');
            hold(ax1,'on');
            imshow(app.vd.xasGrid(:,:,1),'Parent',ax1);
            axis(ax1,'image');
            for magIndex = 1:length(app.vd.magnet)
                x = app.vd.magnet(magIndex).colXPos;
                y = app.vd.magnet(magIndex).rowYPos;
                a = app.vd.magnet(magIndex).aInd;
                b = app.vd.magnet(magIndex).bInd;
                plot(ax1,x,y,'r.','MarkerSize',20);
                text(ax1,x,y,sprintf('(%i,%i)',a,b),'FontSize',5);
            end
            hold(ax1,'off');
            f_img = frame2im(getframe(f));
            app.vd.latticeMap = f_img;
            close(f);
            close(plotDialog);
            
            % Plot the lattice points in the main window
            app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
            app.AxesAnalysis.XLim = [0 app.vd.gridHeight*10];
            app.AxesAnalysis.YLim = [0 app.vd.gridWidth*10];
            general_imageDisplay(app,app.AxesAnalysis,f_img);
            axis(app.AxesAnalysis,'image');
            
            % Plot the lattice points in the lattice indexing tab window
            app.TabGroupAnalysis.SelectedTab = app.LatticeIndexTab;
            app.AxesLatticeIndex.XLim = [0 app.vd.gridHeight];
            app.AxesLatticeIndex.YLim = [0 app.vd.gridWidth];
            general_imageDisplay(app,app.AxesLatticeIndex,f_img);
            axis(app.AxesLatticeIndex,'image');
            
            statusUpdate_imagePro(app,'Lattice point assignment completed.');
            
            % Save a copy of the current app.vd.magnet state in case a magnetization is erroneously deleted
            saveDialog = uiprogressdlg(app.IceScannerUI,'Title','Backing up data','Message',...
                'Backing up magnetization data. Please wait..','Indeterminate','on');
            app.vd.oldMagnet = app.vd.magnet;
            close(saveDialog);
            
            app.TabGroupImgProcess.SelectedTab = app.FinalinspectionTab;
            app.arrow_finalInspect.Visible = 1;
            app.steps_finalInspect.Enable = 1;
            app.arrow_latticeIdx.Visible = 0;
            app.steps_latticeIdx.Enable = 0;
        end

        % Value changed function: MagneticstructurefactorCheckBox
        function MagneticstructurefactorCheckBoxValueChanged(app, event)
            value = app.MagneticstructurefactorCheckBox.Value;
            app.vd.postProcess.MSF.create = value;
        end

        % Value changed function: MSFSteps
        function MSFStepsValueChanged(app, event)
            value = app.MSFSteps.Value;
            app.vd.postProcess.MSF.steps = value;
        end

        % Value changed function: MSFStart
        function MSFStartValueChanged(app, event)
            value = app.MSFStart.Value;
            app.vd.postProcess.MSF.qMin = value;
        end

        % Value changed function: MSFEnd
        function MSFEndValueChanged(app, event)
            value = app.MSFEnd.Value;
            app.vd.postProcess.MSF.qMax = value;
        end

        % Value changed function: VertexcountsCheckBox
        function VertexcountsCheckBoxValueChanged(app, event)
            value = app.VertexcountsCheckBox.Value;
            app.vd.postProcess.vertexPop.create = value;
        end

        % Value changed function: ContrastvertextypeCheckBox
        function ContrastvertextypeCheckBoxValueChanged(app, event)
            value = app.ContrastvertextypeCheckBox.Value;
            app.vd.postProcess.overlayType.create = value;
        end

        % Value changed function: ContrastdetectedMCheckBox
        function ContrastdetectedMCheckBoxValueChanged(app, event)
            value = app.ContrastdetectedMCheckBox.Value;
            app.vd.postProcess.overlayMDetect = value;
        end

        % Button pushed function: next_postProcess
        function next_postProcessPushed(app, event)
            % Generates all requested postprocessing material
            % First, ask user where to save the files to
            oldDirImages = app.dirImages;
            [FileName,app.dirImages] = uiputfile(strcat(app.dirImages,'*.mat'),'PostProcessing data file');
            if ~ischar(FileName)
                app.dirImages = oldDirImages;
                return;
            end
            
            % If MSF option was selected, make MSF plots
            if app.MagneticstructurefactorCheckBox.Value == 1
                generateMSF(app,app.dirImages,'on','on');
            end
            
            % If pair-wise correlation option was selected, calculate correlations
            if app.PairwisecorrelationCheckBox.Value == 1
                % First, determine the n-th neighbors for each magnet
                neighborIdxLocator(app);
                
                % Then, for each neighbor type, compile a list of index pairs
                % to eliminate redundant index pairs
                removeRedundantNbrIdx(app);
                
                % Finally, calculate spin-spin correlations
                correlationCalc(app);
            end
            
            % If the vertex count option was selected, plot the vertex statistics
            if app.VertexcountsCheckBox.Value == 1
                exportVertexStatistics(app,app.dirImages);
            end
            
            % If the mapped lattice point option is selected, save app.vd.latticeMap
            if app.MappedlatticepointsCheckBox.Value == 1
                currentStatus = uiprogressdlg(app.IceScannerUI,'Title','Generating image','Message',...
                    'Generating topography image with superimposed lattice coordinates.','Indeterminate','on');
                imwrite(app.vd.latticeMap,sprintf('%sLattice coordinates.tif',app.dirImages),'tif',"Resolution",[10000,10000]);
                statusUpdate_imagePro(app,'Finished generating mapped lattice points');
            end
            
            % If the contrast + vertex type check box is selected, save that image
            if app.ContrastvertextypeCheckBox.Value == 1
                currentStatus = uiprogressdlg(app.IceScannerUI,'Title','Generating image','Message',...
                    'Generating contrast image with vertex types superimposed.','Indeterminate','on');
                [imgHeight,imgWidth,~] = size(mat2gray(app.vd.xmcd));
                f = figure('visible','off','Name','Magnetic Structure Factor', 'Position', [100,100,imgWidth,imgHeight]);
                ax1 = axes(f,'Visible','off');
                general_imageDisplay(app,ax1,mat2gray(app.vd.xmcd));
                overlayVertexType(app,ax1);
                print(f,sprintf('%sContrast and vertex types.tif',app.dirImages),'-dtiffn','-r600');
                close(f);
                close(currentStatus);
            end
            
            % If the contrast + vertex type check box is selected, save that image
            if app.ContrastdetectedMCheckBox.Value == 1
                currentStatus = uiprogressdlg(app.IceScannerUI,'Title','Generating image','Message',...
                    'Generating contrast image with vertex types and detected magnetizations superimposed.','Indeterminate','on');
                [imgHeight,imgWidth,~] = size(mat2gray(app.vd.xmcd));
                f = figure('visible','off','Name','Magnetic Structure Factor', 'Position', [100,100,imgWidth,imgHeight]);
                ax1 = axes(f,'Visible','off');
                general_imageDisplay(app,ax1,mat2gray(app.vd.xmcd));
                overlayVertexType(app,ax1);
                overlayMagnetization(app,ax1);
                print(f,sprintf('%sContrast and magnetizations.tif',app.dirImages),'-dtiffn','-r600');
                close(f);
                close(currentStatus);
            end

            currentStatus = uiprogressdlg(app.IceScannerUI,'Title','Saving data','Message',...
                'Saving analysis data to MAT-file.','Indeterminate','on');
            vd = app.vd;
            if ischar(FileName)
                save(fullfile(app.dirImages,FileName),'-struct','vd');
            end
            close(currentStatus)
            
            statusUpdate_imagePro(app,'Results saved.');
            app.TabGroupImgProcess.SelectedTab = app.FinishTab;
            app.arrow_finish.Visible = 1;
            app.steps_finish.Enable = 1;
            app.arrow_postProcess.Visible = 0;
            app.steps_postProcess.Enable = 0;
            uialert(app.IceScannerUI,'Requested files have been saved.','Save completed','Icon','success')
        end

        % Callback function
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            app.vd.postProcess.MSF.colormap = value;
        end

        % Button pushed function: next_import
        function next_importPushed(app, event)
            app.TabGroupImgProcess.SelectedTab = app.CleanupTab;
            app.arrow_import.Visible = 0;
            app.steps_import.Enable = 0;
            app.arrow_cleanUp.Visible = 1;
            app.steps_cleanUp.Enable = 1;
        end

        % Button pushed function: back_cleanUp
        function back_cleanUpButtonPushed(app, event)
            app.TabGroupImgProcess.SelectedTab = app.ImportTab;
            app.arrow_import.Visible = 1;
            app.steps_import.Enable = 1;
            app.arrow_cleanUp.Visible = 0;
            app.steps_cleanUp.Enable = 0;
        end

        % Button pushed function: back_nbrScan
        function back_nbrScanButtonPushed(app, event)
            app.TabGroupImgProcess.SelectedTab = app.CleanupTab;
            app.arrow_nbrScan.Visible = 0;
            app.steps_nbrScan.Enable = 0;
            app.arrow_cleanUp.Visible = 1;
            app.steps_cleanUp.Enable = 1;
        end

        % Button pushed function: next_nbrScan
        function next_nbrScanButtonPushed(app, event)
            app.TabGroupImgProcess.SelectedTab = app.LatticeindexingTab;
            app.arrow_nbrScan.Visible = 0;
            app.steps_nbrScan.Enable = 0;
            app.arrow_latticeIdx.Visible = 1;
            app.steps_latticeIdx.Enable = 1;
            
            % Disable mapping button in the lattice indexing tab
            app.next_latticeIdx.Enable = 0;
        end

        % Button pushed function: back_latticeIdx
        function back_latticeIdxButtonPushed(app, event)
            app.TabGroupImgProcess.SelectedTab = app.NbrScanTab;
            app.arrow_nbrScan.Visible = 1;
            app.steps_nbrScan.Enable = 1;
            app.arrow_latticeIdx.Visible = 0;
            app.steps_latticeIdx.Enable = 0;
        end

        % Button pushed function: back_finalInspect
        function back_finalInspectButtonPushed(app, event)
            app.TabGroupImgProcess.SelectedTab = app.LatticeindexingTab;
            app.arrow_finalInspect.Visible = 0;
            app.steps_finalInspect.Enable = 0;
            app.arrow_latticeIdx.Visible = 1;
            app.steps_latticeIdx.Enable = 1;
        end

        % Button pushed function: next_finalInspect
        function next_finalInspectButtonPushed(app, event)
            %%%%%%%%%% Move to the next tab and determine spin vector components for magnetic structure factor and correlation length %%%%%%%%%%
            determineSpinVectorComp(app);
            
            app.TabGroupImgProcess.SelectedTab = app.PostprocessingTab;
            app.arrow_finalInspect.Visible = 0;
            app.steps_finalInspect.Enable = 0;
            app.arrow_postProcess.Visible = 1;
            app.steps_postProcess.Enable = 1;
        end

        % Button pushed function: back_postProcess
        function back_postProcessButtonPushed(app, event)
            app.TabGroupImgProcess.SelectedTab = app.FinalinspectionTab;
            app.arrow_finalInspect.Visible = 1;
            app.steps_finalInspect.Enable = 1;
            app.arrow_postProcess.Visible = 0;
            app.steps_postProcess.Enable = 0;
        end

        % Button pushed function: finish_scanNewImage
        function finish_scanNewImagePushed(app, event)
            app.TabGroupEMD.SelectedTab = app.ImageTab;
            app.arrow_importImg.Visible = 1;
            app.steps_importImg.Enable = 1;
            app.arrow_finishEMD.Visible = 0;
            app.steps_finishEMD.Enable = 0;
        end

        % Callback function
        function next_directoryButtonPushed(app, event)
            app.TabGroupEMD.SelectedTab = app.ImageTab;
            app.arrow_importImg.Visible = 1;
            app.steps_importImg.Enable = 1;
            app.arrow_path.Visible = 0;
            app.steps_path.Enable = 0;
        end

        % Callback function
        function back_imageButtonPushed(app, event)
            app.TabGroupEMD.SelectedTab = app.DirectoryTab;
            app.arrow_importImg.Visible = 0;
            app.steps_importImg.Enable = 0;
            app.arrow_path.Visible = 1;
            app.steps_path.Enable = 1;
        end

        % Button pushed function: back_threshold
        function back_thresholdButtonPushed(app, event)
            app.TabGroupEMD.SelectedTab = app.ImageTab;
            app.arrow_importImg.Visible = 1;
            app.steps_importImg.Enable = 1;
            app.arrow_thresholding.Visible = 0;
            app.steps_thresholding.Enable = 0;
        end

        % Button pushed function: ButtonRefMagSelect_2
        function ButtonRefMagSelect_2Pushed(app, event)
            %%%%%%%%%% Choose magnets to manually remove using specified image %%%%%%%%%%
            
            % Generate the image figure
            selectionFigure = figure('Name','Select magnets to mask');
            selectionAxes = axes(selectionFigure);
            movegui(selectionFigure,'center');
            
            switch app.drop_inspectionImage.Value
                case '-'
                    uialert(app.IceScannerUI,'Select an image to perform manual masking of nanomagnets.','Error');
                    return;
                case 'XAS'
                    backgroundImage = app.vd.xasGrid(:,:,1);
                case 'XMCD'
                    backgroundImage = mat2gray(app.vd.xmcd);
                case 'XMCD (more contrast)'
                    backgroundImage = mat2gray(app.vd.xmcdBoost);
                case 'Absolute value of XMCD'
                    backgroundImage = mat2gray(abs(app.vd.xmcd));
                case 'ROI - Regular'
                    backgroundImage = mat2gray(app.vd.xmcdOriginalSkeleton);
                case 'ROI - Averaged'
                    backgroundImage = mat2gray(app.vd.magnetInterpretCombinedImg);
            end
            
            general_imageDisplay(app,selectionAxes,backgroundImage);
            overlayMagnetization(app,selectionAxes);
            statusUpdate_imagePro(app,'Window created.');
            statusUpdate_imagePro(app,'Awaiting user to select the reference magnet.');
            
            % Notify the user to select points on the popup figure that will be initiated after activation of the dialog
            deleteDialog = uiprogressdlg(app.IceScannerUI,'Title','Please view the pop-up figure window','Message',...
                strcat('In the pop-up window, select the centers of the nanomagnets you wish to mask (i.e., set the magnetization equal to zero).',...
                'To undo the last selection, press the delete/backspace key. Once you are done, press the enter key.',...
                'For this to work, the last object you click must be somewhere within the analysis window.'),'Indeterminate','on');
            try
                % Selection script
                [xRemove, yRemove] = getpts(selectionAxes);
                xRemove = floor(xRemove);
                yRemove = floor(yRemove);
            catch ME
                errorNotice(app,ME);
                close(deleteDialog);
                return;
            end
            
            magnetRowYLoc = vertcat(app.vd.magnet.rowYPos);
            magnetColXLoc = vertcat(app.vd.magnet.colXPos);
            for i = 1:length(xRemove)
                % Look for the corresponding magnet coordinates in iceMagnets within a +- 10 variance along x and y
                deleteIdx = find((magnetRowYLoc <= yRemove(i) + 10 & magnetRowYLoc >= yRemove(i) - 10) &...
                    magnetColXLoc <= xRemove(i) + 10 & magnetColXLoc >= xRemove(i) - 10);
                
                % Set the magnetization equal to zero (background/ambiguous)
                app.vd.magnet(deleteIdx).xmcdAvg = 0;
                app.vd.magnet(deleteIdx).projection = 0;
                app.vd.magnet(deleteIdx).xmcdWeighted = 0;
                app.vd.magnet(deleteIdx).xmcdBinary = 0;
                
                % Once the index is found, backreference which vertices correspond to it and set their corresponding charges to zero
                alphaVertex = app.vd.magnet(deleteIdx).nbrVertexInd(1);
                betaVertex = app.vd.magnet(deleteIdx).nbrVertexInd(2);
                
                app.vd.vertex(alphaVertex).type = 0;
                app.vd.vertex(betaVertex).type = 0;
            end
            close(selectionFigure);
            
            % Update the analysis window with the new magnetization based on whatever the chosen inspection image was
            app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
            general_imageDisplay(app,app.AxesAnalysis,backgroundImage);
            overlayMagnetization(app,app.AxesAnalysis);
            overlayVertexType(app,app.AxesAnalysis);
            close(deleteDialog);
            statusUpdate_imagePro(app,'Manual magnetization masking completed.');
        end

        % Value changed function: drop_inspectionImage
        function drop_inspectionImageValueChanged(app, event)
            value = app.drop_inspectionImage.Value;
            
        end

        % Button pushed function: undoAll_finalInspect
        function undoAll_finalInspectButtonPushed(app, event)
            %%%%%%%%%% Reverses any deletion of the magnetization %%%%%%%%%%
            app.vd.magnet = app.vd.oldMagnet;
            app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
            general_imageDisplay(app,app.AxesAnalysis,mat2gray(app.vd.xmcd));
            overlayMagnetization(app,app.AxesAnalysis);
            overlayVertexType(app,app.AxesAnalysis);
            uialert(app.IceScannerUI,'Magnetization data restored.','Undo all','Icon','success');
        end

        % Button pushed function: startAgain_finish
        function startAgain_finishButtonPushed(app, event)
            app.TabGroupImgProcess.SelectedTab = app.ImportTab;
            app.TabGroupAnalysis.SelectedTab = app.AnalysisTab;
            app.arrow_finish.Visible = 0;
            app.steps_finish.Enable = 0;
            app.arrow_import.Visible = 1;
            app.steps_import.Enable = 1;
            app.next_import.Enable = 0;
            app.ButtonRemovePts.Enable = 0;
            app.ButtonAddPts.Enable = 0;
            app.next_cleanUp.Enable = 0;
            app.EditFieldVertexReduction.Value = 0;
            app.DropDownOldVersionDetect.Value = '-';
            app.EditFieldVertexIndex.Value = 0;
            app.DropDown.Value = 'XMCD-PEEM';
            app.Field_scanCross1Width.Value = 0;
            app.Field_scanCross2Width.Value = 0;
            app.Field_scanCross1Height.Value = 0;
            app.Field_scanCross2Height.Value = 0;
            app.Field_scanCross1Angle.Value = 0;
            app.Field_scanCross2Angle.Value = 0;
            app.Field_scanDiameter.Value = 0;
            app.ButtonPreviewScan.Enable = 0;
            app.ButtonNeighborDetect.Enable = 0;
            app.next_nbrScan.Enable = 0;
            app.next_latticeIdx.Enable = 0;
            app.drop_inspectionImage.Value = '-';
            app.MSFSteps.Value = 0;
            app.MSFStart.Value = 0;
            app.MSFEnd.Value = 0;
            app.drop_firstTimeProcessQuery.Value = '-';
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app)
        end

        % Close request function: IceScannerUI
        function IceScannerUICloseRequest(app, event)
            delete(app)
        end

        % Value changed function: EditFieldRefImg3Thresh
        function EditFieldRefImg3ThreshValueChanged(app, event)
            % Change the value of thresholdFactor1
            app.thresholdFactor3 = app.EditFieldRefImg3Thresh.Value;

            % Plot the rough detection points
            vertexDetect_roughDetection(app);
            
            % Message
            statusUpdate_vertexDetect(app,'Threshold 3 adjusted. Rough detection points plotted.');
        end

        % Button pushed function: ButtonImportRefImg3
        function ButtonImportRefImg3Pushed(app, event)
            % Get the file name and directory for the image file containing the second reference image
            % of a vertex (for square, brickwork, and kagome)
            oldDirImages = app.dirImages;
            [FileName,app.dirImages] = uigetfile(strcat(app.dirImages,'*.tif'),'Select the first vertex reference image');
            if FileName == 0
                app.dirImages = oldDirImages;
                return;
            end
            app.refFrame3 = imread(fullfile(app.dirImages,FileName));
            
            % Initial processing of the reference frame
            app.refFrame3 = vertexDetect_imgPreprocess(app,app.refFrame3);
            
            % Modifying reference image to prevent crashing in EMD calculation associated with zeros
            app.refFrame3(app.refFrame3 == 0) = 0.01;
            
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame3,app.refFrame3)
            
            % Update status window
            statusUpdate_vertexDetect(app,'Imported 3rd vertex reference image');
        end

        % Button pushed function: ButtonImportRefImg4
        function ButtonImportRefImg4Pushed(app, event)
            % Get the file name and directory for the image file containing the second reference image
            % of a vertex (for square, brickwork, and kagome)
            oldDirImages = app.dirImages;
            [FileName,app.dirImages] = uigetfile(strcat(app.dirImages,'*.tif'),'Select the first vertex reference image');
            if FileName == 0
                app.dirImages = oldDirImages;
                return;
            end
            app.refFrame4 = imread(fullfile(app.dirImages,FileName));
            
            % Initial processing of the reference frame
            app.refFrame4 = vertexDetect_imgPreprocess(app,app.refFrame4);
            
            % Modifying reference image to prevent crashing in EMD calculation associated with zeros
            app.refFrame4(app.refFrame4 == 0) = 0.01;
            
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame4,app.refFrame4)
            
            % Update status window
            statusUpdate_vertexDetect(app,'Imported 4th vertex reference image');
        end

        % Value changed function: EditFieldRefImg4Thresh
        function EditFieldRefImg4ThreshValueChanged(app, event)
            % Change the value of thresholdFactor1
            app.thresholdFactor4 = app.EditFieldRefImg4Thresh.Value;

            % Plot the rough detection points
            vertexDetect_roughDetection(app);
            
            % Message
            statusUpdate_vertexDetect(app,'Threshold 4 adjusted. Rough detection points plotted.');
        end

        % Button pushed function: ButtonImportRefImg5
        function ButtonImportRefImg5Pushed(app, event)
            % Get the file name and directory for the image file containing the second reference image
            % of a vertex (for square, brickwork, and kagome)
            oldDirImages = app.dirImages;
            [FileName,app.dirImages] = uigetfile(strcat(app.dirImages,'*.tif'),'Select the first vertex reference image');
            if FileName == 0
                app.dirImages = oldDirImages;
                return;
            end
            app.refFrame5 = imread(fullfile(app.dirImages,FileName));
            
            % Initial processing of the reference frame
            app.refFrame5 = vertexDetect_imgPreprocess(app,app.refFrame5);
            
            % Modifying reference image to prevent crashing in EMD calculation associated with zeros
            app.refFrame5(app.refFrame5 == 0) = 0.01;
            
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame5,app.refFrame5)
            
            % Update status window
            statusUpdate_vertexDetect(app,'Imported 5th vertex reference image');
        end

        % Button pushed function: ButtonImportRefImg6
        function ButtonImportRefImg6Pushed(app, event)
            % Get the file name and directory for the image file containing the second reference image
            % of a vertex (for square, brickwork, and kagome)
            oldDirImages = app.dirImages;
            [FileName,app.dirImages] = uigetfile(strcat(app.dirImages,'*.tif'),'Select the first vertex reference image');
            if FileName == 0
                app.dirImages = oldDirImages;
                return;
            end
            app.refFrame6 = imread(fullfile(app.dirImages,FileName));
            
            % Initial processing of the reference frame
            app.refFrame6 = vertexDetect_imgPreprocess(app,app.refFrame6);
            
            % Modifying reference image to prevent crashing in EMD calculation associated with zeros
            app.refFrame6(app.refFrame6 == 0) = 0.01;
            
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame6,app.refFrame6)
            
            % Update status window
            statusUpdate_vertexDetect(app,'Imported 6th vertex reference image');
        end

        % Button pushed function: ButtonImportRefImg7
        function ButtonImportRefImg7Pushed(app, event)
            % Get the file name and directory for the image file containing the second reference image
            % of a vertex (for square, brickwork, and kagome)
            oldDirImages = app.dirImages;
            [FileName,app.dirImages] = uigetfile(strcat(app.dirImages,'*.tif'),'Select the first vertex reference image');
            if FileName == 0
                app.dirImages = oldDirImages;
                return;
            end
            app.refFrame7 = imread(fullfile(app.dirImages,FileName));
            
            % Initial processing of the reference frame
            app.refFrame7 = vertexDetect_imgPreprocess(app,app.refFrame7);
            
            % Modifying reference image to prevent crashing in EMD calculation associated with zeros
            app.refFrame7(app.refFrame7 == 0) = 0.01;
            
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame7,app.refFrame7)
            
            % Update status window
            statusUpdate_vertexDetect(app,'Imported 7th vertex reference image');
        end

        % Button pushed function: ButtonImportRefImg8
        function ButtonImportRefImg8Pushed(app, event)
            % Get the file name and directory for the image file containing the second reference image
            % of a vertex (for square, brickwork, and kagome)
            oldDirImages = app.dirImages;
            [FileName,app.dirImages] = uigetfile(strcat(app.dirImages,'*.tif'),'Select the first vertex reference image');
            if FileName == 0
                app.dirImages = oldDirImages;
                return;
            end
            app.refFrame8 = imread(fullfile(app.dirImages,FileName));
            
            % Initial processing of the reference frame
            app.refFrame8 = vertexDetect_imgPreprocess(app,app.refFrame8);
            
            % Modifying reference image to prevent crashing in EMD calculation associated with zeros
            app.refFrame8(app.refFrame8 == 0) = 0.01;
            
            % Display image in figure window
            general_imageDisplay(app,app.AxesRefFrame8,app.refFrame8)
            
            % Update status window
            statusUpdate_vertexDetect(app,'Imported 8th vertex reference image');
        end

        % Value changed function: EditFieldRefImg5Thresh
        function EditFieldRefImg5ThreshValueChanged(app, event)
            % Change the value of thresholdFactor1
            app.thresholdFactor5 = app.EditFieldRefImg5Thresh.Value;

            % Plot the rough detection points
            vertexDetect_roughDetection(app);
            
            % Message
            statusUpdate_vertexDetect(app,'Threshold 5 adjusted. Rough detection points plotted.');
        end

        % Value changed function: EditFieldRefImg6Thresh
        function EditFieldRefImg6ThreshValueChanged(app, event)
            % Change the value of thresholdFactor1
            app.thresholdFactor6 = app.EditFieldRefImg6Thresh.Value;

            % Plot the rough detection points
            vertexDetect_roughDetection(app);
            
            % Message
            statusUpdate_vertexDetect(app,'Threshold 6 adjusted. Rough detection points plotted.');
        end

        % Value changed function: EditFieldRefImg7Thresh
        function EditFieldRefImg7ThreshValueChanged(app, event)
            % Change the value of thresholdFactor1
            app.thresholdFactor7 = app.EditFieldRefImg7Thresh.Value;

            % Plot the rough detection points
            vertexDetect_roughDetection(app);
            
            % Message
            statusUpdate_vertexDetect(app,'Threshold 7 adjusted. Rough detection points plotted.');
        end

        % Value changed function: EditFieldRefImg8Thresh
        function EditFieldRefImg8ThreshValueChanged(app, event)
            % Change the value of thresholdFactor1
            app.thresholdFactor8 = app.EditFieldRefImg8Thresh.Value;

            % Plot the rough detection points
            vertexDetect_roughDetection(app);
            
            % Message
            statusUpdate_vertexDetect(app,'Threshold 8 adjusted. Rough detection points plotted.');
        end

        % Value changed function: PairwisecorrelationCheckBox
        function PairwisecorrelationCheckBoxValueChanged(app, event)
            value = app.PairwisecorrelationCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IceScannerUI = uifigure('Visible', 'off');
            app.IceScannerUI.Name = 'IceScanner - Artificial Spin Ice Analyzer Toolbox';
            app.IceScannerUI.CloseRequestFcn = createCallbackFcn(app, @IceScannerUICloseRequest, true);
            app.Menu = uimenu(app.IceScannerUI);
            app.Menu.Text = 'Menu';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.AdvancedMenu = uimenu(app.IceScannerUI);
            app.AdvancedMenu.Text = 'Advanced';
            app.MenuImgProcSaveState = uimenu(app.AdvancedMenu);
            app.MenuImgProcSaveState.MenuSelectedFcn = createCallbackFcn(app, @MenuImgProcSaveStateSelected, true);
            app.MenuImgProcSaveState.Text = 'Save current state (*.mat)';
            app.MenuDiagnosticImport = uimenu(app.AdvancedMenu);
            app.MenuDiagnosticImport.MenuSelectedFcn = createCallbackFcn(app, @MenuDiagnosticImportSelected, true);
            app.MenuDiagnosticImport.Text = 'Diagnostic import';
            app.TabGroup = uitabgroup(app.IceScannerUI);
            app.VertexdetectionTab = uitab(app.TabGroup);
            app.VertexdetectionTab.Title = 'Vertex detection';
            app.AxesASITopography = uiaxes(app.VertexdetectionTab);
            app.AxesASITopography.XTick = [];
            app.AxesASITopography.XTickLabel = '';
            app.AxesASITopography.YTick = [];
            app.AxesASITopography.YTickLabel = '';
            app.TabGroupEMD = uitabgroup(app.VertexdetectionTab);
            app.ImageTab = uitab(app.TabGroupEMD);
            app.ImageTab.Title = 'Image';
            app.GridLayout7_6 = uigridlayout(app.ImageTab);
            app.AxesRefFrame8 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame8.XTick = [];
            app.AxesRefFrame8.XTickLabel = '';
            app.AxesRefFrame8.YTick = [];
            app.AxesRefFrame8.YTickLabel = '';
            app.AxesRefFrame7 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame7.XTick = [];
            app.AxesRefFrame7.XTickLabel = '';
            app.AxesRefFrame7.YTick = [];
            app.AxesRefFrame7.YTickLabel = '';
            app.AxesRefFrame6 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame6.XTick = [];
            app.AxesRefFrame6.XTickLabel = '';
            app.AxesRefFrame6.YTick = [];
            app.AxesRefFrame6.YTickLabel = '';
            app.AxesRefFrame5 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame5.XTick = [];
            app.AxesRefFrame5.XTickLabel = '';
            app.AxesRefFrame5.YTick = [];
            app.AxesRefFrame5.YTickLabel = '';
            app.AxesRefFrame4 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame4.XTick = [];
            app.AxesRefFrame4.XTickLabel = '';
            app.AxesRefFrame4.YTick = [];
            app.AxesRefFrame4.YTickLabel = '';
            app.AxesRefFrame3 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame3.XTick = [];
            app.AxesRefFrame3.XTickLabel = '';
            app.AxesRefFrame3.YTick = [];
            app.AxesRefFrame3.YTickLabel = '';
            app.AxesRefFrame1 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame1.XTick = [];
            app.AxesRefFrame1.XTickLabel = '';
            app.AxesRefFrame1.YTick = [];
            app.AxesRefFrame1.YTickLabel = '';
            app.AxesRefFrame2 = uiaxes(app.GridLayout7_6);
            app.AxesRefFrame2.XTick = [];
            app.AxesRefFrame2.XTickLabel = '';
            app.AxesRefFrame2.YTick = [];
            app.AxesRefFrame2.YTickLabel = '';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_37 = uilabel(app.GridLayout7_6);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_37.Text = {'Define the type of ASI array being analyzed. Then, load the reference images for distance '; 'calculation (refer to manual). You can speed up the detection by scaling down the image '; 'sizes and/or skipping a certain number of pixels during the distance calculations.'};
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_38 = uilabel(app.GridLayout7_6);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_38.Text = 'Type:';
            app.DropDownASIType = uidropdown(app.GridLayout7_6);
            app.DropDownASIType.Items = {'-', 'Square', 'Brickwork', 'Kagome', 'Tetris'};
            app.DropDownASIType.ValueChangedFcn = createCallbackFcn(app, @vertexDetect_adjTypeASI, true);
            app.DropDownASIType.Value = '-';
            app.Scale1041Label = uilabel(app.GridLayout7_6);
            app.Scale1041Label.Text = 'Scale (1-0.41):';
            app.FieldScaleFactor = uieditfield(app.GridLayout7_6, 'numeric');
            app.FieldScaleFactor.ValueChangedFcn = createCallbackFcn(app, @vertexDetect_adjScaleFactor, true);
            app.FieldScaleFactor.Value = 0.41;
            app.SkipofpixelsLabel = uilabel(app.GridLayout7_6);
            app.SkipofpixelsLabel.Text = 'Skip # of pixels';
            app.FieldSkipInterval = uieditfield(app.GridLayout7_6, 'numeric');
            app.FieldSkipInterval.ValueChangedFcn = createCallbackFcn(app, @vertexDetect_adjSkipInterval, true);
            app.FieldSkipInterval.Value = 2;
            app.ButtonImportRefImg1 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg1.ButtonPushedFcn = createCallbackFcn(app, @vertexDetect_importRefImg1, true);
            app.ButtonImportRefImg1.Text = 'Select 1st ref.';
            app.TopographyimageButton = uibutton(app.GridLayout7_6, 'push');
            app.TopographyimageButton.ButtonPushedFcn = createCallbackFcn(app, @vertexDetect_importTopography, true);
            app.TopographyimageButton.Text = 'Topography image';
            app.ButtonImportRefImg2 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg2.ButtonPushedFcn = createCallbackFcn(app, @vertexDetect_importRefImg2, true);
            app.ButtonImportRefImg2.Enable = 'off';
            app.ButtonImportRefImg2.Text = 'Select 2nd ref.';
            app.next_image = uibutton(app.GridLayout7_6, 'push');
            app.next_image.ButtonPushedFcn = createCallbackFcn(app, @vertexDetect_executeEMD, true);
            app.next_image.Text = 'Next (start EMD) >';
            app.ButtonImportRefImg3 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg3.ButtonPushedFcn = createCallbackFcn(app, @ButtonImportRefImg3Pushed, true);
            app.ButtonImportRefImg3.Enable = 'off';
            app.ButtonImportRefImg3.Text = 'Select 3rd ref.';
            app.ButtonImportRefImg4 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg4.ButtonPushedFcn = createCallbackFcn(app, @ButtonImportRefImg4Pushed, true);
            app.ButtonImportRefImg4.Enable = 'off';
            app.ButtonImportRefImg4.Text = 'Select 4th ref.';
            app.ButtonImportRefImg5 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg5.ButtonPushedFcn = createCallbackFcn(app, @ButtonImportRefImg5Pushed, true);
            app.ButtonImportRefImg5.Enable = 'off';
            app.ButtonImportRefImg5.Text = 'Select 5th ref.';
            app.ButtonImportRefImg6 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg6.ButtonPushedFcn = createCallbackFcn(app, @ButtonImportRefImg6Pushed, true);
            app.ButtonImportRefImg6.Enable = 'off';
            app.ButtonImportRefImg6.Text = 'Select 6th ref.';
            app.ButtonImportRefImg7 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg7.ButtonPushedFcn = createCallbackFcn(app, @ButtonImportRefImg7Pushed, true);
            app.ButtonImportRefImg7.Enable = 'off';
            app.ButtonImportRefImg7.Text = 'Select 7th ref.';
            app.ButtonImportRefImg8 = uibutton(app.GridLayout7_6, 'push');
            app.ButtonImportRefImg8.ButtonPushedFcn = createCallbackFcn(app, @ButtonImportRefImg8Pushed, true);
            app.ButtonImportRefImg8.Enable = 'off';
            app.ButtonImportRefImg8.Text = 'Select 8th ref.';
            app.ThresholdTab = uitab(app.TabGroupEMD);
            app.ThresholdTab.Title = 'Threshold';
            app.GridLayout7_7 = uigridlayout(app.ThresholdTab);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_39 = uilabel(app.GridLayout7_7);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_39.Text = {'Adjust the detection threshold to remove as many extraneous and incorrect detection points'; 'as possible. Further processing of these "raw" points can be performed in the image'; 'processing wizard tab once the vertex detection wizard has exported the results. The image'; 'window will automatically update when you change the threshold value.'};
            app.streferencethresholdLabel = uilabel(app.GridLayout7_7);
            app.streferencethresholdLabel.Text = '1st reference threshold:';
            app.EditFieldRefImg1Thresh = uieditfield(app.GridLayout7_7, 'numeric');
            app.EditFieldRefImg1Thresh.ValueChangedFcn = createCallbackFcn(app, @vertexDetect_adjThresholdFactor1, true);
            app.EditFieldRefImg1Thresh.Value = 1;
            app.ndreferencethresholdLabel = uilabel(app.GridLayout7_7);
            app.ndreferencethresholdLabel.Text = '2nd reference threshold:';
            app.EditFieldRefImg2Thresh = uieditfield(app.GridLayout7_7, 'numeric');
            app.EditFieldRefImg2Thresh.ValueChangedFcn = createCallbackFcn(app, @vertexDetect_adjThresholdFactor2, true);
            app.EditFieldRefImg2Thresh.Enable = 'off';
            app.EditFieldRefImg2Thresh.Value = 1;
            app.SaveresultsButton = uibutton(app.GridLayout7_7, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Text = 'Save results';
            app.back_threshold = uibutton(app.GridLayout7_7, 'push');
            app.back_threshold.ButtonPushedFcn = createCallbackFcn(app, @back_thresholdButtonPushed, true);
            app.back_threshold.Text = '< Back';
            app.rdreferencethresholdLabel = uilabel(app.GridLayout7_7);
            app.rdreferencethresholdLabel.Text = '3rd reference threshold:';
            app.EditFieldRefImg3Thresh = uieditfield(app.GridLayout7_7, 'numeric');
            app.EditFieldRefImg3Thresh.ValueChangedFcn = createCallbackFcn(app, @EditFieldRefImg3ThreshValueChanged, true);
            app.EditFieldRefImg3Thresh.Enable = 'off';
            app.EditFieldRefImg3Thresh.Value = 1;
            app.threferencethresholdLabel = uilabel(app.GridLayout7_7);
            app.threferencethresholdLabel.Text = '4th reference threshold:';
            app.EditFieldRefImg4Thresh = uieditfield(app.GridLayout7_7, 'numeric');
            app.EditFieldRefImg4Thresh.ValueChangedFcn = createCallbackFcn(app, @EditFieldRefImg4ThreshValueChanged, true);
            app.EditFieldRefImg4Thresh.Enable = 'off';
            app.EditFieldRefImg4Thresh.Value = 1;
            app.threferencethresholdLabel_2 = uilabel(app.GridLayout7_7);
            app.threferencethresholdLabel_2.Text = '5th reference threshold:';
            app.EditFieldRefImg5Thresh = uieditfield(app.GridLayout7_7, 'numeric');
            app.EditFieldRefImg5Thresh.ValueChangedFcn = createCallbackFcn(app, @EditFieldRefImg5ThreshValueChanged, true);
            app.EditFieldRefImg5Thresh.Enable = 'off';
            app.EditFieldRefImg5Thresh.Value = 1;
            app.threferencethresholdLabel_3 = uilabel(app.GridLayout7_7);
            app.threferencethresholdLabel_3.Text = '6th reference threshold:';
            app.EditFieldRefImg6Thresh = uieditfield(app.GridLayout7_7, 'numeric');
            app.EditFieldRefImg6Thresh.ValueChangedFcn = createCallbackFcn(app, @EditFieldRefImg6ThreshValueChanged, true);
            app.EditFieldRefImg6Thresh.Enable = 'off';
            app.EditFieldRefImg6Thresh.Value = 1;
            app.threferencethresholdLabel_4 = uilabel(app.GridLayout7_7);
            app.threferencethresholdLabel_4.Text = '7th reference threshold:';
            app.EditFieldRefImg7Thresh = uieditfield(app.GridLayout7_7, 'numeric');
            app.EditFieldRefImg7Thresh.ValueChangedFcn = createCallbackFcn(app, @EditFieldRefImg7ThreshValueChanged, true);
            app.EditFieldRefImg7Thresh.Enable = 'off';
            app.EditFieldRefImg7Thresh.Value = 1;
            app.threferencethresholdLabel_5 = uilabel(app.GridLayout7_7);
            app.threferencethresholdLabel_5.Text = '8th reference threshold:';
            app.EditFieldRefImg8Thresh = uieditfield(app.GridLayout7_7, 'numeric');
            app.EditFieldRefImg8Thresh.ValueChangedFcn = createCallbackFcn(app, @EditFieldRefImg8ThreshValueChanged, true);
            app.EditFieldRefImg8Thresh.Enable = 'off';
            app.EditFieldRefImg8Thresh.Value = 1;
            app.FinishTabEMD = uitab(app.TabGroupEMD);
            app.FinishTabEMD.Title = 'Finish';
            app.GridLayout7_8 = uigridlayout(app.FinishTabEMD);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_40 = uilabel(app.GridLayout7_8);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_40.Text = {'The wizard has completed the vertex detection sequence. You may use the exported results'; 'to perform analysis in the image processing wizard. If you would like to analyze another'; 'image click "Scan new image".'};
            app.finish_scanNewImage = uibutton(app.GridLayout7_8, 'push');
            app.finish_scanNewImage.ButtonPushedFcn = createCallbackFcn(app, @finish_scanNewImagePushed, true);
            app.finish_scanNewImage.Text = 'Scan new image';
            app.VertexDetectPanel = uipanel(app.VertexdetectionTab);
            app.VertexDetectPanel.Title = 'Steps';
            app.VertexDetectPanel_Grid = uigridlayout(app.VertexDetectPanel);
            app.steps_importImg = uilabel(app.VertexDetectPanel_Grid);
            app.steps_importImg.Text = 'Import images';
            app.arrow_importImg = uilabel(app.VertexDetectPanel_Grid);
            app.arrow_importImg.Text = '->';
            app.steps_thresholding = uilabel(app.VertexDetectPanel_Grid);
            app.steps_thresholding.Enable = 'off';
            app.steps_thresholding.Text = 'Thresholding';
            app.arrow_thresholding = uilabel(app.VertexDetectPanel_Grid);
            app.arrow_thresholding.Visible = 'off';
            app.arrow_thresholding.Text = '->';
            app.steps_finishEMD = uilabel(app.VertexDetectPanel_Grid);
            app.steps_finishEMD.Enable = 'off';
            app.steps_finishEMD.Text = 'Finish';
            app.arrow_finishEMD = uilabel(app.VertexDetectPanel_Grid);
            app.arrow_finishEMD.Visible = 'off';
            app.arrow_finishEMD.Text = '->';
            app.MsgVertexDetect = uitextarea(app.VertexdetectionTab);
            app.MsgVertexDetect.Editable = 'off';
            app.MsgVertexDetect.Value = {'Status updates or errors will be posted here.'};
            app.ImageprocessingTab = uitab(app.TabGroup);
            app.ImageprocessingTab.Title = 'Image processing';
            app.TabGroupAnalysis = uitabgroup(app.ImageprocessingTab);
            app.AnalysisTab = uitab(app.TabGroupAnalysis);
            app.AnalysisTab.Title = 'Analysis window';
            app.AxesAnalysis = uiaxes(app.AnalysisTab);
            app.AxesAnalysis.XTick = [];
            app.AxesAnalysis.XTickLabel = '';
            app.AxesAnalysis.YTick = [];
            app.AxesAnalysis.YTickLabel = '';
            app.ImportedmagneticcontrastTab = uitab(app.TabGroupAnalysis);
            app.ImportedmagneticcontrastTab.Title = 'Imported magnetic contrast';
            app.AxesImportMagneticContrast = uiaxes(app.ImportedmagneticcontrastTab);
            app.AxesImportMagneticContrast.XTick = [];
            app.AxesImportMagneticContrast.XTickLabel = '';
            app.AxesImportMagneticContrast.YTick = [];
            app.AxesImportMagneticContrast.YTickLabel = '';
            app.TopographyTab = uitab(app.TabGroupAnalysis);
            app.TopographyTab.Title = 'Topography';
            app.AxesTopography = uiaxes(app.TopographyTab);
            app.AxesTopography.XTick = [];
            app.AxesTopography.XTickLabel = '';
            app.AxesTopography.YTick = [];
            app.AxesTopography.YTickLabel = '';
            app.InterpretedMTab = uitab(app.TabGroupAnalysis);
            app.InterpretedMTab.Title = 'Interpreted M';
            app.AxesInterpret = uiaxes(app.InterpretedMTab);
            app.AxesInterpret.XTick = [];
            app.AxesInterpret.XTickLabel = '';
            app.AxesInterpret.YTick = [];
            app.AxesInterpret.YTickLabel = '';
            app.BinaryMTab = uitab(app.TabGroupAnalysis);
            app.BinaryMTab.Title = 'Binary M';
            app.AxesBinary = uiaxes(app.BinaryMTab);
            app.AxesBinary.XTick = [];
            app.AxesBinary.XTickLabel = '';
            app.AxesBinary.YTick = [];
            app.AxesBinary.YTickLabel = '';
            app.LatticeIndexTab = uitab(app.TabGroupAnalysis);
            app.LatticeIndexTab.Title = 'Lattice indexing';
            app.AxesLatticeIndex = uiaxes(app.LatticeIndexTab);
            app.AxesLatticeIndex.XTick = [];
            app.AxesLatticeIndex.XTickLabel = '';
            app.AxesLatticeIndex.YTick = [];
            app.AxesLatticeIndex.YTickLabel = '';
            app.MagneticstructurefactorTab = uitab(app.TabGroupAnalysis);
            app.MagneticstructurefactorTab.Title = 'Magnetic structure factor';
            app.AxesMSF = uiaxes(app.MagneticstructurefactorTab);
            app.AxesMSF.XTick = [];
            app.AxesMSF.XTickLabel = '';
            app.AxesMSF.YTick = [];
            app.AxesMSF.YTickLabel = '';
            app.TabGroupImgProcess = uitabgroup(app.ImageprocessingTab);
            app.ImportTab = uitab(app.TabGroupImgProcess);
            app.ImportTab.Title = 'Import';
            app.GridLayout = uigridlayout(app.ImportTab);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel = uilabel(app.GridLayout);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel.Text = {'Select the *.mat file containing the vertex detection data as well as the magnetic contrast '; 'image used for magnetization detection.'};
            app.DetectionresultfileEditFieldLabel = uilabel(app.GridLayout);
            app.DetectionresultfileEditFieldLabel.Text = 'Detection result file:';
            app.Field_detectionPath = uieditfield(app.GridLayout, 'text');
            app.Field_detectionPath.Editable = 'off';
            app.browse_importEMDResults = uibutton(app.GridLayout, 'push');
            app.browse_importEMDResults.ButtonPushedFcn = createCallbackFcn(app, @browse_importEMDResultsPushed, true);
            app.browse_importEMDResults.Text = 'Browse';
            app.MagneticimagefileEditFieldLabel = uilabel(app.GridLayout);
            app.MagneticimagefileEditFieldLabel.Text = 'Magnetic image file:';
            app.Field_magImagePath = uieditfield(app.GridLayout, 'text');
            app.Field_magImagePath.Editable = 'off';
            app.browse_importMagContrast = uibutton(app.GridLayout, 'push');
            app.browse_importMagContrast.ButtonPushedFcn = createCallbackFcn(app, @browse_importMagContrastPushed, true);
            app.browse_importMagContrast.Text = 'Browse';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_2 = uilabel(app.GridLayout);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_2.Text = 'Have the files been processed through this wizard before?';
            app.drop_firstTimeProcessQuery = uidropdown(app.GridLayout);
            app.drop_firstTimeProcessQuery.Items = {'-', 'No', 'Yes'};
            app.drop_firstTimeProcessQuery.ValueChangedFcn = createCallbackFcn(app, @drop_firstTimeProcessQueryValueChanged, true);
            app.drop_firstTimeProcessQuery.Value = '-';
            app.next_import = uibutton(app.GridLayout, 'push');
            app.next_import.ButtonPushedFcn = createCallbackFcn(app, @next_importPushed, true);
            app.next_import.Enable = 'off';
            app.next_import.Text = 'Next >';
            app.CleanupTab = uitab(app.TabGroupImgProcess);
            app.CleanupTab.Title = 'Clean-up';
            app.GridLayout2 = uigridlayout(app.CleanupTab);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_3 = uilabel(app.GridLayout2);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_3.Text = {'Specify the minimum distance between two adjacent vertices; this distance will be used'; 'by the wizard to perform reduction of the EMD-detected points and provide a best '; 'estimate of the true vertex center locations. A good starting value is 90. Once the '; 'wizard has completed the initial guesswork, you may manually add/remove vertices.'};
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_4 = uilabel(app.GridLayout2);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_4.Text = 'Minimum distance between two adjacent vertices (in pixels):';
            app.EditFieldVertexReduction = uieditfield(app.GridLayout2, 'numeric');
            app.EditFieldVertexReduction.ValueChangedFcn = createCallbackFcn(app, @EditFieldVertexReductionValueChanged, true);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_5 = uilabel(app.GridLayout2);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_5.Text = 'Select points to remove';
            app.ButtonRemovePts = uibutton(app.GridLayout2, 'push');
            app.ButtonRemovePts.ButtonPushedFcn = createCallbackFcn(app, @ButtonRemovePtsPushed, true);
            app.ButtonRemovePts.Enable = 'off';
            app.ButtonRemovePts.Text = 'Select';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_6 = uilabel(app.GridLayout2);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_6.Text = 'Select points to add';
            app.ButtonAddPts = uibutton(app.GridLayout2, 'push');
            app.ButtonAddPts.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddPtsPushed, true);
            app.ButtonAddPts.Enable = 'off';
            app.ButtonAddPts.Text = 'Select';
            app.back_cleanUp = uibutton(app.GridLayout2, 'push');
            app.back_cleanUp.ButtonPushedFcn = createCallbackFcn(app, @back_cleanUpButtonPushed, true);
            app.back_cleanUp.Text = '< Back';
            app.next_cleanUp = uibutton(app.GridLayout2, 'push');
            app.next_cleanUp.ButtonPushedFcn = createCallbackFcn(app, @next_cleanUpPushed, true);
            app.next_cleanUp.Enable = 'off';
            app.next_cleanUp.Text = 'Next (clean-up) >';
            app.NbrScanTab = uitab(app.TabGroupImgProcess);
            app.NbrScanTab.Title = 'Nbr Scan';
            app.GridLayout3 = uigridlayout(app.NbrScanTab);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_7 = uilabel(app.GridLayout3);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_7.Text = {'To map the vertex positions to an ideal lattice, the wizard will first attempt to define, for each '; 'vertex, the relative positions of all nearest-neighboring vertices. This is done with the use of '; 'an area scan whose geometric properties can be adjusted. Preview an individual vertex by '; 'providing an arbitrary index value (i.e., 90). Then adjust the area scan parameter and '; 'preview scan area until the ROI contains the nearest neighboring vertices only.'};
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_8 = uilabel(app.GridLayout3);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_8.Text = 'Old detection method?';
            app.DropDownOldVersionDetect = uidropdown(app.GridLayout3);
            app.DropDownOldVersionDetect.Items = {'-', 'No', 'Yes'};
            app.DropDownOldVersionDetect.ValueChangedFcn = createCallbackFcn(app, @DropDownOldVersionDetectValueChanged, true);
            app.DropDownOldVersionDetect.Value = '-';
            app.InputascanningdistanceLabel_11 = uilabel(app.GridLayout3);
            app.InputascanningdistanceLabel_11.Text = 'Width';
            app.InputascanningdistanceLabel_12 = uilabel(app.GridLayout3);
            app.InputascanningdistanceLabel_12.Text = 'Height';
            app.InputascanningdistanceLabel_13 = uilabel(app.GridLayout3);
            app.InputascanningdistanceLabel_13.Text = 'Angle';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_9 = uilabel(app.GridLayout3);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_9.Text = 'Index #:';
            app.EditFieldVertexIndex = uieditfield(app.GridLayout3, 'numeric');
            app.EditFieldVertexIndex.ValueChangedFcn = createCallbackFcn(app, @EditFieldVertexIndexValueChanged, true);
            app.InputascanningdistanceLabel_10 = uilabel(app.GridLayout3);
            app.InputascanningdistanceLabel_10.Text = 'Cross 1';
            app.InputascanningdistanceLabel_14 = uilabel(app.GridLayout3);
            app.InputascanningdistanceLabel_14.Text = 'Cross 2';
            app.InputascanningdistanceLabel_15 = uilabel(app.GridLayout3);
            app.InputascanningdistanceLabel_15.Text = 'Circle';
            app.Field_scanCross1Width = uieditfield(app.GridLayout3, 'numeric');
            app.Field_scanCross1Width.ValueChangedFcn = createCallbackFcn(app, @Field_scanCross1WidthValueChanged, true);
            app.Field_scanCross1Height = uieditfield(app.GridLayout3, 'numeric');
            app.Field_scanCross1Height.ValueChangedFcn = createCallbackFcn(app, @Field_scanCross1HeightValueChanged, true);
            app.Field_scanCross1Angle = uieditfield(app.GridLayout3, 'numeric');
            app.Field_scanCross1Angle.ValueChangedFcn = createCallbackFcn(app, @Field_scanCross1AngleValueChanged, true);
            app.Field_scanCross2Width = uieditfield(app.GridLayout3, 'numeric');
            app.Field_scanCross2Width.ValueChangedFcn = createCallbackFcn(app, @Field_scanCross2WidthValueChanged, true);
            app.Field_scanCross2Height = uieditfield(app.GridLayout3, 'numeric');
            app.Field_scanCross2Height.ValueChangedFcn = createCallbackFcn(app, @Field_scanCross2HeightValueChanged, true);
            app.Field_scanCross2Angle = uieditfield(app.GridLayout3, 'numeric');
            app.Field_scanCross2Angle.ValueChangedFcn = createCallbackFcn(app, @Field_scanCross2AngleValueChanged, true);
            app.Field_scanDiameter = uieditfield(app.GridLayout3, 'numeric');
            app.Field_scanDiameter.ValueChangedFcn = createCallbackFcn(app, @Field_scanDiameterValueChanged, true);
            app.ButtonPreviewScan = uibutton(app.GridLayout3, 'push');
            app.ButtonPreviewScan.ButtonPushedFcn = createCallbackFcn(app, @ButtonPreviewScanPushed, true);
            app.ButtonPreviewScan.Enable = 'off';
            app.ButtonPreviewScan.Text = 'Preview scan area';
            app.back_nbrScan = uibutton(app.GridLayout3, 'push');
            app.back_nbrScan.ButtonPushedFcn = createCallbackFcn(app, @back_nbrScanButtonPushed, true);
            app.back_nbrScan.Text = '< Back';
            app.next_nbrScan = uibutton(app.GridLayout3, 'push');
            app.next_nbrScan.ButtonPushedFcn = createCallbackFcn(app, @next_nbrScanButtonPushed, true);
            app.next_nbrScan.Enable = 'off';
            app.next_nbrScan.Text = 'Next >';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_10 = uilabel(app.GridLayout3);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_10.Text = 'Imaging technique:';
            app.DropDown = uidropdown(app.GridLayout3);
            app.DropDown.Items = {'XMCD-PEEM', 'MFM'};
            app.DropDown.Value = 'XMCD-PEEM';
            app.ButtonNeighborDetect = uibutton(app.GridLayout3, 'push');
            app.ButtonNeighborDetect.ButtonPushedFcn = createCallbackFcn(app, @ButtonNeighborDetectPushed, true);
            app.ButtonNeighborDetect.Enable = 'off';
            app.ButtonNeighborDetect.Text = 'Perform neighbor detect';
            app.LatticeindexingTab = uitab(app.TabGroupImgProcess);
            app.LatticeindexingTab.Title = 'Lattice indexing';
            app.GridLayout4 = uigridlayout(app.LatticeindexingTab);
            app.Image = uiimage(app.GridLayout4);
            app.Image.ImageSource = 'BrickRef.png';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_11 = uilabel(app.GridLayout4);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_11.Text = {'To assign 2D lattice coordinates to the ASI array, select the location of a reference magnet '; 'based on the criteria for the following systems:'; ''; 'Square and Kagome ASI: Select the center of any magnet (ideally with horizontal orientation)'; ''; 'Brickwork ASI: Select the center of the magnet that is circled in green with neighbor magnets'; '   arranged in the manner shown in the red region (image below).'};
            app.ForthesquareandKagomeLabel = uilabel(app.GridLayout4);
            app.ForthesquareandKagomeLabel.Text = 'Choose reference:';
            app.ButtonRefMagSelect = uibutton(app.GridLayout4, 'push');
            app.ButtonRefMagSelect.ButtonPushedFcn = createCallbackFcn(app, @ButtonRefMagSelectPushed, true);
            app.ButtonRefMagSelect.Text = 'Select';
            app.next_latticeIdx = uibutton(app.GridLayout4, 'push');
            app.next_latticeIdx.ButtonPushedFcn = createCallbackFcn(app, @next_latticeIdxPushed, true);
            app.next_latticeIdx.Enable = 'off';
            app.next_latticeIdx.Text = 'Next (start mapping) >';
            app.back_latticeIdx = uibutton(app.GridLayout4, 'push');
            app.back_latticeIdx.ButtonPushedFcn = createCallbackFcn(app, @back_latticeIdxButtonPushed, true);
            app.back_latticeIdx.Text = '< Back';
            app.FinalinspectionTab = uitab(app.TabGroupImgProcess);
            app.FinalinspectionTab.Title = 'Final inspection';
            app.GridLayout5 = uigridlayout(app.FinalinspectionTab);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_12 = uilabel(app.GridLayout5);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_12.Text = {'Inspect the nanoisland magnetizations and verify that the correct magnetization has been'; 'assigned. If the magnetization is incorrect for a given island(s), you may "remove" the'; 'magnetization. Changing the magnetization value will be added in a future version.'};
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_13 = uilabel(app.GridLayout5);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_13.Text = 'Inspection image:';
            app.drop_inspectionImage = uidropdown(app.GridLayout5);
            app.drop_inspectionImage.Items = {'-', 'XAS', 'XMCD', 'XMCD (more contrast)', 'Absolute value of XMCD', 'ROI - Regular', 'ROI - Averaged'};
            app.drop_inspectionImage.ValueChangedFcn = createCallbackFcn(app, @drop_inspectionImageValueChanged, true);
            app.drop_inspectionImage.Value = '-';
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_14 = uilabel(app.GridLayout5);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_14.Text = 'Choose magnets to remove:';
            app.ButtonRefMagSelect_2 = uibutton(app.GridLayout5, 'push');
            app.ButtonRefMagSelect_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonRefMagSelect_2Pushed, true);
            app.ButtonRefMagSelect_2.Text = 'Select';
            app.next_finalInspect = uibutton(app.GridLayout5, 'push');
            app.next_finalInspect.ButtonPushedFcn = createCallbackFcn(app, @next_finalInspectButtonPushed, true);
            app.next_finalInspect.Text = 'Next >';
            app.back_finalInspect = uibutton(app.GridLayout5, 'push');
            app.back_finalInspect.ButtonPushedFcn = createCallbackFcn(app, @back_finalInspectButtonPushed, true);
            app.back_finalInspect.Text = '< Back';
            app.undoAll_finalInspect = uibutton(app.GridLayout5, 'push');
            app.undoAll_finalInspect.ButtonPushedFcn = createCallbackFcn(app, @undoAll_finalInspectButtonPushed, true);
            app.undoAll_finalInspect.Text = 'Undo all';
            app.PostprocessingTab = uitab(app.TabGroupImgProcess);
            app.PostprocessingTab.Title = 'Postprocessing';
            app.GridLayout6 = uigridlayout(app.PostprocessingTab);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_15 = uilabel(app.GridLayout6);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_15.Text = {'Select which post-processing procedure you would like to perform with the processed ASI'; 'image. The files will be saved to the same directory that you selected the magnetic contrast'; 'image from.'};
            app.MagneticstructurefactorCheckBox = uicheckbox(app.GridLayout6);
            app.MagneticstructurefactorCheckBox.ValueChangedFcn = createCallbackFcn(app, @MagneticstructurefactorCheckBoxValueChanged, true);
            app.MagneticstructurefactorCheckBox.Text = 'Magnetic structure factor';
            app.InputascanningdistanceLabel_25 = uilabel(app.GridLayout6);
            app.InputascanningdistanceLabel_25.Text = 'Steps';
            app.InputascanningdistanceLabel_26 = uilabel(app.GridLayout6);
            app.InputascanningdistanceLabel_26.Text = 'Start (q*pi)';
            app.InputascanningdistanceLabel_27 = uilabel(app.GridLayout6);
            app.InputascanningdistanceLabel_27.Text = 'End (q*pi)';
            app.MSFSteps = uieditfield(app.GridLayout6, 'numeric');
            app.MSFSteps.ValueChangedFcn = createCallbackFcn(app, @MSFStepsValueChanged, true);
            app.MSFStart = uieditfield(app.GridLayout6, 'numeric');
            app.MSFStart.ValueChangedFcn = createCallbackFcn(app, @MSFStartValueChanged, true);
            app.MSFEnd = uieditfield(app.GridLayout6, 'numeric');
            app.MSFEnd.ValueChangedFcn = createCallbackFcn(app, @MSFEndValueChanged, true);
            app.VertexcountsCheckBox = uicheckbox(app.GridLayout6);
            app.VertexcountsCheckBox.ValueChangedFcn = createCallbackFcn(app, @VertexcountsCheckBoxValueChanged, true);
            app.VertexcountsCheckBox.Text = 'Vertex counts';
            app.VertexcountsCheckBox.Value = true;
            app.ContrastvertextypeCheckBox = uicheckbox(app.GridLayout6);
            app.ContrastvertextypeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContrastvertextypeCheckBoxValueChanged, true);
            app.ContrastvertextypeCheckBox.Text = 'Contrast + vertex type';
            app.ContrastvertextypeCheckBox.Value = true;
            app.ContrastdetectedMCheckBox = uicheckbox(app.GridLayout6);
            app.ContrastdetectedMCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContrastdetectedMCheckBoxValueChanged, true);
            app.ContrastdetectedMCheckBox.Text = 'Contrast + detected M';
            app.ContrastdetectedMCheckBox.Value = true;
            app.back_postProcess = uibutton(app.GridLayout6, 'push');
            app.back_postProcess.ButtonPushedFcn = createCallbackFcn(app, @back_postProcessButtonPushed, true);
            app.back_postProcess.Text = '< Back';
            app.next_postProcess = uibutton(app.GridLayout6, 'push');
            app.next_postProcess.ButtonPushedFcn = createCallbackFcn(app, @next_postProcessPushed, true);
            app.next_postProcess.Text = 'Next (postprocess) >';
            app.MappedlatticepointsCheckBox = uicheckbox(app.GridLayout6);
            app.MappedlatticepointsCheckBox.Text = 'Mapped lattice points';
            app.MappedlatticepointsCheckBox.Value = true;
            app.PairwisecorrelationCheckBox = uicheckbox(app.GridLayout6);
            app.PairwisecorrelationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PairwisecorrelationCheckBoxValueChanged, true);
            app.PairwisecorrelationCheckBox.Text = 'Pair-wise correlation';
            app.PairwisecorrelationCheckBox.Value = true;
            app.FinishTab = uitab(app.TabGroupImgProcess);
            app.FinishTab.Title = 'Finish';
            app.GridLayout7 = uigridlayout(app.FinishTab);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_16 = uilabel(app.GridLayout7);
            app.BrowseforthedirectoriesthatcontainMEXOpenCVtheLabel_16.Text = {'Wizard has finished processing the ASI image. Double-check the generated figures to make'; 'sure the results make sense.'};
            app.startAgain_finish = uibutton(app.GridLayout7, 'push');
            app.startAgain_finish.ButtonPushedFcn = createCallbackFcn(app, @startAgain_finishButtonPushed, true);
            app.startAgain_finish.Text = 'Start again >>';
            app.Tab = uitab(app.TabGroupImgProcess);
            app.Tab.Title = 'Tab';
            app.MsgImgProcess = uitextarea(app.ImageprocessingTab);
            app.MsgImgProcess.Editable = 'off';
            app.MsgImgProcess.Value = {'Status updates or errors will be posted here.'};
            app.StepsPanel = uipanel(app.ImageprocessingTab);
            app.StepsPanel.Title = 'Steps';
            app.StepsPanel_Grid = uigridlayout(app.StepsPanel);
            app.steps_import = uilabel(app.StepsPanel_Grid);
            app.steps_import.Text = 'Import';
            app.arrow_import = uilabel(app.StepsPanel_Grid);
            app.arrow_import.Text = '->';
            app.steps_cleanUp = uilabel(app.StepsPanel_Grid);
            app.steps_cleanUp.Enable = 'off';
            app.steps_cleanUp.Text = 'Vertex clean-up';
            app.arrow_cleanUp = uilabel(app.StepsPanel_Grid);
            app.arrow_cleanUp.Visible = 'off';
            app.arrow_cleanUp.Text = '->';
            app.steps_nbrScan = uilabel(app.StepsPanel_Grid);
            app.steps_nbrScan.Enable = 'off';
            app.steps_nbrScan.Text = 'Neighbor scan';
            app.arrow_nbrScan = uilabel(app.StepsPanel_Grid);
            app.arrow_nbrScan.Visible = 'off';
            app.arrow_nbrScan.Text = '->';
            app.steps_latticeIdx = uilabel(app.StepsPanel_Grid);
            app.steps_latticeIdx.Enable = 'off';
            app.steps_latticeIdx.Text = 'Lattice indexing';
            app.arrow_latticeIdx = uilabel(app.StepsPanel_Grid);
            app.arrow_latticeIdx.Visible = 'off';
            app.arrow_latticeIdx.Text = '->';
            app.steps_finalInspect = uilabel(app.StepsPanel_Grid);
            app.steps_finalInspect.Enable = 'off';
            app.steps_finalInspect.Text = 'Final inspection';
            app.arrow_finalInspect = uilabel(app.StepsPanel_Grid);
            app.arrow_finalInspect.Visible = 'off';
            app.arrow_finalInspect.Text = '->';
            app.steps_postProcess = uilabel(app.StepsPanel_Grid);
            app.steps_postProcess.Enable = 'off';
            app.steps_postProcess.Text = 'Post-processing';
            app.arrow_postProcess = uilabel(app.StepsPanel_Grid);
            app.arrow_postProcess.Visible = 'off';
            app.arrow_postProcess.Text = '->';
            app.steps_finish = uilabel(app.StepsPanel_Grid);
            app.steps_finish.Enable = 'off';
            app.steps_finish.Text = 'Finish';
            app.arrow_finish = uilabel(app.StepsPanel_Grid);
            app.arrow_finish.Visible = 'off';
            app.arrow_finish.Text = '->';
            app.ContextMenu = uicontextmenu(app.IceScannerUI);
            app.Menu_2 = uimenu(app.ContextMenu);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu);
            app.Menu2_2.Text = 'Menu2';
            % Show the figure after all components are created
            app.IceScannerUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IceScanner_Version_1_3

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.IceScannerUI)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.IceScannerUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IceScannerUI)
        end
    end
end